classdef App_01_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_SIGA_CAL           matlab.ui.Figure
        ArchivoMenu             matlab.ui.container.Menu
        Menu_OpenProject        matlab.ui.container.Menu
        Menu_Configurar         matlab.ui.container.Menu
        Menu_Ejecutar           matlab.ui.container.Menu
        SalirMenu               matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        Menu_Plot_Esc           matlab.ui.container.Menu
        Menu_Comp_Sce           matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        ManualdeReferenciaMenu  matlab.ui.container.Menu
        AcercadeSIGACALMenu     matlab.ui.container.Menu
        Panel_2                 matlab.ui.container.Panel
        Logo_Pegasys            matlab.ui.control.Image
        SimulacinGeocientficaAbiertaSIGACALv10Label  matlab.ui.control.Label
        Logo_Gotta              matlab.ui.control.Image
        Logo_N4W                matlab.ui.control.Image
        Logo_TNC                matlab.ui.control.Image
        Panel_3                 matlab.ui.container.Panel
        Ms_StatusRun            matlab.ui.control.Label
        EscenarioDropDownLabel  matlab.ui.control.Label
        Logo_CuencaVerde        matlab.ui.control.Image
        Button_Configure        matlab.ui.control.StateButton
        Button_Plot             matlab.ui.control.StateButton
        List_Sce                matlab.ui.control.DropDown
        EscenarioLabel          matlab.ui.control.Label
        Text_ProjectPath        matlab.ui.control.EditField
        RutadeProyectoLabel     matlab.ui.control.Label
        Button_Run              matlab.ui.control.StateButton
        Button_OpenProject      matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        AppConfig
        AppPlot
        AppPlotSce   
        AppHelp
    end
    
    properties (Access = public)
        ProjectPath_Temp    = 'C:\';
        ProjectPath
        FolderApp
        NameExeSIGA         = 'SIGA.exe';
    end
    
    methods (Access = public)
        
        function Disable_Button(app)
            % Deshabilitar botón de configuración
            app.Button_Configure.Enable     = "off";
            app.Menu_Configurar.Enable      = "off";
            % Deshabilitar botón de abrir cuenca
            app.Button_OpenProject.Enable   = "off";
            app.Menu_OpenProject.Enable     = "off";
            % Deshabilitar lista de ejecuaciones
            app.List_Sce.Enable             = "off";         
            % Deshabilitar botón de plot
            app.Button_Plot.Enable          = "off";
            app.Menu_Plot_Esc.Enable        = "off";
            app.Menu_Comp_Sce.Enable        = "off";
            % Deshabilitar botón de ejecuación
            app.Button_Run.Enable           = "off"; 
            app.Menu_Ejecutar.Enable        = "off";
        end
        
        function Enable_Button(app)
            % Habilitar botón de configuración
            app.Button_Configure.Enable     = "on";
            app.Menu_Configurar.Enable      = "on";
            % Habilitar botón de abrir cuenca
            app.Button_OpenProject.Enable   = "on";
            app.Menu_OpenProject.Enable     = "on";
            % Habilitar lista de ejecuaciones
            app.List_Sce.Enable             = "on";
            % Actulizar botones según la configuración
            app.List_SceValueChanged;
        end
        
        function [DateProcess, NumProcess] = ProcessCapture(app)
            % Capturar procesos
            [~, TextProcess] = system(['wmic process where name=''' app.NameExeSIGA ''' get ProcessId, CreationDate']);
            TextProcess      = strrep(TextProcess,'-300','');
            TextProcess      = split(TextProcess);
            TextProcess(1:2) = [];
            TextProcess(end) = [];
            TextProcess      = reshape(TextProcess,2,[])';
            NumProcess       = cellfun(@str2num,TextProcess(:,2));
            TmpFun           = @(x) datetime(x,'InputFormat','yyyyMMddHHmmss.SSSSSS');
            DateProcess      = cellfun(TmpFun,TextProcess(:,1));
        end
                
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Capturar folder de app
            app.FolderApp = pwd;                
        end

        % Value changed function: Button_OpenProject
        function Button_OpenProjectValueChanged(app, event)
            % Deseleccionar Boton de "Abrir cuenca"
            app.Button_OpenProject.Value = 0;                       
            
            % Abrir ventana para seleccionar carpeta
            Tmp = uigetdir(app.ProjectPath_Temp);
            
            % Control: Si el usuario no selecciona una carpeta, se finaliza el proceso
            if Tmp == 0
                return
            end
            
            % crear carpeta bin para ejecución
            oo      = strsplit(Tmp,'\');
            Tmp1    = fullfile(oo{1:end-1});            
            mkdir(fullfile(Tmp1,'bin'))
            copyfile('C:\SIGA_CAL\application\cnvrtr.exe',fullfile(Tmp1,'bin','cnvrtr.exe'))
            
            % descomprimir cygwin
            if ~isfolder('C:\SIGA_CAL\application\cygwin64')
                unzip('C:\SIGA_CAL\application\cygwin64.zip','C:\SIGA_CAL\application')
                system('PATH=C:\SIGA_CAL\applicationcygwin64\bin:$PATH')
            end
            
            % Asigna la ruta de la carpeta seleccionada por el usuario
            app.Text_ProjectPath.Value = Tmp;
            
            % Guardar ruta temporal
            app.ProjectPath_Temp = Tmp1;
            
            % Guardar ruta de la carpeta seleccionada por el usuario como variable
            app.ProjectPath = Tmp;
            
            % Leer y listar las ejecuciones configuradas en la carpeta de
            % la cuenca
            NameSce   = dir(fullfile(app.ProjectPath,'entradas','ejecucion','*Scenario_*.txt'));
            
            
            % Control: Si no existen ejecuciones configuradas
            if isempty(NameSce)
                % Envia mensaje al usuario
                errordlg('La carpeta seleccionada no corresponde a un proyecto que pueda ser entendido por SIGA-CAL Tools')   
                % Deshabilita la funcionalidad de graficar
                app.Button_Plot.Enable      = 0;
                app.Menu_Plot_Esc.Enable    = 0;
                app.Menu_Comp_Sce.Enable    = 0;
                % Deshabilita la funcionalidad de listar ejecuciones
                app.List_Sce.Enable         = 0;
                % Deshabilita la funcionalidad de Configurar Ejecución
                app.Button_Configure.Enable = 0;  
                app.Menu_Configurar.Enable  = 0;
                % Deshabilita la funcionalidad de Ejecución
                app.Button_Run.Enable       = 0;
                app.Menu_Ejecutar.Enable    = 0;
                return
            else
                % Asignar ejecuciones
                NameSce             = {NameSce.name}; 
                NameSce             = cellfun(@(x) strrep(x,'.txt',''),NameSce,'UniformOutput',false);
                NameSce             = cellfun(@(x) strrep(x,'Scenario_',''),NameSce,'UniformOutput',false);
                NameSce             = ['None',NameSce]';
                app.List_Sce.Items  = NameSce;
                app.List_Sce.Value  = NameSce{1};                
                % Habilita la funcionalidad de listar ejecuciones
                app.List_Sce.Enable         = 1;     
                % Habilita la funcionalidad de Configurar Ejecución
                app.Button_Configure.Enable = 1;  
                app.Menu_Configurar.Enable  = 1;
            end                                 
            
            % Habilitar o deshabilitar botones
            app.List_SceValueChanged;
        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
            % Deseleccionar Boton de "Plot"
            app.Button_Plot.Value = 0;
            
            % Captura de ejecución seleccionada por el usuario
            NameSce     = app.List_Sce.Value;            
            
            % Abre la ventana de "Plot"
            app.AppPlot   = App_03_Plot_TimeSeries(app.ProjectPath,NameSce);
            
        end

        % Value changed function: Button_Configure
        function Button_ConfigureValueChanged(app, event)
            % Deseleccionar Boton de "Configurar Ejecución"
            app.Button_Configure.Value  = 0;            
            % Captura de ejecución seleccionada por el usuario
            NameSce     = app.List_Sce.Value;
            % Control: si no esta seleccionada una ejecución
            if strcmp(NameSce,'None')
                StatusNew   = true;
            else
                StatusNew   = false;
            end                                 
            
            ProB = waitbar(0.05, 'Cargando información, por favor no cierre la ventana. ','Color',[1 1 1]);
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1); 

            % Abre la ventana de "Configurar Ejecución"
            app.AppConfig   = App_02_Configuration(app, StatusNew, app.ProjectPath, NameSce, ProB);
                        
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            try
                % Deshabilitar todos los botones
                app.Disable_Button;
                
                %% Deseleccionar Boton de ejecucion
                app.Button_Run.Value = 0;

                %% En la instalación de SIGA-CAL Tools este path es obligatorio
                PathSIGA = 'C:\SIGA_CAL\application';                
                
                if ~isfolder(fullfile(PathSIGA))
                    errordlg('No se a encontrado a carpeta C:\SIGA_CAL\application')
                    return                    
                end
%                 if ~isfile(fullfile(PathSIGA,'SIGA.exe')) || ~isfile(fullfile(PathSIGA,'cnvrtr.exe'))
%                     % Abrir ventana para seleccionar carpeta
%                     PathSIGA = uigetdir(app.ProjectPath_Temp,'Seleccione la carpeta donde se encuntra los archivos SIGA.exe y cnvrtr.exe');
%                     
%                     % Control: Si el usuario no selecciona una carpeta, se finaliza el proceso
%                     if PathSIGA == 0
%                         % Habilitar todos lo botones
%                         app.Enable_Button; 
%                         return
%                     end
%                     
%                     if ~isfile(fullfile(PathSIGA,'SIGA.exe')) || ~isfile(fullfile(PathSIGA,'cnvrtr.exe'))
%                         errordlg('El archivo SIGA.exe o cnvrtr.exe no se encuentra en la carpeta seleccionada')
%                         % Habilitar todos lo botones
%                         app.Enable_Button; 
%                         return
%                     end
%                 end
                
                %% Leer lista de escenarios
                NameFileSce = strsplit(app.List_Sce.Value,'.');                    
                NameFileSce = ['Scenario_',NameFileSce{1}];                                                 
                
                % Crear carpeta de Log
                warning off
                mkdir(fullfile(app.ProjectPath,'Log'))
                
                % Capturar fecha y hora de ejecución SIGA-CAL   
                DateExe     = datetime("now");
                ErrorFile   = fullfile(app.ProjectPath,'Log',['Log_',char(string(DateExe,'yyyyMMddhhmmssSSSSSS')),'.txt']);
                
                % Ejecutar SIGA   
                eval(['!',PathSIGA,filesep,'SIGA.exe -t "',app.ProjectPath,'" -n ',NameFileSce,'.txt -a true 2>&1 | tee ',...                        
                        ErrorFile,' &',]);
                                        
                % Capturar procesos
                [DateProcess, NumProcess] = app.ProcessCapture;
                
                % Lectura de texto
                id = (abs(DateProcess - DateExe) == min(abs(DateProcess - DateExe)));
                Check_NumProcess = NumProcess(id);
                
                % Check proceso de SIGA
                IsRunning = 1;
                IsEnd     = 0;
                while IsRunning > 0
                    pause(0.5)
                    try
                        [~, NumProcess] = app.ProcessCapture;
                        [id,~] = ismember(NumProcess,Check_NumProcess);
                        IsRunning = sum(id);                        
                    catch
                        IsRunning = 0;
                    end 
                    
                    % Leer archivo 
                    FileText = fileread(ErrorFile);
                    
                    % Reiniciar de cero
                    IsError1    = contains(FileText,'Error');
                    IsError2    = contains(FileText,'error');
                    IsEnd       = contains(FileText,'finalizado');
                    IsWarning   = contains(FileText,'Advertencia');
                    if IsError1 || IsError2 || IsEnd
                        system(['Taskkill /PID ',num2str(Check_NumProcess),' /F'])
                        IsRunning = 0;
                    end                    
                end
                
                % Habilitar todos lo botones
                app.Enable_Button;                
                
                %% Revisión de errore en la ejecución 
                FileText    = split(FileText,newline);
                FunTmp1     = @(x) contains(x,'Error');
                FunTmp2     = @(x) contains(x,'error');
                if IsError1 || IsError2
                    id1 = find(cellfun(FunTmp1,FileText));
                    id2 = find(cellfun(FunTmp2,FileText));
                    errordlg(['SIGA-CAL no se ha ejecutado correctamente, por favor verifique que la ruta de la ',...
                        'carpeta de los ejecutables de SIGA sea correcta o que los archivos de configuración ',...
                        'del escenario estén completos (mapas, series, etc.). Le sugerimos revisar nuevamente ',...
                        'la configuración del modelo para descartar cualquier error de configuración.',...
                        newline,newline,FileText{[id1 id2]}])
                    % Habilitar todos lo botones
                    app.Enable_Button; 
                    return
                end
                
                if IsEnd
                    %% Eviar mensaje de ejecucion
                    msgbox('Ejecución Finalizada')
                end                                                                              
                            
            catch
                % Habilitar todos lo botones
                app.Enable_Button;
                
                % Mostrar mensaje al usuario
                errordlg(['SIGA no se ha ejecutado correctamente, por favor verifique que la ruta de la ',...
                        'carpeta de los ejecutables de SIGA sea correcta o que los archivos de configuración ',...
                        'del escenario estén completos (mapas, series). Le sugerimos revisar el archivo ',...
                        'de ejecución, parámetros y muestreador para descartar cualquier error de configuración.'])
            end
        end

        % Value changed function: List_Sce
        function List_SceValueChanged(app, event)
            % Leer y listar las ejecuciones configuradas en la carpeta de la cuenca
            NameSce   = dir(fullfile(app.ProjectPath,'entradas','ejecucion','*Scenario_*.txt'));           

            % Asignar ejecuciones
            NameSce             = {NameSce.name}; 
            NameSce             = cellfun(@(x) strrep(x,'.txt',''),NameSce,'UniformOutput',false);
            NameSce             = cellfun(@(x) strrep(x,'Scenario_',''),NameSce,'UniformOutput',false);
            NameSce             = ['None',NameSce]';
            app.List_Sce.Items  = NameSce;
            
            value = app.List_Sce.Value;
            if strcmp(value,'None')
                % Deshabilita la funcionalidad de Configurar Ejecución
                app.Button_Configure.Enable = 1;  
                app.Menu_Configurar.Enable  = 1;
                % Deshabilita la funcionalidad de Ejecución
                app.Button_Run.Enable       = 0;
                app.Menu_Ejecutar.Enable    = 0;
                % Deshabilita la funcionalidad de graficar
                app.Button_Plot.Enable      = 0;
                app.Menu_Plot_Esc.Enable    = 0;
                app.Menu_Comp_Sce.Enable    = 0;
                % Mostrar estado de ejecución
                app.Ms_StatusRun.Text = 'No Ejecutado - Sin resultados disponibles';
                app.Ms_StatusRun.FontColor = [0.50,0.50,0.50];
            else
                % Habilita la funcionalidad de Configurar Ejecución
                app.Button_Configure.Enable = 1;   
                app.Menu_Configurar.Enable  = 1;
                % Habilita la funcionalidad de Ejecución
                app.Button_Run.Enable       = 1;
                app.Menu_Ejecutar.Enable    = 1;
                % -------------------------------------------------------------------
                % Leer datos de Ejecución 
                % -------------------------------------------------------------------
                % Leer Achivo de Ejecución 
                FilePath        = fullfile(app.ProjectPath,'entradas','ejecucion',['Scenario_',value,'.txt']);
                UserData_Exe    = Read_ExecutionFile(FilePath);
                
                if exist(fullfile(app.ProjectPath,'salidas',UserData_Exe.SceName)) == 7                
                    % Habilita la funcionalidad de graficar
                    app.Button_Plot.Enable      = 1;
                    app.Menu_Plot_Esc.Enable    = 1;
                    app.Menu_Comp_Sce.Enable    = 1;
                    % Mostrar estado de ejecución
                    app.Ms_StatusRun.Text = 'Ejecutado - Con resultados disponibles';
                    app.Ms_StatusRun.FontColor = [0 0 0];
                else
                    % Deshabilita la funcionalidad de graficar
                    app.Button_Plot.Enable      = 0;
                    app.Menu_Plot_Esc.Enable    = 0;
                    app.Menu_Comp_Sce.Enable    = 0;
                    % Mostrar estado de ejecución
                    app.Ms_StatusRun.Text = 'No Ejecutado - Sin resultados disponibles';
                    app.Ms_StatusRun.FontColor = [0.50,0.50,0.50];
                end
            end                
        end

        % Callback function
        function Button_Info_2ValueChanged(app, event)
           
            app.Button_Info_2.Value = 0;
            
            % Manual
            system('explorer https://tnc.box.com/s/jlj8oq3tlpz0rjup8fx2sxzn27ry9lly')
            
        end

        % Menu selected function: ManualdeReferenciaMenu
        function ManualdeReferenciaMenuSelected(app, event)
            % Manual
            dos('explorer https://tnc.box.com/s/r8smn41pd3fxbwc3jku26v8ysd5tidxn')
            
        end

        % Close request function: Main_SIGA_CAL
        function Main_CloseRequest(app, event)
           
            delete(app.AppConfig)
            delete(app.AppHelp)
            delete(app.AppPlot)
            delete(app.AppPlotSce)            
            app.delete    
            close('all')
        end

        % Menu selected function: AcercadeSIGACALMenu
        function AcercadeSIGACALMenuSelected(app, event)
            % Abre la ventana de "Plot"
            app.AppHelp   = App_05_Help;
        end

        % Menu selected function: Menu_Comp_Sce
        function Menu_Comp_SceSelected(app, event)
            % Abre la ventana de "Plot" para comparación de escenarios
            app.AppPlotSce   = App_04_Plot_Scenarios(app.ProjectPath);
        end

        % Menu selected function: Menu_Configurar
        function Menu_ConfigurarSelected(app, event)
            
            app.Button_ConfigureValueChanged;
            
        end

        % Menu selected function: Menu_OpenProject
        function Menu_OpenProjectSelected(app, event)
            app.Button_OpenProjectValueChanged;
            
        end

        % Menu selected function: Menu_Ejecutar
        function Menu_EjecutarSelected(app, event)
            
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            close(app.Main_SIGA_CAL)             
        end

        % Menu selected function: Menu_Plot_Esc
        function Menu_Plot_EscSelected(app, event)
            app.Button_PlotValueChanged;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_SIGA_CAL = uifigure('Visible', 'off');
            app.Main_SIGA_CAL.Name = 'UI Figure';
            app.Main_SIGA_CAL.CloseRequestFcn = createCallbackFcn(app, @Main_CloseRequest, true);
            app.ArchivoMenu = uimenu(app.Main_SIGA_CAL);
            app.ArchivoMenu.Text = 'Archivo';
            app.Menu_OpenProject = uimenu(app.ArchivoMenu);
            app.Menu_OpenProject.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenProjectSelected, true);
            app.Menu_OpenProject.Text = 'Abrir';
            app.Menu_Configurar = uimenu(app.ArchivoMenu);
            app.Menu_Configurar.MenuSelectedFcn = createCallbackFcn(app, @Menu_ConfigurarSelected, true);
            app.Menu_Configurar.Enable = 'off';
            app.Menu_Configurar.Text = 'Configurar';
            app.Menu_Ejecutar = uimenu(app.ArchivoMenu);
            app.Menu_Ejecutar.MenuSelectedFcn = createCallbackFcn(app, @Menu_EjecutarSelected, true);
            app.Menu_Ejecutar.Enable = 'off';
            app.Menu_Ejecutar.Text = 'Ejecutar';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.ToolsMenu = uimenu(app.Main_SIGA_CAL);
            app.ToolsMenu.Text = 'Herramientas';
            app.Menu_Plot_Esc = uimenu(app.ToolsMenu);
            app.Menu_Plot_Esc.MenuSelectedFcn = createCallbackFcn(app, @Menu_Plot_EscSelected, true);
            app.Menu_Plot_Esc.Enable = 'off';
            app.Menu_Plot_Esc.Text = 'Visualización de escenario';
            app.Menu_Comp_Sce = uimenu(app.ToolsMenu);
            app.Menu_Comp_Sce.MenuSelectedFcn = createCallbackFcn(app, @Menu_Comp_SceSelected, true);
            app.Menu_Comp_Sce.Enable = 'off';
            app.Menu_Comp_Sce.Text = 'Comparación de escenarios';
            app.HelpMenu = uimenu(app.Main_SIGA_CAL);
            app.HelpMenu.Text = 'Ayuda';
            app.ManualdeReferenciaMenu = uimenu(app.HelpMenu);
            app.ManualdeReferenciaMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualdeReferenciaMenuSelected, true);
            app.ManualdeReferenciaMenu.Text = 'Manual de Referencia';
            app.AcercadeSIGACALMenu = uimenu(app.HelpMenu);
            app.AcercadeSIGACALMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeSIGACALMenuSelected, true);
            app.AcercadeSIGACALMenu.Text = 'Acerca de SIGA-CAL';
            app.Panel_2 = uipanel(app.Main_SIGA_CAL);
            app.Panel_3 = uipanel(app.Panel_2);
            app.Button_OpenProject = uibutton(app.Panel_3, 'state');
            app.Button_OpenProject.ValueChangedFcn = createCallbackFcn(app, @Button_OpenProjectValueChanged, true);
            app.Button_OpenProject.Icon = 'Icons_Basin.png';
            app.Button_OpenProject.Text = 'Abrir';
            app.Button_Run = uibutton(app.Panel_3, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Enable = 'off';
            app.Button_Run.Icon = 'Icons_Play.png';
            app.Button_Run.Text = 'Ejecutar';
            app.RutadeProyectoLabel = uilabel(app.Panel_3);
            app.RutadeProyectoLabel.Text = 'Ruta de Proyecto';
            app.Text_ProjectPath = uieditfield(app.Panel_3, 'text');
            app.Text_ProjectPath.Editable = 'off';
            app.Text_ProjectPath.Value = 'No Project';
            app.EscenarioLabel = uilabel(app.Panel_3);
            app.EscenarioLabel.Text = 'Escenario';
            app.List_Sce = uidropdown(app.Panel_3);
            app.List_Sce.Items = {'None'};
            app.List_Sce.ValueChangedFcn = createCallbackFcn(app, @List_SceValueChanged, true);
            app.List_Sce.Enable = 'off';
            app.List_Sce.Value = 'None';
            app.Button_Plot = uibutton(app.Panel_3, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Icon = 'Icons_TimeSeries.png';
            app.Button_Plot.Text = 'Plot';
            app.Button_Configure = uibutton(app.Panel_3, 'state');
            app.Button_Configure.ValueChangedFcn = createCallbackFcn(app, @Button_ConfigureValueChanged, true);
            app.Button_Configure.Enable = 'off';
            app.Button_Configure.Icon = 'Icons_Execution.png';
            app.Button_Configure.Text = 'Configurar';
            app.Logo_CuencaVerde = uiimage(app.Panel_3);
            app.Logo_CuencaVerde.ImageSource = 'Logo_CuencaVerde.png';
            app.EscenarioDropDownLabel = uilabel(app.Panel_3);
            app.EscenarioDropDownLabel.Text = 'Estado de ejecuación';
            app.Ms_StatusRun = uilabel(app.Panel_3);
            app.Ms_StatusRun.Text = 'No Ejecutado - Sin resultados disponibles';
            app.Logo_TNC = uiimage(app.Panel_2);
            app.Logo_TNC.ImageSource = 'Logo_TNC.png';
            app.Logo_N4W = uiimage(app.Panel_2);
            app.Logo_N4W.ImageSource = 'Logo_N4W-Facility.png';
            app.Logo_Gotta = uiimage(app.Panel_2);
            app.Logo_Gotta.ImageSource = 'Icons_Gotta.jpg';
            app.SimulacinGeocientficaAbiertaSIGACALv10Label = uilabel(app.Panel_2);
            app.SimulacinGeocientficaAbiertaSIGACALv10Label.Text = 'Simulación  Geocientífica  Abierta - SIGA-CAL v1.0';
            app.Logo_Pegasys = uiimage(app.Panel_2);
            app.Logo_Pegasys.ImageSource = 'Logo_Pegasys.png';
            % Show the figure after all components are created
            app.Main_SIGA_CAL.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_01_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_SIGA_CAL)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_SIGA_CAL)
        end
    end
end