classdef App_02_Configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Configuracion                   matlab.ui.Figure
        ConfiguracindelmodeloSIGACALCuencaVerdePanel  matlab.ui.container.Panel
        Button_Cancel                   matlab.ui.control.StateButton
        Button_Save                     matlab.ui.control.StateButton
        Logo_CuencaVerde                matlab.ui.control.Image
        Logo_Pegasys                    matlab.ui.control.Image
        Logo_Gotta                      matlab.ui.control.Image
        Logo_N4W                        matlab.ui.control.Image
        Logo_TNC                        matlab.ui.control.Image
        TabGroup3                       matlab.ui.container.TabGroup
        EjecucinTab                     matlab.ui.container.Tab
        ConfiguracionesdeejecucinPanel  matlab.ui.container.Panel
        ActivarmodelogeotcnicoLabel_2   matlab.ui.control.Label
        Edit_pHExeType                  matlab.ui.control.CheckBox
        ActivarmodelogeotcnicoLabel     matlab.ui.control.Label
        Edit_GeoExeType                 matlab.ui.control.CheckBox
        Edit_SIGAExeType                matlab.ui.control.DropDown
        MododeejecucinLabel             matlab.ui.control.Label
        Edit_ExeFileName                matlab.ui.control.EditField
        EscenarioLabel_2                matlab.ui.control.Label
        MdulosedimentolgicoPanel        matlab.ui.container.Panel
        Edit_MM                         matlab.ui.control.DropDown
        MovimientosenmasaLabel          matlab.ui.control.Label
        Edit_Clay                       matlab.ui.control.DropDown
        ArcillasmezcladasendescargaLabel  matlab.ui.control.Label
        Edit_Sand                       matlab.ui.control.DropDown
        ArenasmezcladasendescargaLabel  matlab.ui.control.Label
        Edit_Silt                       matlab.ui.control.DropDown
        LimosmezcladosendescargaLabel   matlab.ui.control.Label
        MdulodecalidaddelaguaPanel      matlab.ui.container.Panel
        Edit_LoadCycle                  matlab.ui.control.DropDown
        CiclosanualesdecargasdifusasLabel  matlab.ui.control.Label
        Edit_Dumping                    matlab.ui.control.DropDown
        PuntosdevertimientosLabel       matlab.ui.control.Label
        MdulometeorolgicoehidrolgicoPanel  matlab.ui.container.Panel
        Edit_Q_LC                       matlab.ui.control.DropDown
        CaudallquidocaptadoLabel        matlab.ui.control.Label
        Edit_Q_LD                       matlab.ui.control.DropDown
        CaudallquidodescargadoLabel     matlab.ui.control.Label
        Edit_VM                         matlab.ui.control.DropDown
        VientomeridionalLabel           matlab.ui.control.Label
        Edit_VZ                         matlab.ui.control.DropDown
        VientozonalLabel                matlab.ui.control.Label
        Edit_P                          matlab.ui.control.DropDown
        PrecipitacinLabel               matlab.ui.control.Label
        Edit_RS                         matlab.ui.control.DropDown
        RadiacinsolarLabel              matlab.ui.control.Label
        Edit_HR                         matlab.ui.control.DropDown
        HumedadrelativaLabel            matlab.ui.control.Label
        Edit_Tmax                       matlab.ui.control.DropDown
        TemperaturamximaLabel           matlab.ui.control.Label
        Edit_Tmin                       matlab.ui.control.DropDown
        TemperaturamnimaLabel           matlab.ui.control.Label
        ConfiguracionesgeneralesPanel   matlab.ui.container.Panel
        Edit_Boundary                   matlab.ui.control.DropDown
        MuestreadorderesultadosLabel_2  matlab.ui.control.Label
        Edit_Sampler                    matlab.ui.control.DropDown
        MuestreadorderesultadosLabel    matlab.ui.control.Label
        Edit_FacCal                     matlab.ui.control.DropDown
        FactoresdecalibracinLabel       matlab.ui.control.Label
        Edit_Topo                       matlab.ui.control.DropDown
        TopologaLabel                   matlab.ui.control.Label
        Edit_EndDate                    matlab.ui.control.DatePicker
        FechaFinalLabel                 matlab.ui.control.Label
        Edit_StartDate                  matlab.ui.control.DatePicker
        FechaInicialLabel               matlab.ui.control.Label
        MduloFenolgicosPanel            matlab.ui.container.Panel
        Edit_Res                        matlab.ui.control.DropDown
        TasasderestauracinLabel         matlab.ui.control.Label
        ParmetrosTab                    matlab.ui.container.Tab
        Button_Save_Params              matlab.ui.control.StateButton
        ParamsMenu                      matlab.ui.container.TabGroup
        MdulometeorolgicohidrolgicoyfenolgicoTab  matlab.ui.container.Tab
        N_Pveg                          matlab.ui.control.EditField
        ParmetroshidrolgicosLabel       matlab.ui.control.Label
        Table_Hid                       matlab.ui.control.Table
        ParmetrosfenolgicosLabel        matlab.ui.control.Label
        Table_Fen                       matlab.ui.control.Table
        ParmetrosderestauracindelavegetacinLabel  matlab.ui.control.Label
        ParmetrosmeteorolgicosLabel     matlab.ui.control.Label
        Table_Veg                       matlab.ui.control.Table
        Table_Met                       matlab.ui.control.Table
        MdulosedimentolgicoTab          matlab.ui.container.Tab
        ParmetrosgeotcnicosLabel        matlab.ui.control.Label
        Table_Geo                       matlab.ui.control.Table
        ParmetrossedimentolgicosLabel   matlab.ui.control.Label
        Table_Sed                       matlab.ui.control.Table
        Params_CalidadAgua              matlab.ui.container.Tab
        ModelodePhLabel                 matlab.ui.control.Label
        Table_pH                        matlab.ui.control.Table
        RezagostemporalesLabel          matlab.ui.control.Label
        Table_Tau                       matlab.ui.control.Table
        ModeloADZQUASARLabel            matlab.ui.control.Label
        Table_ADZ                       matlab.ui.control.Table
        Table_The                       matlab.ui.control.Table
        ParmetrosparalacorrecindetasaportemperaturaLabel  matlab.ui.control.Label
        ModelodetemperaturadelsueloyaguaLabel  matlab.ui.control.Label
        Table_Tsa                       matlab.ui.control.Table
        ModelodesolidossuspendidostotalesLabel  matlab.ui.control.Label
        Table_SST                       matlab.ui.control.Table
        ModelodeconductividadelctricaLabel  matlab.ui.control.Label
        Table_CoE                       matlab.ui.control.Table
        ModelodeturbiedadLabel          matlab.ui.control.Label
        Table_Tur                       matlab.ui.control.Table
        ModelodecoliformestotalesLabel  matlab.ui.control.Label
        Table_CoT                       matlab.ui.control.Table
        ModelodefsforoLabel             matlab.ui.control.Label
        Table_Fos                       matlab.ui.control.Table
        ModelodenitrgenoLabel           matlab.ui.control.Label
        ModelodeescherichacoliLabel     matlab.ui.control.Label
        Table_EsC                       matlab.ui.control.Table
        ModelodereaireacinLabel         matlab.ui.control.Label
        Table_Air                       matlab.ui.control.Table
        ModeloDBOcarbonaceaLabel        matlab.ui.control.Label
        Table_DBO                       matlab.ui.control.Table
        Table_CaD                       matlab.ui.control.Table
        ParmetrosdeaplicacindecargasdifusasLabel  matlab.ui.control.Label
        Table_Nit                       matlab.ui.control.Table
        Edit_CalName                    matlab.ui.control.EditField
        NombredeconfiguracindeCalibracinLabel  matlab.ui.control.Label
        MuestreadorTab                  matlab.ui.container.Tab
        Button_CreateSampler            matlab.ui.control.StateButton
        TabGroup5                       matlab.ui.container.TabGroup
        PuntosytramosdecontrolTab_2     matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        MuestreadorTab_3                matlab.ui.container.Tab
        PuntofinaldetramodecontrolLamp  matlab.ui.control.Lamp
        PuntofinaldetramodecontrolLampLabel  matlab.ui.control.Label
        PuntoinicialdetramodecontrolLamp  matlab.ui.control.Lamp
        PuntoinicialdetramodecontrolLampLabel  matlab.ui.control.Label
        PuntodecontrolLamp              matlab.ui.control.Lamp
        PuntodecontrolLampLabel         matlab.ui.control.Label
        Clear_ControlBranch             matlab.ui.control.StateButton
        Create_ControlPoints            matlab.ui.control.StateButton
        TramosdeControlLabel            matlab.ui.control.Label
        PuntosdeControlLabel            matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Create_ControlBranch            matlab.ui.control.StateButton
        Clear_ControlPoints             matlab.ui.control.StateButton
        MapsMuestreo                    matlab.ui.control.UIAxes
        PuntosytramosdecontrolTab_3     matlab.ui.container.Tab
        Info_LC_3                       matlab.ui.control.StateButton
        Info_LC_2                       matlab.ui.control.StateButton
        Button_Load_Branch              matlab.ui.control.StateButton
        Button_Load_Points              matlab.ui.control.StateButton
        ListadodeTramosdeControlLabel   matlab.ui.control.Label
        Table_ControlBranch             matlab.ui.control.Table
        ListadodePuntosdeControlLabel   matlab.ui.control.Label
        Table_ControlPoint              matlab.ui.control.Table
        VariblesTab                     matlab.ui.container.Tab
        ListadodeVariablesParaMapasLabel  matlab.ui.control.Label
        Table_ListMapVar                matlab.ui.control.Table
        ListadodeVariablesParaEnmascararMapasEnCauceLabel  matlab.ui.control.Label
        Table_ListMapVar_StreamMask     matlab.ui.control.Table
        ListadodeVariablesParaMapasSoloEnCauceLabel  matlab.ui.control.Label
        Table_ListMapVar_Stream         matlab.ui.control.Table
        ListadodeVariablesParaTramosdeControlLabel  matlab.ui.control.Label
        Table_ControlBranchVar          matlab.ui.control.Table
        ListadodeVariablesParaPuntosdeControlLabel  matlab.ui.control.Label
        Table_ControlPointsVar          matlab.ui.control.Table
        FactoresTab                     matlab.ui.container.Tab
        FactoresdeConversindelaUnidadLabel  matlab.ui.control.Label
        Table_ConFactors                matlab.ui.control.Table
        UnidadesdeVariablesLabel        matlab.ui.control.Label
        Table_VarUnit                   matlab.ui.control.Table
        EtiquetasdeVariablesLabel       matlab.ui.control.Label
        Table_LabelVar                  matlab.ui.control.Table
        Edit_NameSampler                matlab.ui.control.EditField
        NombredeconfiguracindeMuestreadorLabel  matlab.ui.control.Label
        CiclodeCargasTab                matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        Info_LC                         matlab.ui.control.StateButton
        Button_Load_LC                  matlab.ui.control.StateButton
        Edit_NameLoadCycle              matlab.ui.control.EditField
        NombredeconfiguracindeCiclosLabel  matlab.ui.control.Label
        Button_CreateLoadCycle          matlab.ui.control.StateButton
        SelectCycle                     matlab.ui.control.DropDown
        SeleccionecicloparagrficarLabel  matlab.ui.control.Label
        N_Cycle                         matlab.ui.control.EditField
        NmerodeCiclosaconfigurarLabel   matlab.ui.control.Label
        Table_LoadCycle                 matlab.ui.control.Table
        PlotAx_LoadCycle                matlab.ui.control.UIAxes
        SeriesTab                       matlab.ui.container.Tab
        Info_TS                         matlab.ui.control.StateButton
        Button_Load_TS                  matlab.ui.control.StateButton
        Table_Series                    matlab.ui.control.Table
        NameTimeSeries                  matlab.ui.control.DropDown
        VariableDropDownLabel_2         matlab.ui.control.Label
        MapSeries                       matlab.ui.control.UIAxes
        Plot_TimeSeries                 matlab.ui.control.UIAxes
        MovimientosenMasaTab            matlab.ui.container.Tab
        MM_Lamp                         matlab.ui.control.Lamp
        MovimientosenmasaLampLabel      matlab.ui.control.Label
        Info_MM                         matlab.ui.control.StateButton
        Button_Load_MM                  matlab.ui.control.StateButton
        Edit_NameMM                     matlab.ui.control.EditField
        NombredeconfiguracindemovimientosenmasaLabel  matlab.ui.control.Label
        Button_CreateMM                 matlab.ui.control.StateButton
        Button_Plot_MM                  matlab.ui.control.StateButton
        N_MM                            matlab.ui.control.EditField
        NmerodemovimientosenmasaLabel   matlab.ui.control.Label
        Table_MM                        matlab.ui.control.Table
        MapsMM                          matlab.ui.control.UIAxes
        VertimientosTab                 matlab.ui.container.Tab
        Dumping_Lamp                    matlab.ui.control.Lamp
        VertimientosLampLabel           matlab.ui.control.Label
        Info_Dumping                    matlab.ui.control.StateButton
        Button_Load_Dumping             matlab.ui.control.StateButton
        Edit_NameDumping                matlab.ui.control.EditField
        NombredeconfiguracindevertimientosLabel  matlab.ui.control.Label
        Button_CreateDumping            matlab.ui.control.StateButton
        Button_Plot_Dumping             matlab.ui.control.StateButton
        NDumping                        matlab.ui.control.EditField
        NmerodevertimientosLabel        matlab.ui.control.Label
        Table_Dumping                   matlab.ui.control.Table
        MapsDumping                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Ruta de proyecto SIGA-CAL
        ProjectPath
        % Valor para No Values
        NoValue (1,1) double = -9999;
        % Nombre del escenario
        SceFileName
        
        NameVar = {'TA','TAmin','TAmax','TAran','TS','TW','HR','RS','PVer','U','Ux','Uy',...
            'LCL','LWC','EV','ETo','SMI','HU','LAI','psi','theta','ks','n2v','n2','C_usle',...
            'ETP','PHor','DTRjo','LAIjo','LAIopt','S0','S1','S2','S3','S4','S5','E0','E1',...
            'E2','E3','E4','E5','I0','I1','I2','I3','I4','O4','U2','U3','U4','U5','AF3',...
            'Q2','Q5','Q5a','Q5c','Q5d','Q5p','H2','H5','H5b','W5','W5b','P5','A5','R5',...
            'D5','n5l','n5b','n5','arcS2S','limS2S','areS2S','arcS2D','limS2D','areS2D',...
            'arcS5S','limS5S','areS5S','arcS5D','limS5D','areS5D','arcE2S','limE2S',...
            'areE2S','arcE2D','limE2D','areE2D','arcE2E','limE2E','areE2E','arcE5S',...
            'limE5S','areE5S','arcE5D','limE5D','areE5D','arcE5E','limE5E','areE5E',...
            'E2Smax','E2Dmax','arcE5Smax','limE5Smax','areE5Smax','arcI2D','limI2D',...
            'areI2D','arcI5D','limI5D','areI5D','ER5b','PRx','Lx','Fx','EntSed','SalSed',...
            'EST','EDT','E2ET','E5ET','EET','ESDET','Zw','arcDV','limDV','areDV','PFB',...
            'S0EC','S1ECp','S1ECs','S2EC','S3EC','S4EC','S0NO','S1NOp','S1NOs','S2NO',...
            'S3NO','S4NO','S0NH4','S1NH4p','S1NH4s','S2NH4','S3NH4','S4NH4','S0NO3',...
            'S1NO3p','S1NO3s','S2NO3','S3NO3','S4NO3','S0PO','S1POp','S1POs','S2PO',...
            'S3PO','S4PO','S0PI','S1PIp','S1PIs','S2PI','S3PI','S4PI','S0PO_fb',...
            'S1POp_fb','S1POs_fb','S2PO_fb','S3PO_fb','S0PI_fb','S1PIp_fb',...
            'S1PIs_fb','S2PI_fb','S3PI_fb','QEC','QNO3','QNH4','QNO','QPO','QPI',...
            'I1EC','I2EC','I3EC','I4EC','E2EC','E3EC','S0NO_miner','S1NOp_miner',...
            'S1NOs_miner','S2NO_miner','S3NO_miner','I1NO','I2NO','I3NO','I4NO',...
            'E2NO','E3NO','E4NO','I1NO3','I2NO3','I3NO3','I4NO3','E2NO3','E3NO3',...
            'E4NO3','S0NH4_nitri','S1NH4p_nitri','S1NH4s_nitri','S2NH4_nitri',...
            'S3NH4_nitri','I1NH4','I2NH4','I3NH4','I4NH4','E2NH4','E3NH4','E4NH4',...
            'I1PO','I2PO','I3PO','I4PO','E2PO','E3PO','E4PO','I1PI','I2PI','I3PI',...
            'I4PI','E2PI','E3PI','E4PI','OD','ODs','CDBO','CDBO5','CE','EC','CT',...
            'SST','TUR','NO3','NH4','NO','PO','PI','PT','pH','alk','CA'};
            
        % Unidades de las variables
        UnitVar = {'(oC)','(oC)','(oC)','(oC)','(oC)','(oC)','(kPa.kPa-1)','(MJ.m-2.d-1)',...
            '(m)','(m.d-1)','(m.d-1)','(m.d-1)','(m)','(kg.m-3)','(m)','(m)','(m.m-1)',...
            '(oC)','(m2.m-2)','(Adim.)','(Adim.)','(m.d-1)','(s.m-0.33)','(s.m-0.33)',...
            '(Adim.)','(m)','(m)','(d)','(m2.m-2)','(m2.m-2)','(m)','(m)','(m)','(m)',...
            '(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)','(m)',...
            '(m)','(m.d-1)','(m.d-1)','(m.d-1)','(m.d-1)','(m)','(m3.d-1)','(m3.d-1)',...
            '(m3.d-1)','(m3.d-1)','(m3.d-1)','(m3.d-1)','(m)','(m)','(m)','(m)','(m)',...
            '(m)','(m2)','(m)','(m)','(s.m-0.33)','(s.m-0.33)','(s.m-0.33)','(m3)','(m3)',...
            '(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)',...
            '(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)',...
            '(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)',...
            '(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(T.h.MJ-1.mm-1)','(Adim.)','(m)',...
            '(m)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m3)','(m)','(m3)',...
            '(m3)','(m3)','(m3.d-1)','(UFC)','(UFC)','(UFC)','(UFC)','(UFC)','(UFC)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(UFC.d-1.m-2)',...
            '(kg.d-1.m-2)','(kg.d-1.m-2)','(kg.d-1.m-2)','(kg.d-1.m-2)','(kg.d-1.m-2)',...
            '(UFC)','(UFC)','(UFC)','(UFC)','(UFC)','(UFC)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)',...
            '(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg)','(kg.m-3)',...
            '(kg.m-3)','(kg.m-3)','(kg.m-3)','(uS.cm-1)','(ufc.m-3)','(ufc.m-3)',...
            '(kg.m-3)','(NTU)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)',...
            '(Unidades_pH)','(Unidades_alk)','(Unidades_CA)'};
    end
    
    properties (Access = private)
        AppSIGA % Description
        AppProgress % Description
        % Número de variables de siga
        NumVarSIGA = 250 % Description
    end
    
    methods (Access = public)
        
        function cell_Novalue(app,x)
            if isnan(x)
                app.NoValue;
            end
        end
        
        function ExecutionType(app, value,varargin)            
            
            if nargin > 2
                ProB = waitbar(0.1, 'Cargando, por favor no cierre la ventana. ','Color',[1 1 1]);
            end
            % Tipo de ejecución del modelo
            if strcmp(value,'Hídrología')
                % Habilitar modelo geotécnico
                app.Edit_GeoExeType.Enable = 'off';
                % Evaluar modelo geotécnico
                app.Edit_GeoExeType.Value  = false;
                % Deshabilitar series de arcillas
                app.Edit_Clay.Enable       = 'off';
                % Deshabilitar series de arenas
                app.Edit_Sand.Enable       = 'off';
                % Deshabilitar series de limos
                app.Edit_Silt.Enable       = 'off';
                % Deshabilitar serie de movimientos en masa
                app.Edit_MM.Enable         = 'off';
                app.Edit_MM.Value          = 'NA';
                % Deshabilitar serie de vertimientos
                app.Edit_Dumping.Enable    = 'off';
                app.Edit_Dumping.Value     = 'NA';
                % Deshabilitar serie de ciclos de carga
                app.Edit_LoadCycle.Enable  = 'off';
                app.Edit_LoadCycle.Value   = 'NA';
                % Habilitar parametros de sedimentos
                app.Table_Sed.Enable       = 'off';
                pause(0.09)
                % Habilitar parametros geotécnicos
                app.Table_Geo.Enable       = 'off';
                pause(0.09)
                % Habilitar parametros de calidad del agua
                app.Table_CaD.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.2);
                end
                % Habilitar Parámetros DBO-Carbonacea
                app.Table_DBO.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Reaireación
                app.Table_Air.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Escherichia Coli
                app.Table_EsC.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.4);
                end
                % Habilitar Parámetros de Nitrógeno
                app.Table_Nit.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Fósforo
                app.Table_Fos.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Coliformes Totales
                app.Table_CoT.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.6);
                end
                % Habilitar Parámetros de Turbiedad
                app.Table_Tur.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Conductividad Eléctrica
                app.Table_CoE.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Solidos Suspendidos Totales
                app.Table_SST.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.8);
                end
                % Habilitar Parámetros de Theta para correción de temperatura
                app.Table_The.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Temperatura del Suelo y Agua
                app.Table_Tsa.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de ADZ-QUASAR
                app.Table_ADZ.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.9);
                end
                % Habilitar Parámetros de rezagos temporales
                app.Table_Tau.Enable       = 'off';   
                pause(0.09)
                if nargin > 2
                    waitbar(1);
                end
            elseif strcmp(value,'Hídrología+Sedimentos')
                % Habilitar modelo geotécnico
                app.Edit_GeoExeType.Enable = true;
                % Deshabilitar series de arcillas
                app.Edit_Clay.Enable       = 'on';
                % Deshabilitar series de arenas
                app.Edit_Sand.Enable       = 'on';
                % Deshabilitar series de limos
                app.Edit_Silt.Enable       = 'on';
                % Deshabilitar serie de movimientos en masa
                app.Edit_MM.Enable         = 'on';  
                % Deshabilitar serie de vertimientos
                app.Edit_Dumping.Enable    = 'off';
                app.Edit_Dumping.Value     = 'NA';
                % Deshabilitar serie de ciclos de carga
                app.Edit_LoadCycle.Enable  = 'off';
                app.Edit_LoadCycle.Value   = 'NA';
                % Habilitar parametros de sedimentos
                app.Table_Sed.Enable       = 'on';
                pause(0.09)
                % Habilitar parametros de calidad del agua
                app.Table_CaD.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.2);
                end
                % Habilitar Parámetros DBO-Carbonacea
                app.Table_DBO.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Reaireación
                app.Table_Air.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Escherichia Coli
                app.Table_EsC.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.4);
                end
                % Habilitar Parámetros de Nitrógeno
                app.Table_Nit.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Fósforo
                app.Table_Fos.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Coliformes Totales
                app.Table_CoT.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.6);
                end
                % Habilitar Parámetros de Turbiedad
                app.Table_Tur.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Conductividad Eléctrica
                app.Table_CoE.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Solidos Suspendidos Totales
                app.Table_SST.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.8);
                end
                % Habilitar Parámetros de Theta para correción de temperatura
                app.Table_The.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de Temperatura del Suelo y Agua
                app.Table_Tsa.Enable       = 'off';
                pause(0.09)
                % Habilitar Parámetros de ADZ-QUASAR
                app.Table_ADZ.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(0.9);
                end
                % Habilitar Parámetros de rezagos temporales
                app.Table_Tau.Enable       = 'off';
                pause(0.09)
                if nargin > 2
                    waitbar(1);
                end
            elseif strcmp(value,'Hídrología+Sedimentos+CalidadAgua')
                % Deshabilitar series de arcillas
                app.Edit_Clay.Enable       = 'on';
                % Deshabilitar series de arenas
                app.Edit_Sand.Enable       = 'on';
                % Deshabilitar series de limos
                app.Edit_Silt.Enable       = 'on';
                % Deshabilitar serie de movimientos en masa
                app.Edit_MM.Enable         = 'on';    
                % Deshabilitar serie de vertimientos
                app.Edit_Dumping.Enable    = 'on';   
                % Deshabilitar serie de ciclos de carga
                app.Edit_LoadCycle.Enable  = 'on';   
                % Habilitar parametros de sedimentos
                app.Table_Sed.Enable       = 'on';
                pause(0.09)
                % Habilitar parametros de calidad del agua
                app.Table_CaD.Enable       = 'on';
                pause(0.09)
                if nargin > 2
                    waitbar(0.2);
                end
                % Habilitar Parámetros DBO-Carbonacea
                app.Table_DBO.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Reaireación
                app.Table_Air.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Escherichia Coli
                app.Table_EsC.Enable       = 'on';
                pause(0.09)
                if nargin > 2
                    waitbar(0.4);
                end
                % Habilitar Parámetros de Nitrógeno
                app.Table_Nit.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Fósforo
                app.Table_Fos.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Coliformes Totales
                app.Table_CoT.Enable       = 'on';
                pause(0.09)
                if nargin > 2
                    waitbar(0.6);
                end
                % Habilitar Parámetros de Turbiedad
                app.Table_Tur.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Conductividad Eléctrica
                app.Table_CoE.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Solidos Suspendidos Totales
                app.Table_SST.Enable       = 'on';
                pause(0.09)
                if nargin > 2
                    waitbar(0.8);
                end
                % Habilitar Parámetros de Theta para correción de temperatura
                app.Table_The.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de Temperatura del Suelo y Agua
                app.Table_Tsa.Enable       = 'on';
                pause(0.09)
                % Habilitar Parámetros de ADZ-QUASAR
                app.Table_ADZ.Enable       = 'on';
                pause(0.09)
                if nargin > 2
                    waitbar(0.9);
                end
                % Habilitar Parámetros de rezagos temporales
                app.Table_Tau.Enable       = 'on';
                pause(0.09)
                % Habilitar modelo geotécnico
                app.Edit_GeoExeType.Enable = true;
                if nargin > 2
                    waitbar(1);
                end
            end  
            if nargin > 2
                close(ProB)
            end
        end   
        
        function Plot_ControlPoints(app)
            global Ax3
            delete(Ax3)
            
            % -------------------------------------------------------------------
            % leer puntos de control de app
            % -------------------------------------------------------------------
            ControlPoints  = app.Table_ControlPoint.Data;
            id      = find(~isnan(cell2mat(ControlPoints(:,1))));
            ControlPoints  = ControlPoints(id,:); 
            
            % -------------------------------------------------------------------
            % Plot puntos de control
            % -------------------------------------------------------------------
            Xp = cell2mat(ControlPoints(:,1));
            Yp = cell2mat(ControlPoints(:,2));
            Ax3 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.5882 0 0.0941],...
                    'MarkerFaceColor',[0.5882 0 0.0941],...
                    'LineWidth',1.5);
        end
        
        function Plot_ControlBranch_Start(app)
            global Ax4
            delete(Ax4)
            
            % -------------------------------------------------------------------
            % leer tramos de control de app
            % -------------------------------------------------------------------
            ControlBranch  = app.Table_ControlBranch.Data;
            id      = find(~isnan(cell2mat(ControlBranch(:,1))));
            ControlBranch  = ControlBranch(id,:); 
            
            % -------------------------------------------------------------------
            % Plot tramos de control iniciales
            % -------------------------------------------------------------------
            Xp = cell2mat(ControlBranch(:,1));
            Yp = cell2mat(ControlBranch(:,2));
            Ax4 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0 .5 .5],...
                    'MarkerFaceColor',[0 .5 .5],...
                    'LineWidth',1.5);
                
        end
        
        function Plot_ControlBranch_End(app)
            global Ax5
            delete(Ax5)
            
            % -------------------------------------------------------------------
            % leer tramos de control de app
            % -------------------------------------------------------------------
            ControlBranch  = app.Table_ControlBranch.Data;
            id      = find(~isnan(cell2mat(ControlBranch(:,1))));
            ControlBranch  = ControlBranch(id,:); 
            
            % -------------------------------------------------------------------
            % Plot tramos de control finales
            % -------------------------------------------------------------------
            Xp = cell2mat(ControlBranch(:,3));
            Yp = cell2mat(ControlBranch(:,4));
            Ax5 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.9 .7 .2],...
                    'MarkerFaceColor',[0.9 .7 .2],...
                    'LineWidth',1.5);
        end               
        
        function Plot_Basin(app,UserDataTopo,NameAxes)  
            eval(['cla(app.',NameAxes,',''reset'')'])
            eval(['app.',NameAxes,'.BackgroundColor = [1 1 1];'])    
            
            % -------------------------------------------------------------------
            % Graficar cuenca, puntos y tramos de control
            % -------------------------------------------------------------------
            % Plot de DEM de la cuenca
            [CoorX,CoorY]  = meshgrid(UserDataTopo.XX,UserDataTopo.YY);
            Z              = CoorX*NaN;
            Z(UserDataTopo.IX) = UserDataTopo.Z;
            
            % Generar mapa de sombras de la cuenca (hillshade)
            OUT = double(hillshade(Z,sqrt(UserDataTopo.AreaPixel)));
            OUT(OUT == 0) = NaN;
            
            % Plot modelo de sombra de la cuenca
            eval(['imagesc(app.',NameAxes,',UserDataTopo.XX,UserDataTopo.YY,OUT,''AlphaData'',~isnan(OUT));']);
            eval(['set(app.',NameAxes,',''YDir'',''normal'');']) 
            eval(['colormap(app.',NameAxes,',gray)']);
            
            % Mantener figuras
            eval(['hold(app.',NameAxes,')'])
            
            % Configuración de cuerpos de agua
            Stream = CoorX*NaN;
            Stream(UserDataTopo.IX(UserDataTopo.Stream)) = 1;  
            Stream(UserDataTopo.IX(UserDataTopo.Dams))   = NaN;  
            
            Dams = CoorX*NaN;
            Dams(UserDataTopo.IX(UserDataTopo.Dams)) = 1;  
            
            % Plot cuerpos de agua
            eval(['imagesc(app.',NameAxes,',UserDataTopo.XX,UserDataTopo.YY,Stream,''AlphaData'',~isnan(Stream));'])
            eval(['imagesc(app.',NameAxes,',UserDataTopo.XX,UserDataTopo.YY,Dams,''AlphaData'',~isnan(Dams));'])
            eval(['set(app.',NameAxes,',''YDir'',''normal'');']) 
            
            % Configuraciones estéticas
            eval(['xlabel(app.',NameAxes,',''Coordenadas Este (m)'', ''interpreter'',''latex'', ''Fontsize'',18)'])
            eval(['ylabel(app.',NameAxes,',''Coordenadas Norte (m)'',''interpreter'',''latex'', ''Fontsize'',18)'])   
            eval(['title(app.',NameAxes,','''',''interpreter'',''latex'', ''Fontsize'',18)']) 
            eval(['set(app.',NameAxes,',''TickLabelInterpreter'',''latex'',''FontWeight'',''bold'',''Color'',''none'',''box'',''on'',''FontSize'',15)'])    
            
            % Ajustar límites 
            Xmax_B = max(UserDataTopo.X);
            Ymax_B = max(UserDataTopo.Y);
            Xmin_B = min(UserDataTopo.X);
            Ymin_B = min(UserDataTopo.Y);
            
            dx_B = Xmax_B - Xmin_B;
            dy_B = Ymax_B - Ymin_B;
            
            RB = dx_B/dy_B;
            
            if RB < 1
                Factor_X = (1 - RB)*0.55;
                Factor_Y = 0.05;
            else
                Factor_Y = (1 - (1/RB))*0.55;
                Factor_X = 0.05;
            end          
            
            eval(['xlim(app.',NameAxes,',[(Xmin_B - (dx_B*Factor_X)) (Xmax_B + (dx_B*Factor_X))])'])
            eval(['ylim(app.',NameAxes,',[(Ymin_B - (dy_B*Factor_Y)) (Ymax_B + (dy_B*Factor_Y))])'])

        end                           
        
        function Plot_MM(app)
            global Ax_MM
            delete(Ax_MM)
            
            Data = app.Table_MM.Data;                   
                                
            % Plot movimientos en masa
            Xp = Data(:,4);
            Yp = Data(:,5);
            Ax_MM = scatter(app.MapsMM,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.5882 0 0.0941],...
                    'MarkerFaceColor',[0.5882 0 0.0941],...
                    'LineWidth',1.5); 
            
        end
        
        function Plot_Dumping(app)
            global Ax_Dumping
            delete(Ax_Dumping)
            
            Data = app.Table_Dumping.Data;
                        
            % Plot veritimientos en mapa
            Xp = cell2mat(Data(:,2));
            Yp = cell2mat(Data(:,3));
            Ax_Dumping = scatter(app.MapsDumping,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.5882 0 0.0941],...
                    'MarkerFaceColor',[0.5882 0 0.0941],...
                    'LineWidth',1.5);
            
        end
        function Plot_LoadCycle(app)
            
            global Ax_Cycle
            delete(Ax_Cycle)
            
            value = app.SelectCycle.Value;
            Data  = app.Table_LoadCycle.Data;
            Date  = Data(:,1);
            Data  = Data(:,2:end);
            
            [fil, NCycle] = size(Data);
            
            if strcmp(value, 'Todos')
                
                % Plot gráfica
                Ax_Cycle = plot(app.PlotAx_LoadCycle,Date, Data);                
                xlim(app.PlotAx_LoadCycle,[1 366])
                ylim(app.PlotAx_LoadCycle,[0 max(max(Data))*1.1])
                
                % Configuraciones estéticas
                NameAxes = 'PlotAx_LoadCycle'; 
                xlabel(app.PlotAx_LoadCycle,'Cantidad de d\''ias', 'interpreter','latex', 'Fontsize',18)
                eval(['ylabel(app.',NameAxes,',''Valor ${(\%)}$'',''interpreter'',''latex'', ''Fontsize'',18)'])   
                eval(['title(app.',NameAxes,',''Ciclos de cargas difusas'',''interpreter'',''latex'', ''Fontsize'',18)']) 
                eval(['set(app.',NameAxes,',''TickLabelInterpreter'',''latex'',''FontWeight'',''bold'',''Color'',''none'',''box'',''on'',''FontSize'',15)'])
            
                % Plot etiquetas
                LabelName = {'Ciclo - 1'};
                for j = 2:NCycle
                    LabelName{j} = ['Ciclo - ',num2str(j)];
                end
                legend(app.PlotAx_LoadCycle,LabelName,'interpreter','latex',"Location","bestoutside")
                
            else                
                value = str2double(strrep(value,'Ciclo - ',''));
                
                if sum(isnan(Data(:,value))) ~= length(Data(:,value))
                    % Plot gráfica
                    Ax_Cycle = plot(app.PlotAx_LoadCycle,Date, Data(:,value));                
                    xlim(app.PlotAx_LoadCycle,[1 366])
                    ylim(app.PlotAx_LoadCycle,[0 max(Data(:,value))*1.1])
                end                               
                
                % Configuraciones estéticas
                NameAxes = 'PlotAx_LoadCycle'; 
                xlabel(app.PlotAx_LoadCycle,'Cantidad de d\''ias', 'interpreter','latex', 'Fontsize',18)
                xlabel(app.PlotAx_LoadCycle,'Valor ${(\%)}$','interpreter','latex', 'Fontsize',18)
                title(app.PlotAx_LoadCycle,['Ciclos - ',num2str(value), ' de cargas difusas'],'interpreter','latex', 'Fontsize',18) 
                eval(['set(app.',NameAxes,',''TickLabelInterpreter'',''latex'',''FontWeight'',''bold'',''Color'',''none'',''box'',''on'',''FontSize'',15)'])
            
                % Plot etiquetas
                legend(app.PlotAx_LoadCycle,['Ciclos - ',num2str(value)],'interpreter','latex',"Location","bestoutside")
                
            end        
        end
        
        function UserData = Read_Execution_App(app)
            % Nombre del archivo de ejecución                       
            UserData.NameSce    = app.Edit_ExeFileName.Value;
            % Paso de tiempo del modelo
            UserData.dt         = 1.0;
            % Fecha de inicio de la simulación
            UserData.StartDate  = app.Edit_StartDate.Value;
            % Fecha de finalización de la simulación
            UserData.EndDate    = app.Edit_EndDate.Value;
            % Nombre del archivo de la topología
            UserData.Topo       = app.Edit_Topo.Value ;
            % Nombre del archivo de series de tiempo de temperatura mínima
            UserData.Tmin       = app.Edit_Tmin.Value;
            % Nombre del archivo de series de tiempo de temperatura máxima
            UserData.Tmax       = app.Edit_Tmax.Value;
            % Nombre del archivo de series de tiempo de humedad relativa
            UserData.HR         = app.Edit_HR.Value;
            % Nombre del archivo de series de tiempo de radiación solar
            UserData.RS         = app.Edit_RS.Value;
            % Nombre del archivo de series de tiempo de precipitación
            UserData.P          = app.Edit_P.Value;
            % Nombre del archivo de series de tiempo de viento zonal
            UserData.VZ         = app.Edit_VZ.Value;
            % Nombre del archivo de series de tiempo de viento meridional
            UserData.VM         = app.Edit_VM.Value;
            % Nombre del archivo de caudal líquido descargado
            UserData.Q_LD       = app.Edit_Q_LD.Value;
            % Nombre del archivo de caudal líquido captado
            UserData.Q_LC       = app.Edit_Q_LC.Value;
            % Nombre del archivo de series de tiempo de arcillas
            UserData.Clay       = app.Edit_Clay.Value;
            % Nombre del archivo de series de tiempo de limos
            UserData.Silt       = app.Edit_Silt.Value;
            % Nombre del archivo de series de tiempo de arenas
            UserData.Sand       = app.Edit_Sand.Value;
            % Nombre del archivo de movimientos en masa
            UserData.MM         = app.Edit_MM.Value;
            % Nombre del archivo de factores de calibración
            UserData.F_Cal      = app.Edit_FacCal.Value;
            % Nombre del archivo muestreador
            UserData.Sampler    = app.Edit_Sampler.Value;
            % Nombre del archivo de tasas de restauración
            UserData.ResRate    = app.Edit_Res.Value;
            % Nombre del archivo de puntos de vertimiento
            UserData.Dumping    = app.Edit_Dumping.Value;
            % Nombre del archivo de ciclos de cargas difusas
            UserData.LoadCycle  = app.Edit_LoadCycle.Value;
            % Nombre del archivo de condiciones de frontera
            UserData.BoundaryC  = app.Edit_Boundary.Value;
            
            % Asignación de txt
            NameField = fieldnames(UserData);
            for i = 5:length(NameField)                
                eval(['Tmp = ~strcmp(UserData.',NameField{i},',''NA'');']);
                if Tmp
                    eval(['UserData.',NameField{i},' = [UserData.',NameField{i},',''.txt''];']);
                end                               
            end
            
            % Tipo de ejecución del modelo
            if strcmp(app.Edit_SIGAExeType.Value,'Hídrología')
                UserData.ExeType = 1;               
            elseif strcmp(app.Edit_SIGAExeType.Value,'Hídrología+Sedimentos')
                UserData.ExeType = 2;
            elseif strcmp(app.Edit_SIGAExeType.Value,'Hídrología+Sedimentos+CalidadAgua')
                UserData.ExeType = 3;
            end
            
            % Evaluar modelo geotécnico
            UserData.GeoExeType = app.Edit_GeoExeType.Value;   

            % Evaluar modelo de pH caliente
            UserData.pHExeType = app.Edit_pHExeType.Value;   
        end
        
        function [StatusError,UserData] = Read_Calibration_App(app)  
            StatusError = false;             
            % Parámetros de Restauración
            Tmp             = app.Table_Veg.Data;
            if isempty(Tmp)
                Tmp = [0 app.NoValue 0 0 0];
            end
            [fil, col]      = size(Tmp);
            UserData.n_rest = fil;
            UserData.Veg    = Tmp;            
            % Parámetros Fenológicos
            Tmp             = app.Table_Fen.Data;
            [fil, col]      = size(Tmp);
            UserData.n_feno = fil;
            UserData.Fen    = Tmp;            
            % Parámetros de aplicación de Cargas Difusas
            Tmp         = app.Table_CaD.Data;
            id          = cellfun(@isempty,Tmp);
            Tmp(id)     = {NaN};
            Tmp         = cell2mat(Tmp);
            id          = sum(isnan(Tmp),2) == 7;
            Tmp(id,:)   = [];
            
            if sum(sum(isnan(Tmp),2)) > 0
                StatusError = true;
                Text = ['Módulo - Parámetros | Cargas Difusas',newline,...
                    'Los párametros de cargas difusas no admiten valores vacios o NaN'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            [fil, col]      = size(Tmp);                        
            UserData.n_load = fil;
            UserData.CaD    = Tmp;
            % Parámetros Meteorológicos
            UserData.Met = app.Table_Met.Data;
            % Parámetros Hidrológicos
            UserData.Hid = app.Table_Hid.Data;
            % Parámetros Sedimentológicos
            UserData.Sed = app.Table_Sed.Data;
            % Parámetros Geotécnicos
            UserData.Geo = app.Table_Geo.Data;                     
            % Parámetros DBO-Carbonacea
            UserData.DBO = app.Table_DBO.Data;
            % Parámetros de Reaireación
            UserData.Air = app.Table_Air.Data;
            % Parámetros de Escherichia Coli
            UserData.EsC = app.Table_EsC.Data;
            % Parámetros de Nitrógeno
            UserData.Nit = app.Table_Nit.Data;
            % Parámetros de Fósforo
            UserData.Fos = app.Table_Fos.Data;
            % Parámetros de Coliformes Totales
            UserData.CoT = app.Table_CoT.Data;
            % Parámetros de Turbiedad
            UserData.Tur = app.Table_Tur.Data;
            % Parámetros de Conductividad Eléctrica
            UserData.CoE = app.Table_CoE.Data;
            % Parámetros de Solidos Suspendidos Totales
            UserData.SST = app.Table_SST.Data;
            % Parámetros de Theta para correción de temperatura
            UserData.The = app.Table_The.Data;
            % Parámetros de Temperatura del Suelo y Agua
            UserData.Tsa = app.Table_Tsa.Data;
            % Parámetros de ADZ-QUASAR
            UserData.ADZ = app.Table_ADZ.Data;
            % Parámetros de rezagos temporales
            UserData.Tau = app.Table_Tau.Data;
            % Parámetros ICA-EPM
            UserData.ICA = 1;
            % Parámetros de pH
            UserData.pH  = app.Table_pH.Data;
            
            NameParams = {  '',...
                            'parámetros de Restauración',...
                            '',...
                            'parámetros Fenológicos',...
                            '',...
                            'parámetros de aplicación de Cargas Difusas',...
                            'parámetros Meteorológicos',...
                            'parámetros Hidrológicos',...
                            'parámetros Sedimentológicos',...
                            'parámetros Geotécnicos',...
                            'parámetros DBO-Carbonacea',...
                            'parámetros de Reaireación',...
                            'parámetros de Escherichia Coli',...
                            'parámetros de Nitrógeno',...
                            'parámetros de Fósforo',...
                            'parámetros de Coliformes Totales',...
                            'parámetros de Turbiedad',...
                            'parámetros de Conductividad Eléctrica',...
                            'parámetros de Solidos Suspendidos Totales',...
                            'parámetros de Theta para correción de temperatura',...
                            'parámetros de Temperatura del Suelo y Agua',...
                            'parámetros de ADZ-QUASAR',...
                            'parámetros de rezagos temporales'};            
                
            % check parámetros fenológicos
            if sum(sum(isnan(UserData.Fen(:,[1:5, 19:21]))),2) > 0
                StatusError = true;
                Text = ['Módulo - Parámetros',newline,...
                    'Los párametros [CLC, psi,theta,n2,C_usle,KCmin,KCmax,etah] en el módulo fenológico no pueden ser NaN - Corregir'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % check de parámetros de restauración
            if sum(sum(isnan(UserData.Veg(:,[1,3:5]))),2) > 0
                StatusError = true;
                Text = ['Módulo - Parámetros',newline,...
                    'Los párametros [Transicion,Deltatm,idQ,Accion] en el módulo de restauración de la vegetación no pueden ser NaN - Corregir'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check valores NaN
            NameField = fieldnames(UserData);
            for i = 6:length(NameField)                
                eval(['Tmp = sum(isnan(UserData.',NameField{i},'));']);
                if Tmp > 0
                    StatusError = true;
                    Text = ['Módulo - Parámetros',newline,...
                        'Los ', NameParams{i},' contienen valores NaN - Corregir'];
                    f = errordlg(Text);
                    set(f, 'color',[1 1 1]);
                    return
                end                               
            end
            
            % Check valores negativos
            for i = 6:length(NameField)                
                eval(['Tmp = sum(UserData.',NameField{i},' < 0);']);
                if Tmp > 0
                    StatusError = true;
                    Text = ['Módulo - Parámetros',newline,...
                        'Los ', NameParams{i},' contienen negativos - Corregir'];
                    f = errordlg(Text);
                    set(f, 'color',[1 1 1]);
                    return
                end                               
            end
            
            % Check NoValue            
            for i = 1:length(NameField) - 1               
                eval(['UserData.',NameField{i},'(isnan(UserData.',NameField{i},')) = app.NoValue;']);
            end
            
            % Parámetros Meteorológicos
            UserData.Met = [repmat(app.NoValue,1,14), UserData.Met];
        end
        
        function [StatusError,UserData] = Read_Sampler_App(app)    
            StatusError = false;
            % Variables para extraer en mapas
            UserData.Var_Map                = num2cell([app.Table_ListMapVar.Data]); 
            % Variables para extraer en mapas solo en cauce
            UserData.Var_MapVarStream       = num2cell([app.Table_ListMapVar_Stream.Data]);
            % Variables para enmascarar en cauce
            UserData.Var_MapVarStreamMask   = num2cell([app.Table_ListMapVar_StreamMask.Data]);
            % Variables para extraer en los puntos de control
            UserData.Var_ControlPoints      = num2cell([app.Table_ControlPointsVar.Data]);
            % Variables para extraer en los tramos de control
            UserData.Var_ControlBranch      = num2cell([app.Table_ControlBranchVar.Data]); 
            
            % Unidades de las variables
            UserData.Var_Unit               = [app.Table_VarUnit.Data];
            
            % Factores de conversión
            UserData.ConversionFactors      = [app.Table_ConFactors.Data];
            
            % Check valores NaN
            if sum(isnan(UserData.ConversionFactors)) > 0
                StatusError = true;
                Text = ['Módulo - Muestreador',newline,...
                    'Los factores de conversión no pueden ser NaN - Corregir'];
                f = errordlg(Text);                
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Puntos de control
            % leer puntos de control de app
            ControlPoints   = app.Table_ControlPoint.Data;
            id              = ~isnan(cell2mat(ControlPoints(:,1)));
            ControlPoints   = ControlPoints(id,:);              
            UserData.ControlPoints = ControlPoints;
            
            % Número de puntos de control
            UserData.N_CP   = length(ControlPoints(:,1));
            
            % Tramos de control
            ControlBranch   = app.Table_ControlBranch.Data;
            id              = ~isnan(cell2mat(ControlBranch(:,1)));
            ControlBranch   = ControlBranch(id,:);
            UserData.ControlBranch = ControlBranch;
            
            % Número de tramos de control
            UserData.N_CB   = length(ControlBranch(:,1)); 
        end
        
        function [StatusError,UserData] = Read_Dumping_App(app)
            StatusError = false;
            
            % Leer Datos de vertimientos
            Data = app.Table_Dumping.Data;  
            id   = sum(cellfun(@isempty,Data),2) == 18;
            Data = Data(~id,:);
            
            % Check de nombres
            if sum(cellfun(@isempty,Data(:,1))) > 0
                UserData = 0;
                StatusError = true;
                Text = ['Módulo - Vertimientos',newline,...
                    'Todos los puntos de vertimientos deben tener nombre. Revisar'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check coordenadas 
            if ( sum(sum(isnan(cell2mat(Data(:,2:3))))) > 0 ) || ( sum(sum(cellfun(@isempty,Data(:,2:3))),2) > 0 )
                UserData = 0;
                StatusError = true;
                Text = ['Módulo - Vertimientos',newline,...
                    'La columnas X y Y no pueden contener valores NaN o vacios. Revisar'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Asignar NaN a valores nulos
            id          = cellfun(@isempty,Data);
            Data(id)    = {NaN};
            
            % Asignar valor a NaN
            id          = cellfun(@isnan,Data,'UniformOutput',false);
            id          = cellfun(@sum,id,'UniformOutput',false);
            id          = logical(cell2mat(id));
            Data(id)    = {app.NoValue};
            
            % Asignar datos
            UserData.Data = Data;            
            % Número de vertimientos
            UserData.N = length(UserData.Data(:,1));
            % Valor no data
            UserData.NoValue = app.NoValue;            
        end
        
        function [StatusError,UserData] = Read_MM_App(app)
            StatusError = false;
            % Leer Datos de vertimientos
            Data = app.Table_MM.Data;
            id   = sum(isnan(Data),2) == 6;
            Data(id) = [];
            
            % Check NaN Values
            if sum(sum(isnan(Data(:,1:5)),2)) > 0
                UserData = 0;
                StatusError = true;
                Text = ['Módulo - Movimientos en Masa',newline,...
                    'Las columnas Año, Mes, Día, X y Y no pueden contener valores NaN'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check valores negativos
            if sum(sum((Data(:,1:3) < 0),2)) > 0
                UserData = 0;
                StatusError = true;
                Text = ['Módulo - Movimientos en Masa',newline,...
                    'Las columnas Año, Mes y Día no pueden contener valores negativos'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Asignar valores NaN
            Data(isnan(Data)) = app.NoValue;            
            % Datos de movimientos en masa
            UserData.Data = Data;
            % Número de vertimientos
            UserData.N = length(UserData.Data(:,1));
            % Valor no data
            UserData.NoValue = app.NoValue;            
        end
        
        function [StatusError,UserData] = Read_LoadCycle_App(app)
            StatusError = false;
            % Leer Datos de ciclos de la app
            Data                = app.Table_LoadCycle.Data;
            if sum(isnan(Data(:))) > 0
                StatusError = true;
                Text = ['Módulo - Ciclos de carga',newline,...
                    'Las series de ciclos de carga no pueden contener valores NaN'];
                f = errordlg(Text);
                set(f, 'color',[1 1 1]);
                return
            end
            % Nombre de variables
            UserData.NameVar    = 'Vertimientos';
            % Número de dias
            UserData.Pday       = 366;
            % Número de ciclos            
            UserData.NCycle     = length(Data(1,2:end));
            % Paso de tiempo
            UserData.dt         = 1;
            % Tipo de modelo
            UserData.Modelo     = 'ninguno';
            UserData.CodeCycle  = 1:length(Data(1,2:end));
            UserData.Date       = Data(:,1);
            UserData.Data       = Data(:,2:end);                 
        end
        
        function Asignation_List_files_Execution(app)
            % Nombre del archivo de la topología
            Path        = fullfile(app.ProjectPath,'entradas','topologia','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Topo.Items         = FileList;
            % Nombre del archivo de series de tiempo de temperatura mínima
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TAmin_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Tmin.Items         = FileList;
            % Nombre del archivo de series de tiempo de temperatura máxima
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TAmax_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Tmax.Items         = FileList;
            % Nombre del archivo de series de tiempo de humedad relativa
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_HR_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_HR.Items           = FileList;
            % Nombre del archivo de series de tiempo de radiación solar
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_RS_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_RS.Items           = FileList;
            % Nombre del archivo de series de tiempo de precipitación
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_PVer_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_P.Items            = FileList;
            % Nombre del archivo de series de tiempo de viento zonal
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Ux_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_VZ.Items           = FileList;
            % Nombre del archivo de series de tiempo de viento meridional
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Uy_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_VM.Items           = FileList;
            % Nombre del archivo de caudal líquido descargado
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Qld_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Q_LD.Items         = FileList;
            % Nombre del archivo de caudal líquido captado
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Qlc_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Q_LC.Items         = FileList;
            % Nombre del archivo de series de tiempo de arcillas
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Clay_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Clay.Items         = FileList;
            % Nombre del archivo de series de tiempo de limos
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Silt_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Silt.Items         = FileList;
            % Nombre del archivo de series de tiempo de arenas
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Sand_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Sand.Items         = FileList;
            % Nombre del archivo de movimientos en masa
            Path        = fullfile(app.ProjectPath,'entradas','movmasa','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_MM.Items           = FileList;
            % Nombre del archivo de factores de calibración
            Path        = fullfile(app.ProjectPath,'entradas','calibracion','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_FacCal.Items       = FileList;
            % Nombre del archivo muestreador
            Path        = fullfile(app.ProjectPath,'entradas','muestras','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Sampler.Items      = FileList;
            % Nombre del archivo de tasas de restauración
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TasRest_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Res.Items          = FileList;
            % Nombre del archivo de puntos de vertimiento
            Path        = fullfile(app.ProjectPath,'entradas','vertimientos','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Dumping.Items      = FileList;
            % Nombre del archivo de ciclos de cargas difusas
            Path        = fullfile(app.ProjectPath,'entradas','ciclos','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_LoadCycle.Items    = FileList;         
            % Nombre del archivo condiciones de frontera
            Path        = fullfile(app.ProjectPath,'entradas','condicionesfrontera','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Boundary.Items      = FileList;
        end
        
        function Asignation_Execution(app, UserData_Exe,SceFileName)
            % Nombre del archivo de ejecución
            app.Edit_ExeFileName.Value  = SceFileName;
            % Fecha de inicio de la simulación
            app.Edit_StartDate.Value    = UserData_Exe.StartDate;
            % Fecha de finalización de la simulación
            app.Edit_EndDate.Value      = UserData_Exe.EndDate;            
            % Leer arhivos de carpetas para lista desplegable
            app.Asignation_List_files_Execution;
            % Nombre del archivo de la topología
            app.Edit_Topo.Value         = UserData_Exe.Topo;                
            % Nombre del archivo de series de tiempo de temperatura mínima
            app.Edit_Tmin.Value         = UserData_Exe.Tmin;
            % Nombre del archivo de series de tiempo de temperatura máxima
            app.Edit_Tmax.Value         = UserData_Exe.Tmax;
            % Nombre del archivo de series de tiempo de humedad relativa
            app.Edit_HR.Value           = UserData_Exe.HR;
            % Nombre del archivo de series de tiempo de radiación solar
            app.Edit_RS.Value           = UserData_Exe.RS;
            % Nombre del archivo de series de tiempo de precipitación
            app.Edit_P.Value            = UserData_Exe.P;
            % Nombre del archivo de series de tiempo de viento zonal
            app.Edit_VZ.Value           = UserData_Exe.VZ;
            % Nombre del archivo de series de tiempo de viento meridional
            app.Edit_VM.Value           = UserData_Exe.VM;
            % Nombre del archivo de caudal líquido descargado
            app.Edit_Q_LD.Value         = UserData_Exe.Q_LD;
            % Nombre del archivo de caudal líquido captado
            app.Edit_Q_LC.Value         = UserData_Exe.Q_LC;
            % Nombre del archivo de series de tiempo de arcillas
            app.Edit_Clay.Value         = UserData_Exe.Clay;
            % Nombre del archivo de series de tiempo de limos
            app.Edit_Silt.Value         = UserData_Exe.Silt;
            % Nombre del archivo de series de tiempo de arenas
            app.Edit_Sand.Value         = UserData_Exe.Sand;
            % Nombre del archivo de movimientos en masa
            app.Edit_MM.Value           = UserData_Exe.MM;
            % Nombre del archivo de factores de calibración
            app.Edit_FacCal.Value       = UserData_Exe.F_Cal;
            % Nombre del archivo muestreador
            app.Edit_Sampler.Value      = UserData_Exe.Sampler;
            % Nombre del archivo de tasas de restauración
            app.Edit_Res.Value          = UserData_Exe.ResRate;
            % Nombre del archivo de puntos de vertimiento
            app.Edit_Dumping.Value      = UserData_Exe.Dumping;
            % Nombre del archivo de ciclos de cargas difusas
            app.Edit_LoadCycle.Value    = UserData_Exe.LoadCycle;
            % Nombre del archivo condiciones de frontera
            app.Edit_Boundary.Value     = UserData_Exe.BoundaryC;
            
            % Tipo de ejecución del modelo
            if UserData_Exe.ExeType == 1
                app.Edit_SIGAExeType.Value = 'Hídrología';
            elseif UserData_Exe.ExeType == 2
                app.Edit_SIGAExeType.Value = 'Hídrología+Sedimentos';
            elseif UserData_Exe.ExeType == 3
                app.Edit_SIGAExeType.Value = 'Hídrología+Sedimentos+CalidadAgua';
            end
            % Evaluar modelo geotécnico
            if UserData_Exe.ExeType ~= 1
                % Habilitar modelo geotécnico
                app.Edit_GeoExeType.Enable = true;
                % Evaluar modelo geotécnico
                app.Edit_GeoExeType.Value  = UserData_Exe.GeoExeType;
            end

            % Evaluar modelo calidad de agua
            if UserData_Exe.ExeType ~= 1
                % Habilitar modelo pH
                app.Edit_pHExeType.Enable = true;
                % Evaluar modelo pH
                app.Edit_pHExeType.Value  = UserData_Exe.pHExeType;
            end
            
            % Activar o desactivar parametros
            app.ExecutionType(app.Edit_SIGAExeType.Value);
            % Activar o desactivar movimientos en masa
            app.Edit_MMValueChanged(0);
            % Activar o desactivar vertimientos
            app.Edit_DumpingValueChanged(0);
            % Activar o desactivar módulo geotecnico
            app.Edit_GeoExeTypeValueChanged(0)
            % Configuración - Módulo de series de tiempo                
            app.Asignation_ListTimeSeries;            
        end
        
        function Asignation_Execution_New(app)
            
            % Nombre del archivo de ejecución
            app.Edit_ExeFileName.Value  = 'Nueva_Ejecucion';
%             % Nombre del escenario
%             app.Edit_SceName.Value      = 'Nuevo_Escenario';
            % Fecha de inicio de la simulación
            app.Edit_StartDate.Value    = datetime(date);
            % Fecha de finalización de la simulación
            app.Edit_EndDate.Value      = datetime(date) + days;
            % Nombre del archivo de la topología
            Path        = fullfile(app.ProjectPath,'entradas','topologia','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Topo.Items         = FileList;
            app.Edit_Topo.Value         = FileList{1};
            
            % -------------------------------------------------------------------
            % Leer archivo de red Topológica
            % -------------------------------------------------------------------
            % Leer archivo de red Topológica
            FilePath      = fullfile(app.ProjectPath,'entradas','topologia',[FileList{1},'.txt']);
            UserDataTopo  = Read_Topology(FilePath);
            
            % Plot cuenca y red de drenaje
            app.Plot_Basin(UserDataTopo,'MapsMM')
            app.Plot_Basin(UserDataTopo,'MapsDumping') 
            app.Plot_Basin(UserDataTopo,'MapsMuestreo') 
            app.Plot_Basin(UserDataTopo,'MapSeries') 
            
            % Nombre del archivo de series de tiempo de temperatura mínima
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TAmin_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            if ~isempty(FileList)
                app.Edit_Tmin.Items         = FileList;
                app.Edit_Tmin.Value         = FileList{1};
            else
                app.Edit_Tmin.Items         = {'NA'};
                app.Edit_Tmin.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de temperatura máxima
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TAmax_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            
            if ~isempty(FileList)
                app.Edit_Tmax.Items         = FileList;
                app.Edit_Tmax.Value         = FileList{1};
            else
                app.Edit_Tmax.Items         = {'NA'};
                app.Edit_Tmax.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de humedad relativa
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_HR_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            if ~isempty(FileList)
                app.Edit_HR.Items           = FileList;
                app.Edit_HR.Value           = FileList{1};
            else
                app.Edit_HR.Items         = {'NA'};
                app.Edit_HR.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de radiación solar
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_RS_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            if ~isempty(FileList)
                app.Edit_RS.Items           = FileList;
                app.Edit_RS.Value           = FileList{1};
            else
                app.Edit_RS.Items         = {'NA'};
                app.Edit_RS.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de precipitación
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_PVer_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            
            if ~isempty(FileList)
                app.Edit_P.Items            = FileList;
                app.Edit_P.Value            = FileList{1};
            else
                app.Edit_P.Items         = {'NA'};
                app.Edit_P.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de viento zonal
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Ux_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            if ~isempty(FileList)
                app.Edit_VZ.Items           = FileList;
                app.Edit_VZ.Value           = FileList{1};
            else
                app.Edit_VZ.Items         = {'NA'};
                app.Edit_VZ.Value         = 'NA';
            end
            % Nombre del archivo de series de tiempo de viento meridional
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Uy_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            if ~isempty(FileList)
                app.Edit_VM.Items           = FileList;
                app.Edit_VM.Value           = FileList{1};
            else
                app.Edit_VM.Items         = {'NA'};
                app.Edit_VM.Value         = 'NA';
            end
            % Nombre del archivo de caudal líquido descargado
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Qld_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Q_LD.Items         = FileList;
            app.Edit_Q_LD.Value         = FileList{1};
            % Nombre del archivo de caudal líquido captado
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Qlc_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Q_LC.Items         = FileList;
            app.Edit_Q_LC.Value         = FileList{1};
            % Nombre del archivo de series de tiempo de arcillas
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Clay_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Clay.Items         = FileList;
            app.Edit_Clay.Value         = FileList{1};
            % Nombre del archivo de series de tiempo de limos
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Silt_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Silt.Items         = FileList;
            app.Edit_Silt.Value         = FileList{1};
            % Nombre del archivo de series de tiempo de arenas
            Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_Sand_*');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Sand.Items         = FileList;
            app.Edit_Sand.Value         = FileList{1};
            % Nombre del archivo de movimientos en masa
            Path        = fullfile(app.ProjectPath,'entradas','movmasa','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_MM.Items           = FileList;
            app.Edit_MM.Value           = FileList{1};
            % Nombre del archivo de factores de calibración
            Path        = fullfile(app.ProjectPath,'entradas','calibracion','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
                        
            if ~isempty(FileList)
                app.Edit_FacCal.Items       = FileList;
                app.Edit_FacCal.Value       = FileList{1};
            else
                app.Edit_FacCal.Items         = {'NA'};
                app.Edit_FacCal.Value         = 'NA';
            end
            % Nombre del archivo muestreador
            Path        = fullfile(app.ProjectPath,'entradas','muestras','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            
            if ~isempty(FileList)
                app.Edit_Sampler.Items      = FileList;
                app.Edit_Sampler.Value      = FileList{1};
            else
                app.Edit_Sampler.Items         = {'NA'};
                app.Edit_Sampler.Value         = 'NA';
            end
             % Nombre del archivo de tasas de restauración
             Path        = fullfile(app.ProjectPath,'entradas','series','TimeSeries_TasRest_*');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            
            if ~isempty(FileList)
                app.Edit_Res.Items          = FileList;
                app.Edit_Res.Value          = FileList{1};
            else
                app.Edit_Res.Items         = {'NA'};
                app.Edit_Res.Value         = 'NA';
            end
            % Nombre del archivo de puntos de vertimiento
            Path        = fullfile(app.ProjectPath,'entradas','vertimientos','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Dumping.Items      = FileList;
            app.Edit_Dumping.Value      = FileList{1};            
            % Nombre del archivo de ciclos de cargas difusas
            Path        = fullfile(app.ProjectPath,'entradas','ciclos','*.txt');
            FileList    = dir(Path);
            FileList    = {'NA',FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_LoadCycle.Items    = FileList;
            app.Edit_LoadCycle.Value    = FileList{1};
            % Tipo de ejecución del modelo
            app.Edit_SIGAExeType.Value = 'Hídrología';      


            % Activar o desactivar parametros
            app.ExecutionType(app.Edit_SIGAExeType.Value);
            % Activar o desactivar movimientos en masa
            app.Edit_MMValueChanged(0);
            % Activar o desactivar vertimientos
            app.Edit_DumpingValueChanged(0);
            % Activar o desactivar módulo geotecnico
            app.Edit_GeoExeTypeValueChanged(0)
            % Configuración - Módulo de series de tiempo                
            app.Asignation_ListTimeSeries;
            
        end
        
        function Asignation_Calibration(app, CalName, UserData_Params)
            % Nombre del archivo de parámetros
            app.Edit_CalName.Value = CalName;
            % Parámetros Meteorológicos
            app.Table_Met.ColumnFormat = {'numeric','numeric','numeric'};
            app.Table_Met.Data = UserData_Params.Met(1,15:end);
            % Parámetros de Restauración
            app.Table_Veg.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric'};
            app.Table_Veg.Data = UserData_Params.Veg;
            % Número de transiciones
            app.N_Pveg.Value   = num2str(length(UserData_Params.Veg(:,1)));
            % Parámetros Fenológicos
            app.Table_Fen.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric'};
            app.Table_Fen.Data = UserData_Params.Fen;
            % Parámetros Hidrológicos
            app.Table_Hid.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric'};
            app.Table_Hid.Data = UserData_Params.Hid;
            % Parámetros Sedimentológicos
            app.Table_Sed.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric'};
            app.Table_Sed.Data = UserData_Params.Sed;
            % Parámetros Geotécnicos
            app.Table_Geo.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric'};
            app.Table_Geo.Data = UserData_Params.Geo;
            % Parámetros de aplicación de Cargas Difusas 
            app.Table_CaD.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric'};
            Tmp = cell(100,7);
            n = UserData_Params.n_load;
            Tmp(1:n,1:end) = num2cell(UserData_Params.CaD) ;
            app.Table_CaD.Data  = Tmp;
            % Parámetros DBO-Carbonacea
            app.Table_DBO.ColumnFormat = {'numeric','numeric'};
            app.Table_DBO.Data = UserData_Params.DBO;
            % Parámetros de Reaireación
            app.Table_Air.ColumnFormat = {'numeric','numeric'};
            app.Table_Air.Data = UserData_Params.Air;
            % Parámetros de Escherichia Coli
            app.Table_EsC.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric'};
            app.Table_EsC.Data = UserData_Params.EsC;
            % Parámetros de Nitrógeno         
            app.Table_Nit.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric'};
            app.Table_Nit.Data = UserData_Params.Nit;            
            % Parámetros de Fósforo
            app.Table_Fos.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric'};
            app.Table_Fos.Data = UserData_Params.Fos;            
            % Parámetros de Coliformes Totales
            app.Table_CoT.ColumnFormat = {'numeric','numeric'};
            app.Table_CoT.Data = UserData_Params.CoT;
            % Parámetros de Turbiedad
            app.Table_Tur.ColumnFormat = {'numeric','numeric'};
            app.Table_Tur.Data = UserData_Params.Tur;
            % Parámetros de Conductividad Eléctrica
            app.Table_CoE.ColumnFormat = {'numeric','numeric'};
            app.Table_CoE.Data = UserData_Params.CoE;
            % Parámetros de Solidos Suspendidos Totales
            app.Table_SST.ColumnFormat = {'numeric','numeric','numeric'};
            app.Table_SST.Data = UserData_Params.SST;
            % Parámetros de Theta para correción de temperatura
            app.Table_The.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric','numeric','numeric',...
                                          'numeric'};
            app.Table_The.Data = UserData_Params.The;
            % Parámetros de Temperatura del Suelo y Agua
            app.Table_Tsa.ColumnFormat = {'numeric','numeric','numeric',...
                                          'numeric'};
            app.Table_Tsa.Data = UserData_Params.Tsa;
            % Parámetros de ADZ-QUASAR
            app.Table_ADZ.ColumnFormat = {'numeric','numeric'};
            app.Table_ADZ.Data = UserData_Params.ADZ;
            % Parámetros de rezagos temporales
            app.Table_Tau.ColumnFormat = {'numeric'};
            app.Table_Tau.Data = UserData_Params.Tau; 
            % Parámetros de pH
            app.Table_Tsa.ColumnFormat = {'numeric','numeric','numeric'};
            app.Table_pH.Data = UserData_Params.pH; 
        end
        
        function Asignation_LoadCycle(app,UserData_LoadCycle)
            global Ax_Cycle
            delete(Ax_Cycle)
            
            % Asignar nombre
            app.Edit_NameLoadCycle.Value = app.Edit_LoadCycle.Value;           
            
            % Asignar número de ciclos
            app.N_Cycle.Value = num2str(UserData_LoadCycle.NCycle);
            
            % Titulos de los ciclos                
            LabelName = {'Dia'};
            for j = 1:UserData_LoadCycle.NCycle
                LabelName{j + 1} = ['Ciclo - ',num2str(j)];
            end
            app.Table_LoadCycle.ColumnName = LabelName;
            
            % Mostar datos
            app.Table_LoadCycle.Data = [UserData_LoadCycle.Date, UserData_LoadCycle.Data];
           
            % Plot gráfica
            Ax_Cycle = plot(app.PlotAx_LoadCycle,UserData_LoadCycle.Date, UserData_LoadCycle.Data);                
            xlim(app.PlotAx_LoadCycle,[1 366])
            
            % Configuraciones estéticas
            NameAxes = 'PlotAx_LoadCycle'; 
            xlabel(app.PlotAx_LoadCycle,'Cantidad de d\''ias', 'interpreter','latex', 'Fontsize',18)
            eval(['ylabel(app.',NameAxes,',''Valor ${(\%)}$'',''interpreter'',''latex'', ''Fontsize'',18)'])   
            eval(['title(app.',NameAxes,',''Ciclos de cargas difusas'',''interpreter'',''latex'', ''Fontsize'',18)']) 
            eval(['set(app.',NameAxes,',''TickLabelInterpreter'',''latex'',''FontWeight'',''bold'',''Color'',''none'',''box'',''on'',''FontSize'',15)'])
        
            % Plot etiquetas
            LabelName = {['Ciclo - ',num2str(UserData_LoadCycle.CodeCycle(1))]};
            for j = 2:UserData_LoadCycle.NCycle
                LabelName{j} = ['Ciclo - ',num2str(UserData_LoadCycle.CodeCycle(j))];
            end
            legend(app.PlotAx_LoadCycle,LabelName,'interpreter','latex',"Location","bestoutside")
            
            % Asignación de ciclos en select app
            LabelName = {'Todos'};
            for j = 1:UserData_LoadCycle.NCycle
                LabelName{j + 1} = ['Ciclo - ',num2str(UserData_LoadCycle.CodeCycle(j))];
            end
            
            % poner 
            app.SelectCycle.Items = LabelName;
        end


        function Asignation_Sampler(app,UserData_Sampler)  
            
            % Nombre del archivo muestreador
            app.Edit_NameSampler.Value              = app.Edit_Sampler.Value;
            % Variables para extraer en los puntos de control
            app.Table_ControlPointsVar.Data         = UserData_Sampler.Var_ControlPoints(1:app.NumVarSIGA);
            % Variables para extraer en los tramos de control
            app.Table_ControlBranchVar.Data         = UserData_Sampler.Var_ControlBranch(1:app.NumVarSIGA);
            % Variables para extraer en mapas
            app.Table_ListMapVar.Data               = UserData_Sampler.Var_Map(1:app.NumVarSIGA); 
            % Variables para extraer en mapas solo en cauce
            app.Table_ListMapVar_Stream.Data        = UserData_Sampler.Var_MapVarStream(1:app.NumVarSIGA);
            % Variables para enmascarar en cauce
            app.Table_ListMapVar_StreamMask.Data    = UserData_Sampler.Var_MapVarStreamMask(1:app.NumVarSIGA);
            % Etiquetas de las varibles
            app.Table_LabelVar.Data                 =  app.NameVar;
            % Unidades de las variables
            app.Table_VarUnit.Data                  = UserData_Sampler.Var_Unit(1:app.NumVarSIGA);
            % Factores de conversión
            app.Table_ConFactors.Data               = UserData_Sampler.ConversionFactors(1:app.NumVarSIGA);
            % Puntos de control
            Tmp = cell(100,4);
            for i = 1:UserData_Sampler.N_CP
                Tmp(i,:) = UserData_Sampler.ControlPoints(i,:);
            end
            app.Table_ControlPoint.Data = Tmp;
            % Tramos de control
            Tmp = cell(100,5);
            for i = 1:UserData_Sampler.N_CB
                Tmp(i,:) = UserData_Sampler.ControlBranch(i,:);
            end
            app.Table_ControlBranch.Data = Tmp;                                                                        
            
            % Plot de tramos y puntos de control                 
            app.Plot_ControlPoints;
            app.Plot_ControlBranch_Start;
            app.Plot_ControlBranch_End;
            
        end
        
        function Asignation_MM(app,UserData_MM)
            % Asignar nombre
            app.Edit_NameMM.Value = app.Edit_MM.Value;
            
            UserData_MM.Data = UserData_MM.Data;
            UserData_MM.Data(UserData_MM.Data == app.NoValue) = NaN;
            
            % Asignar número de movimiento en masa
            app.N_MM.Value = num2str(UserData_MM.N);
            
            % Asignar datos a tabla de vertimientos
            app.Table_MM.Data = UserData_MM.Data;   
            
            % Plot movimientos en masa
            app.Plot_MM
            
        end
        
        function Asignation_Dumping(app,UserData_Dumping)
            % Asignar nombre
            app.Edit_NameDumping.Value  = app.Edit_Dumping.Value;
            
            for i = 1:UserData_Dumping.N
                for j = 2:18
                    Tmp = UserData_Dumping.Data{i,j};
                    if Tmp == app.NoValue, Tmp = NaN; end
                    UserData_Dumping.Data{i,j} = Tmp;
                end
            end
            
            % Asignar número de vertimientos
            app.NDumping.Value = num2str(UserData_Dumping.N);
                
            % Asignar datos a tabla de vertimientos
            app.Table_Dumping.Data = UserData_Dumping.Data;                        
            
            % Plot vertimeitnos
            app.Plot_Dumping                       
        end
        
        function Asignation_ListTimeSeries(app)
            List = {'None',...
                    'Temperatura Mínima',...
                    'Temperatura Máxima',...
                    'Humedad Relativa',...
                    'Radiación Solar',...
                    'Precipitación',...
                    'Viento Zonal',...
                    'Viento Meridional',...                        
                    'Tasas de Restauración'};
            if ~strcmp(app.Edit_Q_LD.Value,'NA') || strcmp(app.SceFileName, 'None')
                List = {List{:},'Caudal Líquido Descargado'};
            end
            if ~strcmp(app.Edit_Q_LC.Value,'NA') || strcmp(app.SceFileName, 'None')
                List = {List{:},'Caudal Líquido Captado'};
            end
            if ~strcmp(app.Edit_Clay.Value,'NA') || strcmp(app.SceFileName, 'None')
                List = {List{:},'Arcillas Mezcladas en Descarga'};
            end
            if ~strcmp(app.Edit_Silt.Value,'NA') || strcmp(app.SceFileName, 'None')
                List = {List{:},'Limos Mezclados en Descarga'};
            end
            if ~strcmp(app.Edit_Sand.Value,'NA') || strcmp(app.SceFileName, 'None')
                List = {List{:},'Arenas Mezcladas en Descarga'};
            end
            if ~strcmp(app.Edit_MM.Value,'NA')
                app.Enable_MM_App(true)
            else
                app.Enable_MM_App(false)
            end
            if ~strcmp(app.Edit_Dumping.Value,'NA')
                app.Enable_Dumping_App(true)
            else
                app.Enable_Dumping_App(false)
            end
            
            app.NameTimeSeries.Items = List;
            app.NameTimeSeries.Value = 'None';
        end
        
        function Enable_MM_App(app, Status)
            global Ax_MM
            if Status
                % Nombre de archivo de movimientos en masa
                app.Edit_NameMM.Enable      = 'on'; 
                % Numero de movimientos en masa
                app.N_MM.Enable             = 'on';
                % Botón de crear nuevo archivo de movimiento en masa
                app.Button_CreateMM.Enable  = 'on';
                % Tabla de datos de movimientos en masa
                app.Table_MM.Enable         = 'on';
                % Boton de plot de movimientos en masa
                app.Button_Plot_MM.Enable   = 'on'; 
                % Leyenda de movimientos en masa
                app.MM_Lamp.Enable          = 'on';
                % Botón de cargar datos
                app.Button_Load_MM.Enable   = 'on';
                % Botón de info
                app.Info_MM.Enable          = 'on';
            else
                % Nombre de archivo de movimientos en masa
                app.Edit_NameMM.Enable      = 'off'; 
                % Numero de movimientos en masa
                app.N_MM.Enable             = 'off';
                % Botón de crear nuevo archivo de movimiento en masa
                app.Button_CreateMM.Enable  = 'on';
                % Tabla de datos de movimientos en masa
                app.Table_MM.Enable         = 'off';
                % Boton de plot de movimientos en masa
                app.Button_Plot_MM.Enable   = 'off'; 
                % Leyenda de movimientos en masa
                app.MM_Lamp.Enable          = 'off';
                % Botón de cargar datos
                app.Button_Load_MM.Enable   = 'off';
                % Botón de info
                app.Info_MM.Enable          = 'off';
                % Grafico de movimientos en masa
                delete(Ax_MM)                                              
            end
        end
            

        function Enable_Dumping_App(app, Status)
            global Ax_Dumping
            if Status
                % Nombre de archivo de vertimientos
                app.Edit_NameDumping.Enable      = 'on'; 
                % Numero de vertimientos
                app.NDumping.Enable              = 'on';
                % Botón de crear nuevo archivo de movimiento en masa
                app.Button_CreateDumping.Enable  = 'on';
                % Tabla de datos de vertimientos
                app.Table_Dumping.Enable         = 'on';
                % Boton de plot de vertimientos
                app.Button_Plot_Dumping.Enable   = 'on'; 
                % Leyenda de vertimientos
                app.Dumping_Lamp.Enable          = 'on';
                % Botón de cargar datos
                app.Button_Load_Dumping.Enable   = 'on';
                % Botón de info
                app.Info_Dumping.Enable          = 'on';
            else
                % Nombre de archivo de vertimientos
                app.Edit_NameDumping.Enable      = 'off'; 
                % Numero de vertimientos
                app.NDumping.Enable              = 'off';
                % Botón de crear nuevo archivo de movimiento en masa
                app.Button_CreateDumping.Enable  = 'on';
                % Tabla de datos de vertimientos
                app.Table_Dumping.Enable         = 'off';
                % Boton de plot de vertimientos
                app.Button_Plot_Dumping.Enable   = 'off'; 
                % Leyenda de vertimientos
                app.Dumping_Lamp.Enable          = 'off';
                % Botón de cargar datos
                app.Button_Load_Dumping.Enable   = 'off';
                % Botón de info
                app.Info_Dumping.Enable          = 'off';
                % Grafico de vertimientos
                delete(Ax_Dumping)                              
            end
        end
                
    end   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appSIGA, StatusNew, ProjectPath, SceFileName, ProB)
            % -------------------------------------------------------------------
            % Guardar app de menu principal
            % -------------------------------------------------------------------
            app.AppSIGA = appSIGA;
            app.AppSIGA.Disable_Button;
            app.SceFileName = SceFileName;                       
            
            % -------------------------------------------------------------------
            % Definición de varibles globales
            % -------------------------------------------------------------------
            % Topología de la cuenca
            global UserDataTopo
            
            % -----------------------------------------------------------------------
            % Definición de tipo de datos de columnas para tablas
            % -----------------------------------------------------------------------
            % Tabla de parámetros de vegetación
            app.Table_Veg.ColumnFormat              = {'char','numeric','numeric','numeric'};
            % Tabla de vertimientos
            app.Table_Dumping.ColumnFormat          = [{'char'},repmat({'numeric'},1,17)]; 
            % Parámetro Tau
            app.Table_Tau.ColumnFormat              = {'numeric'};
            % Tabla de puntos de control
            app.Table_ControlPoint.ColumnFormat     = {'numeric','numeric','char',{'valor','media'}};
            % Tabla de tramos de control
            app.Table_ControlBranch.ColumnFormat    = {'numeric','numeric','numeric','numeric','char'};
            % Tabla de aplicación de Cargas Difusas
            app.Table_CaD.ColumnFormat              = repmat({'numeric'},1,7);
            % Formato tipo numérico 
            app.Table_ConFactors.ColumnFormat       = repmat({'numeric'},1,app.NumVarSIGA);
            
            % -----------------------------------------------------------------------
            % Ruta de la carpeta de cuenca
            % -----------------------------------------------------------------------
            app.ProjectPath = ProjectPath;
            try
                if StatusNew
                    % Si el usuario va a iniciar una nueva configuración
                    % -------------------------------------------------------------------
                    % Asignar valores vacios al Módulo de ejecución
                    % -------------------------------------------------------------------
                    app.Asignation_Execution_New
                    waitbar(0.3)
                    
                    % -------------------------------------------------------------------
                    % Habilitar módulo de movimientos en masa y vertimietnos
                    % -------------------------------------------------------------------
                    % Habilitar módulo de movimientos en masa
                    Enable_MM_App(app, false)
                    % Habilitar módulo de movimientos vertimietnos
                    Enable_Dumping_App(app, false)
                    
                    % -------------------------------------------------------------------
                    % Asignar valores vacios al Módulo de calibración
                    % -------------------------------------------------------------------
                    % Parámetros Meteorológicos
                    app.Table_Met.Data  = NaN(1,3);
                    % Parámetros de Restauración
                    Tmp = NaN(2,5);
                    Tmp(:,1) = 0:1;
                    app.Table_Veg.Data  = Tmp;
                    % Parámetros Fenológicos
                    app.Table_Fen.Data  = NaN(42,21);
                    % Parámetros Hidrológicos
                    app.Table_Hid.Data  = NaN(1,14);
                    % Parámetros Sedimentológicos
                    app.Table_Sed.Data  = NaN(1,12);
                    % Parámetros Geotécnicos
                    app.Table_Geo.Data  = NaN(1,7);                    
                    % Parámetros de aplicación de Cargas Difusas
                    Tmp = cell(100,7);
                    Tmp(1,1:end) = repmat({NaN},1,7);
                    app.Table_CaD.Data  = Tmp;
                    % Parámetros DBO-Carbonacea
                    app.Table_DBO.Data  = NaN(1,2);
                    % Parámetros de Reaireación
                    app.Table_Air.Data  = NaN(1,2);
                    % Parámetros de Escherichia Coli
                    app.Table_EsC.Data  = NaN(1,6);
                    % Parámetros de Nitrógeno
                    app.Table_Nit.Data  = NaN(1,13);
                    % Parámetros de Fósforo
                    app.Table_Fos.Data  = NaN(1,13);
                    % Parámetros de Coliformes Totales
                    app.Table_CoT.Data  = NaN(1,2);
                    % Parámetros de Turbiedad
                    app.Table_Tur.Data  = NaN(1,2);
                    % Parámetros de Conductividad Eléctrica
                    app.Table_CoE.Data  = NaN(1,2);
                    % Parámetros de Solidos Suspendidos Totales
                    app.Table_SST.Data  = NaN(1,2);
                    % Parámetros de Theta para correción de temperatura
                    app.Table_The.Data  = NaN(1,7);
                    % Parámetros de Temperatura del Suelo y Agua
                    app.Table_Tsa.Data  = NaN(1,4);
                    % Parámetros de ADZ-QUASAR
                    app.Table_ADZ.Data  = NaN(1,2);
                    % Parámetros de rezagos temporales
                    app.Table_Tau.Data  = NaN;
                    % Parámetros de pH
                    app.Table_Tsa.Data  = NaN(1,3);
                    
                    waitbar(0.5)
                    % -------------------------------------------------------------------
                    % Asignar valores vacios al Módulo de Muestreador
                    % -------------------------------------------------------------------
                    % Variables para resultados en puntos de control
                    app.Table_ControlPointsVar.Data         = true(1,app.NumVarSIGA);
                    % Variables para resultados en tramos de control
                    app.Table_ControlBranchVar.Data         = true(1,app.NumVarSIGA);
                    % Mapas de resultados
                    app.Table_ListMapVar.Data               = true(1,app.NumVarSIGA);
                    % Mapas de resultados
                    app.Table_ListMapVar_Stream.Data        = true(1,app.NumVarSIGA);
                    % Mapas de resultados
                    app.Table_ListMapVar_StreamMask.Data    = true(1,app.NumVarSIGA);
                    % Etiquetas de varibles
                    app.Table_LabelVar.Data                 = app.NameVar;
                    % Unidades de las variables
                    app.Table_VarUnit.Data                  = app.UnitVar;
                    % Factores de conversión
                    Tmp = repmat({1},1,app.NumVarSIGA);
                    app.Table_ConFactors.Data               = Tmp;                
                    % Listado de puntos de control
                    Tmp = cell(100,4);
                    app.Table_ControlPoint.Data             = Tmp;
                    % Listado de tramos de control
                    Tmp = cell(100,5);
                    app.Table_ControlBranch.Data            = Tmp;
                    
                    waitbar(0.7)
                    % -------------------------------------------------------------------
                    % Asignar valores vacios al Módulo de ciclo de cargas
                    % -------------------------------------------------------------------
                    Tmp = sin(linspace(0,12*pi,366))';
                    Tmp(Tmp < 0) = 0;
                    app.Table_LoadCycle.Data = [(1:366)',Tmp];               
                    % plot ciclos
                    app.Plot_LoadCycle
                    
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de series de tiempo
                    % -------------------------------------------------------------------                
                    app.Asignation_ListTimeSeries;
                    
                    % Asignar nombre de archivos en diferentes módulos de la
                    % app
                    app.Edit_CalName.Value          = app.Edit_FacCal.Value;
                    app.Edit_NameMM.Value           = app.Edit_MM.Value;
                    app.Edit_NameSampler.Value      = app.Edit_Sampler.Value;
                    app.Edit_NameDumping.Value      = app.Edit_Dumping.Value;
                    app.Edit_NameLoadCycle.Value    = app.Edit_LoadCycle.Value;
                    
                    waitbar(0.8)
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de movimientos en mas
                    % -------------------------------------------------------------------
                    UserData.N          = 1;
                    UserData.NoValue    = app.NoValue;
                    DateN = datetime(date);
                    UserData.Data       = [year(DateN) month(DateN) day(DateN) NaN NaN NaN];
                    app.N_MM.Value      = '1';                       
                    
                    % Habilitar Módulo
                    app.Enable_MM_App(true)
                    
                    % Asignar Valores
                    app.Asignation_MM(UserData);
                    
                    waitbar(0.9)
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de vertimientos
                    % ------------------------------------------------------------------- 
                    UserData.N          = 1;
                    UserData.NoValue    = app.NoValue;
                    UserData.Data       = [{'VER-01'},repmat({NaN},1,17)];
                    
                    % Habilitar Módulo
                    app.Enable_Dumping_App(true)
                    
                    % Asignar Valores
                    app.Asignation_Dumping(UserData);
                    
                    waitbar(1)
                    
                else                       
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de Ejecución 
                    % -------------------------------------------------------------------
                    % Leer Achivo de Ejecución 
                    FilePath        = fullfile(app.ProjectPath,'entradas','ejecucion',['Scenario_',SceFileName,'.txt']);
                    UserData_Exe    = Read_ExecutionFile(FilePath);
                    
                    % Asignar valores
                    app.Asignation_Execution(UserData_Exe,SceFileName)
                               
                    waitbar(0.4)
                    % -------------------------------------------------------------------
                    % Leer archivo de red Topológica
                    % -------------------------------------------------------------------
                    % Leer archivo de red Topológica
                    FilePath      = fullfile(app.ProjectPath,'entradas','topologia',[UserData_Exe.Topo,'.txt']);
                    UserDataTopo  = Read_Topology(FilePath);
                    
                    % Plot cuenca y red de drenaje
                    app.Plot_Basin(UserDataTopo,'MapsMuestreo')
                    app.Plot_Basin(UserDataTopo,'MapsMM')
                    app.Plot_Basin(UserDataTopo,'MapsDumping')                      
                    app.Plot_Basin(UserDataTopo,'MapSeries')  
                    
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de ciclos de carga
                    % -------------------------------------------------------------------
                    % Leer Archivo de ciclo de cargas
                    FilePath            = fullfile(app.ProjectPath,'entradas','ciclos',[UserData_Exe.LoadCycle,'.txt']);
                    UserData_LoadCycle  = Read_LoadCycle(FilePath);
                    
                    % Asignar valores
                    app.Asignation_LoadCycle(UserData_LoadCycle)                                              
                    
                    waitbar(0.5)
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de Parametros
                    % -------------------------------------------------------------------
                    % Leer Archivo de Parametros
                    FilePath = fullfile(app.ProjectPath,'entradas','calibracion',[UserData_Exe.F_Cal,'.txt']);
                    UserData_Params = Read_CalibrationFile(FilePath, app.NoValue);                                                                          
                    
                    % Asignar valores
                    app.Asignation_Calibration(UserData_Exe.F_Cal, UserData_Params)                 
                    
                    waitbar(0.7)
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de Muestreador
                    % -------------------------------------------------------------------               
                    % Leer Archivo de Muestreador
                    FilePath = fullfile(app.ProjectPath,'entradas','muestras',[UserData_Exe.Sampler,'.txt']);
                    UserData_Sampler = Read_SamplerFile(FilePath);               
                    
                    % Asignar valores
                    app.Asignation_Sampler(UserData_Sampler)
                    
                    waitbar(0.8)
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de movimientos en masa
                    % -------------------------------------------------------------------
                    if ~strcmp(UserData_Exe.MM,'NA')
                        % Leer Archivo de movientos en masa
                        FilePath = fullfile(app.ProjectPath,'entradas','movmasa',[UserData_Exe.MM,'.txt']);
                        UserData_MM = Read_MassMovements(FilePath); 
                        
                        % Asignar valores
                        app.Asignation_MM(UserData_MM)
                    end
                    
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de vertimientos
                    % -------------------------------------------------------------------
                    if ~strcmp(UserData_Exe.Dumping,'NA')
                        % Leer Archivo de vertimientos
                        FilePath            = fullfile(app.ProjectPath,'entradas','vertimientos',[UserData_Exe.Dumping,'.txt']);
                        UserData_Dumping    = Read_Dumping(FilePath);
                        
                        % Asignar valores
                        app.Asignation_Dumping(UserData_Dumping)
                    end
                    
                    % -------------------------------------------------------------------
                    % Configuración - Módulo de series de tiempo
                    % -------------------------------------------------------------------                
                    app.Asignation_ListTimeSeries;
                    waitbar(1)               
                end       
                delete(ProB)
                
                b = msgbox('Información cargada con éxito');
                b.Color = [1 1 1];                                
                
                % -------------------------------------------------------------------
                % Bloquerar app
                % -------------------------------------------------------------------
    
%                 uiwait(app.Configuracion)
            
            catch ME
                errorMessage = sprintf(...
                    ['La configuración de los archivos de entrada del escenario ',...
                    SceFileName,' no es correcta. Revisar. A continuación, se presenta el error ',...
                    'en el código de SIGA-CAL Tools. \n\n',...
                    'Error in function %s() at line %d.\n\nError Message:\n%s'], ...
                ME.stack(1).name, ME.stack(1).line, ME.message);
                errordlg( errorMessage )
   
                close(app.Configuracion)                
            end
            
        end

        % Value changed function: Button_Save
        function Button_SaveValueChanged(app, event)
            
            % Deshactivar botón de guardado
            app.Button_Save.Value = 0;            
            
            % -------------------------------------------------------------------
            % Guardar Archivo de Ejecución
            % -------------------------------------------------------------------
            % Leer nombre del archivo de ejecución
            FileName    = ['Scenario_',app.Edit_ExeFileName.Value,'.txt'];
            
            % leer de la app los datos para el archivo de ejecución
            UserData_Exe = app.Read_Execution_App;                       
            
            % -------------------------------------------------------------------
            % Guardar Archivo de Calibración
            % -------------------------------------------------------------------                        
            % leer de la app los datos para el archivo de calibración
            [StatusError,UserData_Params] = app.Read_Calibration_App;
            
            if StatusError
                return
            end
            
            % -------------------------------------------------------------------
            % Guardar Archivo de muestreador
            % -------------------------------------------------------------------  
            % leer de la app los datos para el archivo de muestreador
            [StatusError,UserData_Sampler] = app.Read_Sampler_App;

            if StatusError
                return
            end                        
            
            % -------------------------------------------------------------------
            % Guardar Archivo de Ciclos de Carga
            % -------------------------------------------------------------------
            [StatusError,UserData_LC] = app.Read_LoadCycle_App;
            
            if StatusError
                return
            end            
            
            % -------------------------------------------------------------------
            % Guardar Archivo de vertimientos
            % -------------------------------------------------------------------  
            % leer de la app los datos para el archivo de muestreador
            if ~strcmp(UserData_Exe.Dumping,'NA')
                [StatusError,UserData_Dumping] = app.Read_Dumping_App;
    
                if StatusError
                    return
                end
            end
            
            % -------------------------------------------------------------------
            % Guardar Archivo de movientos en masa
            % -------------------------------------------------------------------  
            % leer de la app los datos para el archivo de muestreador
            if ~strcmp(UserData_Exe.MM,'NA')
                [StatusError,UserData_MM] = app.Read_MM_App;
    
                if StatusError
                    return
                end
            end
            
            % -------------------------------------------------------------------
            % Guardar archivos
            % -------------------------------------------------------------------
            % Guardar Archivo de ejecución nuevo
            FilePath    = fullfile(app.ProjectPath,'entradas','ejecucion',FileName);
            Write_ExecutionFile(FilePath, UserData_Exe);
            
            % Guardar Archivo de Parametros
            FileName = [app.Edit_CalName.Value,'.txt'];
            FilePath = fullfile(app.ProjectPath,'entradas','calibracion',FileName);
            Write_CalibrationFile(FilePath, UserData_Params);
            
            % Guardar Archivo de Muestreador
            FileName = [app.Edit_Sampler.Value,'.txt'];
            FilePath = fullfile(app.ProjectPath,'entradas','muestras',FileName);
            Write_SamplerFile(FilePath, UserData_Sampler);
            
            % Guardar Archivo de ciclos de cargas
            FileName = [app.Edit_LoadCycle.Value,'.txt'];
            FilePath = fullfile(app.ProjectPath,'entradas','ciclos',FileName);
            Write_LoadCycleFile(FilePath, UserData_LC);
            
            % Guardar Archivo de vertimientos
            if ~strcmp(UserData_Exe.Dumping,'NA')
                FileName = [app.Edit_Dumping.Value,'.txt'];
                FilePath = fullfile(app.ProjectPath,'entradas','vertimientos',FileName);
                Write_DumpingFile(FilePath, UserData_Dumping);
            end
            
            % Guardar Archivo de movimientos en masa
            if ~strcmp(UserData_Exe.MM,'NA')
                FileName = [app.Edit_MM.Value,'.txt'];
                FilePath = fullfile(app.ProjectPath,'entradas','movmasa',FileName);
                Write_MassMovementsFile(FilePath, UserData_MM);
            end                       
            
            % -------------------------------------------------------------------
            % Cerrar Panel de Configuración
            % -------------------------------------------------------------------
            close(app.Configuracion)
                       
        end

        % Callback function
        function Edit_FacCalValueChanged(app, event)
            app.Edit_CalName.Value = app.Edit_FacCal.Value;            
        end

        % Callback function
        function Edit_SamplerValueChanged(app, event)
            app.Edit_NameSampler.Value = app.Edit_Sampler.Value;            
        end

        % Value changed function: Button_Cancel
        function Button_CancelValueChanged(app, event)
            % Cerrar Panel de Configuración
            app.Button_Cancel.Value = 0;
            close(app.Configuracion)            
        end

        % Value changed function: Edit_SIGAExeType
        function Edit_SIGAExeTypeValueChanged(app, event)
            value = app.Edit_SIGAExeType.Value;                     
            % Habilitar tablas de parametros            
            app.ExecutionType(value,1)    
            
        end

        % Value changed function: Clear_ControlPoints
        function Clear_ControlPointsValueChanged(app, event)
            % Llamado de variables globales
            global Ax3 Ax4 Ax5
                
            % Captura de estado de Botón
            value = app.Clear_ControlPoints.Value;
            
            % deshabilitar eliminació de puntos de control
            app.Create_ControlPoints.Enable = 'off';
            app.Create_ControlBranch.Enable = 'off';
            app.Clear_ControlBranch.Enable = 'off';
            
            % Deshactivar el ingreso de datos manualmente por parte del
            % usuario
            app.Table_ControlPoint.Enable = 'off';
            
            if value                                 
                % leer puntos de control de app
                ControlPoints  = app.Table_ControlPoint.Data;
                id = find(~isnan(cell2mat(ControlPoints(:,1))));
                ControlPoints  = ControlPoints(id,:);                    
                
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                
                % Plot puntos de control
                Xp = cell2mat(ControlPoints(:,1));
                Yp = cell2mat(ControlPoints(:,2));
                Ax3 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                        'MarkerEdgeColor',[0.5882 0 0.0941],...
                        'MarkerFaceColor',[0.5882 0 0.0941],...
                        'LineWidth',1.5,...
                        "ButtonDownFcn",{@Delete_ControlPoints,app});                
            else  
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                                
                % Plot de tramos y puntos de control 
                app.Plot_ControlPoints; 
                app.Plot_ControlBranch_Start;
                app.Plot_ControlBranch_End;
                
                % Activar el ingreso de datos manualmente por parte del
                % usuario
                app.Table_ControlPoint.Enable = 'on';
                
                % deshabilitar eliminació de puntos de control
                app.Create_ControlPoints.Enable = 'on';
                app.Create_ControlBranch.Enable = 'on';
                app.Clear_ControlBranch.Enable = 'on';
                
            end
        end

        % Value changed function: Create_ControlPoints
        function Create_ControlPointsValueChanged(app, event)
            % Llamado de variables globales
            global Ax3 Ax4 Ax5 Ax6 UserDataTopo
            
            % Captura de estado de Botón
            value = app.Create_ControlPoints.Value;
            
            % deshabilitar eliminació de puntos de control
            app.Clear_ControlPoints.Enable = 'off';
            app.Create_ControlBranch.Enable = 'off';
            app.Clear_ControlBranch.Enable = 'off';
                
            % Deshactivar el ingreso de datos manualmente por parte del
            % usuario
            app.Table_ControlPoint.Enable = 'off';
            
            if value  
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                
                % Plot points network            
                Ax6 = scatter(app.MapsMuestreo,UserDataTopo.X(UserDataTopo.Stream),...
                    UserDataTopo.Y(UserDataTopo.Stream),...
                'MarkerEdgeColor',[0.9 1.0 0],...
                'MarkerFaceColor',[0.9 1.0 0],...                    
                "ButtonDownFcn",{@Create_ControlPoints,app});
                
                % Plot de tramos y puntos de control 
                app.Plot_ControlPoints; 
                
            else
                % Eliminar plot puntos de control previos
                delete(Ax6), clearvars Ax6
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                                
                % Plot de tramos y puntos de control 
                app.Plot_ControlPoints; 
                app.Plot_ControlBranch_Start;
                app.Plot_ControlBranch_End;
                
                % Activar el ingreso de datos manualmente por parte del
                % usuario
                app.Table_ControlPoint.Enable = 'on';
                
                % habilitar eliminació de puntos de control
                app.Clear_ControlPoints.Enable = 'on';
                app.Create_ControlBranch.Enable = 'on';
                app.Clear_ControlBranch.Enable = 'on';
            end
        end

        % Value changed function: Create_ControlBranch
        function Create_ControlBranchValueChanged(app, event)
            % Llamado de variables globales
            global Ax3 Ax4 Ax5 Ax6 UserDataTopo
            
            % Captura de estado de Botón
            value = app.Create_ControlBranch.Value;
            
            % deshabilitar creación de puntos de control
            app.Clear_ControlBranch.Enable = 'off';
            app.Clear_ControlPoints.Enable = 'off';
            app.Create_ControlPoints.Enable = 'off';
            
            % Deshactivar el ingreso de datos manualmente por parte del
            % usuario
            app.Table_ControlBranch.Enable = 'off';
            
            if value  
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                
                % Plot points network            
                Ax6 = scatter(app.MapsMuestreo,UserDataTopo.X(UserDataTopo.Stream),...
                    UserDataTopo.Y(UserDataTopo.Stream),10,'filled',...
                'MarkerEdgeColor',[0.9 1.0 0],...
                'MarkerFaceColor',[0.9 1.0 0],...                    
                "ButtonDownFcn",{@Create_ControlBranch_Start,app});
                
                % Plot de tramos y puntos de control 
                app.Plot_ControlBranch_Start; 
                app.Plot_ControlBranch_End;
                
                % Selección de punto inicial
                answer = questdlg('Seleccione el punto inicial del tramo de control', ...
                                	'Dessert Menu','Ok','Ok');
                                
            else
                % Leer punto de control de tabla
                ControlBranch  = app.Table_ControlBranch.Data;
                id = find(cell2mat(ControlBranch(:,3)) == app.NoValue)  ;
                ControlBranch(id,:)  = []; 
                
                % Asignar puntos a la tabla de la app
                app.Table_ControlBranch.Data = ControlBranch;

                % Eliminar plot puntos de control previos
                delete(Ax6), clearvars Ax6
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                
                % Plot de tramos y puntos de control 
                app.Plot_ControlPoints; 
                app.Plot_ControlBranch_Start;
                app.Plot_ControlBranch_End;
                
                % Activar el ingreso de datos manualmente por parte del
                % usuario
                app.Table_ControlBranch.Enable = 'on';
                
                % Habilitar creación de puntos de control
                app.Clear_ControlBranch.Enable = 'on';
                app.Clear_ControlPoints.Enable = 'on';
                app.Create_ControlPoints.Enable = 'on';
            end
        end

        % Value changed function: Clear_ControlBranch
        function Clear_ControlBranchValueChanged(app, event)
            % Llamado de variables globales
            global Ax3 Ax4 Ax5
                
            % Captura de estado de Botón
            value = app.Clear_ControlBranch.Value;
            
            % deshabilitar creación de tramos de control
            app.Create_ControlBranch.Enable = 'off';
            app.Clear_ControlPoints.Enable = 'off';
            app.Create_ControlPoints.Enable = 'off';
            
            % Deshactivar el ingreso de datos manualmente por parte del
            % usuario
            app.Table_ControlBranch.Enable = 'off';
            
            if value                 
                % leer puntos de control de app
                ControlBranch  = app.Table_ControlBranch.Data;
                id = find(~isnan(cell2mat(ControlBranch(:,1))));
                ControlBranch  = ControlBranch(id,:);                    
                
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)                               
                
                % -------------------------------------------------------------------
                % Plot tramos de control iniciales
                % -------------------------------------------------------------------
                Xp = cell2mat(ControlBranch(:,1));
                Yp = cell2mat(ControlBranch(:,2));
                Ax4 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                        'MarkerEdgeColor',[0 .5 .5],...
                        'MarkerFaceColor',[0 .5 .5],...
                        'LineWidth',1.5,...
                        "ButtonDownFcn",{@Delete_ControlBranch,app});
                    
                % -------------------------------------------------------------------
                % Plot tramos de control finales
                % -------------------------------------------------------------------
                Xp = cell2mat(ControlBranch(:,3));
                Yp = cell2mat(ControlBranch(:,4));
                Ax5 = scatter(app.MapsMuestreo,Xp,Yp,20,'filled',...
                        'MarkerEdgeColor',[0.9 .7 .2],...
                        'MarkerFaceColor',[0.9 .7 .2],...
                        'LineWidth',1.5,...
                        "ButtonDownFcn",{@Delete_ControlBranch,app});
                
            else  
                % Eliminar plot puntos de control previos
                delete(Ax3)
                delete(Ax4)
                delete(Ax5)
                                
                % Plot de tramos y puntos de control 
                app.Plot_ControlPoints; 
                app.Plot_ControlBranch_Start;
                app.Plot_ControlBranch_End;
                
                % Activar el ingreso de datos manualmente por parte del
                % usuario
                app.Table_ControlBranch.Enable = 'on';
                
                % Habilitar creación de tramos de control
                app.Create_ControlBranch.Enable = 'on';
                app.Clear_ControlPoints.Enable = 'on';
                app.Create_ControlPoints.Enable = 'on';
            end
        end

        % Value changed function: Edit_GeoExeType
        function Edit_GeoExeTypeValueChanged(app, event)
            value = app.Edit_GeoExeType.Value;
            
            if value
                % Habilitar parametros geotécnicos
                app.Table_Geo.Enable       = 'on';
            else
                % Habilitar parametros geotécnicos
                app.Table_Geo.Enable       = 'off';
            end
        end

        % Value changed function: N_MM
        function N_MMValueChanged(app, event)
            value = str2double(app.N_MM.Value);            
            
            % Captura de datos
            Tmp = app.Table_MM.Data;
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.N_MM.Value = num2str(length(Tmp(:,1)));
                return
            end
            
            if isnan(value)
                app.N_MM.Value = num2str(length(Tmp(:,1)));
                f = errordlg('Debe ingresar un valor númerico');
                set(f, 'color',[1 1 1])
                return
            end
            
            if value < 1
                app.N_MM.Value = num2str(length(Tmp(:,1)));
               f = errordlg('El número mínimo de movimientos en masa es 1');
               set(f, 'color',[1 1 1])
               return
            end
            
            % Creación de matrix 
            Data    = NaN(value,6);
          
            if value < length(Tmp(:,1))
                % Configurar datos
                Data = Tmp(1:value,:);
            else
                % Configurar datos
                Data(1:length(Tmp(:,1)),:) = Tmp;
            end
            
            % Mostrar datos
            app.Table_MM.Data = Data;
            
            
        end

        % Value changed function: NDumping
        function NDumpingValueChanged(app, event)
            value = str2double(app.NDumping.Value);            
            
            % Captura de datos
            Tmp = app.Table_Dumping.Data;
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.NDumping.Value = num2str(length(Tmp(:,1)));
                return
            end
            
            if isnan(value)
                app.NDumping.Value = num2str(length(Tmp(:,1)));
                f = errordlg('Debe ingresar un valor númerico');
                set(f, 'color',[1 1 1])
                return
            end
            
            if value < 1
                app.NDumping.Value = num2str(length(Tmp(:,1)));
               f = errordlg('El número mínimo de vertimientos es 1');
               set(f, 'color',[1 1 1])
               return
            end
            
            % Creación de matrix 
            Data = repmat({NaN},value,1);
            for i = 1:value
                Data{i} = ['VER-',num2str(i)];
            end
            Data = [Data repmat({NaN},value,15)];                       
            
            if value < length(Tmp(:,1))
                % Configurar datos
                Data = Tmp(1:value,:);
            else
                % Configurar datos
                Data(1:length(Tmp(:,1)),:) = Tmp;
            end
            
            % Mostrar datos
            app.Table_Dumping.Data = Data;
        end

        % Value changed function: Button_Plot_MM
        function Button_Plot_MMValueChanged(app, event)
            global Ax_MM
            delete(Ax_MM)
            
            app.Button_Plot_MM.Value = false;
            
            % Captura de datos
            Data = app.Table_MM.Data;
            
            % Plot movimientos en masa
            Xp = Data(:,4);
            Yp = Data(:,5);
            Ax_MM = scatter(app.MapsMM,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.5882 0 0.0941],...
                    'MarkerFaceColor',[0.5882 0 0.0941],...
                    'LineWidth',1.5);   
            
        end

        % Value changed function: Button_Plot_Dumping
        function Button_Plot_DumpingValueChanged(app, event)
            global Ax_Dumping
            delete(Ax_Dumping)
            
            app.Button_Plot_Dumping.Value = false;      
            
            % Captura de datos
            Data = app.Table_Dumping.Data;
                
            % Plot veritimientos en mapa
            Xp = cellfun(@str2num,Data(:,2));
            Yp = cellfun(@str2num,Data(:,3));
            Ax_Dumping = scatter(app.MapsDumping,Xp,Yp,20,'filled',...
                    'MarkerEdgeColor',[0.5882 0 0.0941],...
                    'MarkerFaceColor',[0.5882 0 0.0941],...
                    'LineWidth',1.5); 
                
        end

        % Value changed function: SelectCycle
        function SelectCycleValueChanged(app, event)
              app.Plot_LoadCycle
          
        end

        % Value changed function: N_Cycle
        function N_CycleValueChanged(app, event)
            % Leer datos
            [~, col]  = size(app.Table_LoadCycle.Data);          
            value     = str2double(app.N_Cycle.Value) + 1;
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.N_Cycle.Value = num2str(col-1);
                return
            end
            
            if isnan(value)               
                app.N_Cycle.Value = num2str(col);
                f = errordlg('Debe ingresar un valor númerico');
                set(f, 'color',[1 1 1])
                app.N_Cycle.Value = num2str(col-1);
                return
            end
            
            if value < 2
               f = errordlg('El número mínimo de ciclos es 1');
               set(f, 'color',[1 1 1])
               app.N_Cycle.Value = num2str(col-1);
               return
            end
            
            if value > 100
               f = errordlg('El número máximo de ciclos es 100');
               set(f, 'color',[1 1 1])
               app.N_Cycle.Value = num2str(col-1);
               return
            end
            
            % Titulos de los ciclos                
            LabelName = {'Dia'};
            for j = 1:value
                LabelName{j + 1} = ['Ciclo - ',num2str(j)];
            end
            app.Table_LoadCycle.ColumnName = LabelName;
            
            if col > value
                app.Table_LoadCycle.Data = app.Table_LoadCycle.Data(:,1:value);
            else
                Data            = NaN(366,value);
                Data(:,1:col)   = app.Table_LoadCycle.Data;
                app.Table_LoadCycle.Data = Data;
            end
            
            % Asignación de ciclos en select app
            LabelName = {'Todos'};
            for j = 1:value-1
                LabelName{j + 1} = ['Ciclo - ',num2str(j)];
            end
            
            % poner 
            app.SelectCycle.Items = LabelName;
            
            
        end

        % Value changed function: NameTimeSeries
        function NameTimeSeriesValueChanged(app, event)
            value = app.NameTimeSeries.Value;
            global Ax_TS ID_Gauges UserDataTopo
            delete(Ax_TS)
            
            app.Button_Load_TS.Enable = 'on';
            if strcmp(value,'None')
                app.Button_Load_TS.Enable = 'off';
                cla(app.Plot_TimeSeries)
                app.Table_Series.Data = [];
                xlabel(app.Plot_TimeSeries,'Tiempo (d\''ias)', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Plot_TimeSeries,'','interpreter','latex','Fontsize',18)
                title(app.Plot_TimeSeries,'','interpreter','latex','Fontsize',18)
                set(app.Plot_TimeSeries,'TickLabelInterpreter','latex','FontWeight','bold','Color','none','box','on','FontSize',15)              
                return
            elseif strcmp(value,'Temperatura Mínima')
                NameVar = 'Temperatura M\''inima';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Tmin.Value,'.txt']);  
            elseif strcmp(value,'Temperatura Máxima')
                NameVar = 'Temperatura M\''axima';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Tmin.Value,'.txt']);  
            elseif strcmp(value,'Humedad Relativa')
                NameVar = 'Humedad Relativa';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Tmax.Value,'.txt']);  
            elseif strcmp(value,'Radiación Solar')
                NameVar = 'Radiaci\''on Solar';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_RS.Value,'.txt']);  
            elseif strcmp(value,'Precipitación')
                NameVar = 'Precipitaci\''on';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_P.Value,'.txt']);  
            elseif strcmp(value,'Viento Zonal')
                NameVar = 'Viento Zonal';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_VZ.Value,'.txt']);  
            elseif strcmp(value,'Viento Meridional')
                NameVar = 'Viento Meridional';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_VM.Value,'.txt']);  
            elseif strcmp(value,'Tasas de Restauración')
                NameVar = 'Tasas de Restauraci\''on';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Res.Value,'.txt']);  
            elseif strcmp(value,'Caudal Líquido Descargado')
                NameVar = 'Caudal Líquido Descargado';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Q_LD.Value,'.txt']);  
            elseif strcmp(value,'Caudal Líquido Captado')
                NameVar = 'Caudal Líquido Captado';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Q_LC.Value,'.txt']);  
            elseif strcmp(value,'Arcillas Mezcladas en Descarga')
                NameVar = 'Arcillas Mezcladas en Descarga';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Clay.Value,'.txt']);  
            elseif strcmp(value,'Limos Mezclados en Descarga')
                NameVar = 'Limos Mezclados en Descarga';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Silt.Value,'.txt']);  
            elseif strcmp(value,'Arenas Mezcladas en Descarga')
                NameVar = 'Arenas Mezcladas en Descarga';
                FilePath = fullfile(app.ProjectPath,'entradas','series',[app.Edit_Sand.Value,'.txt']);  
            end
            
            % Check exist
            Check = isfile(FilePath);
            if ~Check
                return
            end
            
            % Leer serie de tiempo
            UserData = Read_TimeSeries_SIGA(FilePath); 
                        
            if ~strcmp(value,'Tasas de Restauración')
                % Plot estaciones en mapa
                Ax_TS   = scatter(app.MapSeries,UserData.X,UserData.Y,20,'filled',...
                            'MarkerEdgeColor',[0.5882 0 0.0941],...
                            'MarkerFaceColor',[0.5882 0 0.0941],...
                            'LineWidth',1.5,...
                            "ButtonDownFcn",{@Plot_TimeSeries,app, UserData, NameVar});
                
                % Ajustar límites 
                Xmax_B = max(UserDataTopo.X);
                Ymax_B = max(UserDataTopo.Y);
                Xmin_B = min(UserDataTopo.X);
                Ymin_B = min(UserDataTopo.Y);
                Xmax_G = max(UserData.X);
                Ymax_G = max(UserData.Y);
                Xmin_G = min(UserData.X);
                Ymin_G = min(UserData.Y);
                
                dx_B = Xmax_B - Xmin_B;
                dy_B = Ymax_B - Ymin_B;
                
                RB = dx_B/dy_B;
                
                if RB < 1
                    Factor_X = (1 - RB)*0.55;
                    Factor_Y = 0.05;
                else
                    Factor_Y = (1 - (1/RB))*0.55;
                    Factor_X = 0.05;
                end
                
                CX1 = Xmin_B - Xmin_G; CX1(CX1<1) = 0;
                CX2 = Xmax_G - Xmax_B; CX2(CX2<1) = 0;
                CX  = (max([CX1 CX2])/dx_B);
                
                CY1 = Ymin_B - Ymin_G; CY1(CY1<1) = 0;
                CY2 = Ymax_G - Ymax_B; CY2(CY2<1) = 0;
                CY  = (max([CY1 CY2])/dy_B);
                
                Factor_X = Factor_X + max([CX CY]);
                Factor_Y = Factor_Y + max([CX CY]);
                
                xlim(app.MapSeries,[(Xmin_B - (dx_B*Factor_X)) (Xmax_B + (dx_B*Factor_X))])
                ylim(app.MapSeries,[(Ymin_B - (dy_B*Factor_Y)) (Ymax_B + (dy_B*Factor_Y))])
                
                % Identificación 
                ID_Gauges = 1;
                
                % Plot Time Series
                plot(app.Plot_TimeSeries, UserData.Date, UserData.Data(:,ID_Gauges))
                xlabel(app.Plot_TimeSeries,'Tiempo (d\''ias)', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Plot_TimeSeries,NameVar,'interpreter','latex','Fontsize',18)
                title(app.Plot_TimeSeries,['Estaci\''on - ',num2str(UserData.NameVar(ID_Gauges))],'interpreter','latex','Fontsize',18)
                set(app.Plot_TimeSeries,'TickLabelInterpreter','latex','FontWeight','bold','Color','none','box','on','FontSize',15)
                
                % Mostrar datos
                Data = table(UserData.Date, UserData.Data(:,ID_Gauges),'VariableNames',{'Fecha','Valor'});
                app.Table_Series.ColumnName = {'Fecha','Valor'};
                app.Table_Series.Data = Data;
            else
                % plot mapa
                app.Plot_Basin(UserDataTopo,'MapSeries')
                
                % Identificación 
                ID_Gauges = UserData.Col;
                
                % Plot Time Series
                plot(app.Plot_TimeSeries, UserData.Date, UserData.Data)
                title(app.Plot_TimeSeries,'Series de tiempo de Tasas de Restauraci\''on','interpreter','latex','Fontsize',18)
                xlabel(app.Plot_TimeSeries,'Tiempo (d\''ias)', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Plot_TimeSeries,NameVar,'interpreter','latex','Fontsize',18)
                set(app.Plot_TimeSeries,'TickLabelInterpreter','latex','FontWeight','bold','Color','none','box','on','FontSize',15)
                
                % Mostrar datos
                Tmp = cellfun(@num2str,num2cell(UserData.NameVar),'UniformOutput',false);
                Tmp1 = 'Data = table(UserData.Date';
                for k = 1:ID_Gauges
                    Tmp1 = [Tmp1, ',UserData.Data(:,',num2str(k),')'];
                end
                Tmp1 = [Tmp1,',''VariableNames'',[{''Fecha''},Tmp]);'];
                eval(Tmp1)
                app.Table_Series.ColumnName = [{'Fecha'},Tmp];
                app.Table_Series.Data = Data;
                
            end


        end

        % Value changed function: Edit_LoadCycle
        function Edit_LoadCycleValueChanged(app, event)
            value = app.Edit_LoadCycle.Value;
            
            % -------------------------------------------------------------------
            % Configuración - Módulo de ciclos de carga
            % -------------------------------------------------------------------
            % Leer Archivo de ciclo de cargas
            FilePath            = fullfile(app.ProjectPath,'entradas','ciclos',[value,'.txt']);
            UserData_LoadCycle  = Read_LoadCycle(FilePath);
            
            % Asignar valores
            app.Asignation_LoadCycle(UserData_LoadCycle)
            
        end

        % Value changed function: Edit_FacCal
        function Edit_FacCalValueChanged2(app, event)
            value = app.Edit_FacCal.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            % Leer Archivo de Parametros
            FilePath = fullfile(app.ProjectPath,'entradas','calibracion',[value,'.txt']);
            UserData_Params = Read_CalibrationFile(FilePath, app.NoValue);                                                                          
            
            % Asignar valores
            app.Asignation_Calibration(value, UserData_Params) 
        end

        % Value changed function: Edit_Sampler
        function Edit_SamplerValueChanged2(app, event)
            value = app.Edit_Sampler.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            % Leer Archivo de Muestreador
            FilePath = fullfile(app.ProjectPath,'entradas','muestras',[value,'.txt']);
            UserData_Sampler = Read_SamplerFile(FilePath);               
            
            % Asignar valores
            app.Asignation_Sampler(UserData_Sampler)
            
        end

        % Value changed function: Edit_Dumping
        function Edit_DumpingValueChanged(app, event)
            value = app.Edit_Dumping.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
            if ~strcmp(value,'NA')
                % Leer Archivo de vertimientos
                FilePath            = fullfile(app.ProjectPath,'entradas','vertimientos',[value,'.txt']);
                UserData_Dumping    = Read_Dumping(FilePath);
                
                % Asignar valores
                app.Asignation_Dumping(UserData_Dumping)
            end
            
            %Asignar nombre
            app.Edit_NameDumping.Value  = app.Edit_Dumping.Value;
        end

        % Value changed function: Edit_MM
        function Edit_MMValueChanged(app, event)
            value = app.Edit_MM.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
            if ~strcmp(value,'NA')
                % Leer Archivo de movientos en masa
                FilePath = fullfile(app.ProjectPath,'entradas','movmasa',[value,'.txt']);
                UserData_MM = Read_MassMovements(FilePath); 
                
                % Asignar valores
                app.Asignation_MM(UserData_MM)                                 
            end
            
            %Asignar nombre
            app.Edit_NameMM.Value       = app.Edit_MM.Value;
        end

        % Value changed function: Edit_Q_LD
        function Edit_Q_LDValueChanged(app, event)
          
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Q_LC
        function Edit_Q_LCValueChanged(app, event)
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Callback function
        function Edit_ClayValueChanged(app, event)

            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Callback function
        function Edit_SiltValueChanged(app, event)

            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Callback function
        function Edit_SandValueChanged(app, event)

            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Topo
        function Edit_TopoValueChanged(app, event)
            global UserDataTopo
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
                        
            % -------------------------------------------------------------------
            % Red Topológica
            % -------------------------------------------------------------------
            % Leer archivo de red Topológica
            FilePath      = fullfile(app.ProjectPath,'entradas','topologia',[app.Edit_Topo.Value,'.txt']);
            UserDataTopo  = Read_Topology(FilePath);
            
            % Plot cuenca y red de drenaje
            app.Plot_Basin(UserDataTopo,'MapsMM')
            app.Plot_MM
            
            app.Plot_Basin(UserDataTopo,'MapsDumping') 
            app.Plot_Dumping
            
            app.Plot_Basin(UserDataTopo,'MapsMuestreo') 
            app.Plot_ControlPoints;
            app.Plot_ControlBranch_Start;
            app.Plot_ControlBranch_End;
            
            app.Plot_Basin(UserDataTopo,'MapSeries')  
                                   
        end

        % Value changed function: Button_CreateMM
        function Button_CreateMMValueChanged(app, event)
            global UserDataTopo
            
            app.Button_CreateMM.Value = 0;
            
            NameMM = inputdlg('Ingrese el nombre de la nueva configuración de movimientos en masa');
            
            if isempty(NameMM) || isempty(NameMM{1})
                return
            end
            
            NameMM              = strrep(NameMM,' ','_');
            FilePath            = fullfile(app.ProjectPath,'entradas','movmasa',[NameMM{1},'.txt']);            
            UserData.NoValue    = app.NoValue;
            UserData.Data       = app.Table_MM.Data;
            [fil, ~]            = size(UserData.Data);
            UserData.N          = fil;                               
            
            % verificación de datos
            Tmp = isnan(UserData.Data(:,1:5));
            if sum(Tmp(:)) > 0
                errordlg('El año, mes, día y las coordenadas de los movimientos en masa no pueden contener valores NaN')
                return
            end
            
            Tmp = UserData.Data(:,[1:3 6]) < 0;
            if sum(Tmp(:)) > 0
                errordlg('El año, mes, día y las área de los movimientos en masa no pueden contener valores negativos')
                return
            end
            Xmin = min(UserDataTopo.X);
            Xmax = max(UserDataTopo.X);
            Ymin = min(UserDataTopo.Y);
            Ymax = max(UserDataTopo.Y);
            
            Tmp = sum((UserData.Data(:,4) < Xmin) + (UserData.Data(:,4) > Xmax)) > 0 | ...                  
                  sum((UserData.Data(:,5) < Ymin) + (UserData.Data(:,5) > Ymax)) > 0 ;
            if Tmp
                errordlg('Algunos movimientos en masa se encuntran fuera de lso límites de la cuenca')
                return
            end
            
            % Guardar Archivo
            Write_MassMovementsFile(FilePath, UserData)           
            
            % Nombre del archivo de puntos de vertimiento
            app.Asignation_List_files_Execution;
            app.Edit_MM.Value      = NameMM;
            app.Edit_NameMM.Value  = NameMM{:};
            
            % Mensaje 
            msgbox("La nueva configuración de movimientos en masa se ha realizado correctamente");
        end

        % Value changed function: Button_CreateDumping
        function Button_CreateDumpingValueChanged(app, event)
            app.Button_CreateDumping.Value = 0;
            NameDumping = inputdlg('Ingrese el nombre de la nueva configuración de vertimientos');
            
            if isempty(NameDumping) || isempty(NameDumping{1})
                return
            end
            
            % Capturar datos
            NameDumping         = strrep(NameDumping,' ','_'); 
            NameDumping         = strrep(NameDumping,':','');
            FilePath            = fullfile(app.ProjectPath,'entradas','vertimientos',[NameDumping{1},'.txt']);            
            UserData.NoValue    = app.NoValue;
            % Check de datos
            Data    = app.Table_Dumping.Data;
            id      = sum(cellfun(@isnan,Data(:,2:end)),2) ~= 17;
            Data    = Data(id,:);
            
            Tmp     = cell2mat(Data(:,2:3)); 
            if sum(isnan(Tmp(:))) > 0
                errordlg('Las coordenadas de los vertimientos no pueden tener valores NaN')
                return
            end
            
            id      = cellfun(@isempty,Data(:,1));
            if sum(id) > 0 
                errordlg(['Todos los vertimientos deben tener asignado un nombre. ',...
                         'Tenga en cuenta que los nombres asignados deben ser diferetnes'])
                return
            end            
            
            UserData.Data       = Data;            
            [fil, ~]            = size(UserData.Data);
            UserData.N          = fil; 
            
            % Guardar Archivo         
            Write_DumpingFile(FilePath, UserData)
            
            % Nombre del archivo de puntos de vertimiento
            app.Asignation_List_files_Execution;
            app.Edit_Dumping.Value      = NameDumping;
            app.Edit_NameDumping.Value  = NameDumping{:};
            
            % Mensaje 
            msgbox("La nueva configuración de vertimientos se ha realizado correctamente");
        end

        % Value changed function: Button_CreateLoadCycle
        function Button_CreateLoadCycleValueChanged(app, event)
            app.Button_CreateLoadCycle.Value = 0;
            
            NameLC = inputdlg('Ingrese el nombre de la nueva configuración de ciclos de carga');
            
            if isempty(NameLC) || isempty(NameLC{1})
                return
            end
            
            NameLC   = strrep(NameLC,' ','_');            
            FilePath = fullfile(app.ProjectPath,'entradas','ciclos',[NameLC{1},'.txt']);
            % Leer Datos de ciclos de la app
            Tmp = app.Table_LoadCycle.Data;
            Tmp = Tmp(:,2:end);
            if sum(isnan(Tmp(:))) > 0
                f = errordlg('Los ciclos de carga no pueden contener valores nulos','Error en datos de entrada');
                set(f, 'color',[1 1 1]);
                return
            end
            
            if sum((Tmp(:)>1)|(Tmp(:)<0)) > 0
                f = errordlg('Los ciclos de carga no pueden contener valores superiores a "1" o inferiores a "0"','Error en datos de entrada');
                set(f, 'color',[1 1 1]);
                return
            end
            
            Data                = [(1:366)' Tmp];
            % Nombre de variables
            UserData.NameVar    = 'Vertimientos';
            % Número de dias
            UserData.Pday       = 366;
            % Número de ciclos            
            UserData.NCycle     = length(Data(1,2:end));
            % Paso de tiempo
            UserData.dt         = 1;
            % Tipo de modelo
            UserData.Modelo     = 'ninguno';
            UserData.CodeCycle  = 1:length(Data(1,2:end));
            UserData.Date       = Data(:,1);
            UserData.Data       = Data(:,2:end); 

            % Asignar Valores
            app.Asignation_LoadCycle(UserData);
            
            % Guardar Archivo            
            Write_LoadCycleFile(FilePath, UserData)
            
            % Nombre del archivo de puntos de vertimiento
            Path        = fullfile(app.ProjectPath,'entradas','ciclos','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_LoadCycle.Items      = FileList;
            app.Edit_LoadCycle.Value      = NameLC;
            app.Edit_NameLoadCycle.Value  = NameLC{:};
            
            % Mensaje 
            msgbox("La nueva configuración de ciclos de carga se ha realizado correctamente");
        end

        % Value changed function: Button_CreateSampler
        function Button_CreateSamplerValueChanged(app, event)
            app.Button_CreateSampler.Value = 0;
            
            NameSampler = inputdlg('Ingrese el nombre de la nueva configuración de muestreador');
            
            if isempty(NameSampler) || isempty(NameSampler{1})
                return
            end
            
            NameSampler = strrep(NameSampler,' ','_');
            
            % -------------------------------------------------------------------
            % Asignar valores vacios al Módulo de Muestreador
            % -------------------------------------------------------------------
            % Variables para resultados en puntos de control
%             app.Table_ControlPointsVar.Data         = true(1,app.NumVarSIGA);
            % Variables para resultados en tramos de control
%             app.Table_ControlBranchVar.Data         = true(1,app.NumVarSIGA);
            % Mapas de resultados
%             app.Table_ListMapVar.Data               = true(1,app.NumVarSIGA);
            % Mapas de resultados
%             app.Table_ListMapVar_Stream.Data        = true(1,app.NumVarSIGA);
            % Mapas de resultados
%             app.Table_ListMapVar_StreamMask.Data    = true(1,app.NumVarSIGA);
            % Etiquetas de varibles
%             app.Table_LabelVar.Data                 = app.NameVar;
            % Unidades de las variables
%             app.Table_VarUnit.Data                  = app.UnitVar;
            % Factores de conversión
%             Tmp = repmat({1},1,app.NumVarSIGA);
%             app.Table_ConFactors.Data               = Tmp;                
            % Listado de puntos de control
%             Tmp = cell(100,4);
%             app.Table_ControlPoint.Data             = Tmp;
            % Listado de tramos de control
%             Tmp = cell(100,5);
%             app.Table_ControlBranch.Data            = Tmp;
                
            % Leer datos de muestreador
            [~,UserData] = app.Read_Sampler_App;
            
            % guardar datos
            FilePath = fullfile(app.ProjectPath,'entradas','muestras',[NameSampler{1},'.txt']);
            Write_SamplerFile(FilePath, UserData)
            
            % Nombre del archivo de puntos de vertimiento      
            Path = fullfile(app.ProjectPath,'entradas','muestras','*.txt');
            FileList    = dir(Path);
            FileList    = {FileList.name};
            FileList    = cellfun(@(x) strrep(x,'.txt',''),FileList,'UniformOutput',false);
            app.Edit_Sampler.Items      = FileList;
            app.Edit_Sampler.Value      = NameSampler;
            app.Edit_NameSampler.Value  = NameSampler{:};
            
            % borrar puntos de gráfico
            global Ax3 Ax4 Ax5
            delete(Ax3)
            delete(Ax4)
            delete(Ax5)
            
            % Mensaje 
            msgbox("La nueva configuración de muestreador se ha realizado correctamente");
        end

        % Value changed function: Button_Load_TS
        function Button_Load_TSValueChanged(app, event)
            app.Button_Load_TS.Value = 0;
            
            % Captura de nombre de la varible a configurar
            NameTS = app.NameTimeSeries.Value;            
            
            if strcmp(app.NameTimeSeries.Value,'None')
                errordlg('Debe seleccionar una variable para poder asignarle datos')
                return
            end
            
            % Informar al usuario
            answer = questdlg(['Para poder configurar la serie de tiempo de -',NameTS,...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de series de tiempo. La plantilla puede ser descarga en el botón ',...
                'de información que se encuentra al lado del botón de creación. ',...
                'Recuerde utilizar -9999 para valores nulos. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	['Actualización de serie de tiempo - ', NameTS], ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Nombre de la serie
            UserName = inputdlg(['Ingrese el nombre de la nueva serie de tiempo de ',NameTS]);
            UserName = UserName{1};
            UserName = strrep(UserName,' ','_');
            UserName = strrep(UserName,'ñ','nn');
            
            if isempty(UserName)
                return
            end
            
            % Leer Datos
            try
                ID_File = fopen([Path,File],'r');            
                            
                UserData.NoValue    = app.NoValue; 
                Tmp     = strsplit(fgetl(ID_File),',');
                UserData.X  = cellfun(@str2double,Tmp(4:end));
                Tmp         = strsplit(fgetl(ID_File),',');
                UserData.Y  = cellfun(@str2double,Tmp(4:end));
                Tmp         = strsplit(fgetl(ID_File),',');
                UserData.Z  = cellfun(@str2double,Tmp(4:end));
                Tmp         = strsplit(fgetl(ID_File),',');
                UserData.NameVar = cellfun(@str2double,Tmp(4:end));
                UserData.Col    = length(UserData.X);
                fclose(ID_File);
                UserData.Data   = dlmread([Path,File],',',4,0);
                UserData.Date   = datetime(UserData.Data(:,1:3));
                UserData.Data   = UserData.Data(:,4:end);
                UserData.Fil    = length(UserData.Date);   
            catch
                f = errordlg('El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar');
                set(f, 'color',[1 1 1])
                return
            end
            
            % Nombre del archivo de series de tiempo de temperatura mínima
            if strcmp(NameTS,'Temperatura Mínima')
                NameTxt     = ['TimeSeries_TAmin_',UserName];
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Tmin.Value         = NameTxt;
            elseif strcmp(NameTS,'Temperatura Máxima')
                % Nombre del archivo de series de tiempo de temperatura máxima
                NameTxt     = ['TimeSeries_TAmax_',UserName];
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Tmax.Value         = NameTxt;
            elseif strcmp(NameTS,'Humedad Relativa')
                NameTxt     = ['TimeSeries_HR_',UserName];
                % Nombre del archivo de series de tiempo de humedad relativa
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_HR.Value           = NameTxt;
            elseif strcmp(NameTS,'Radiación Solar')
                NameTxt     = ['TimeSeries_RS_',UserName];
                % Nombre del archivo de series de tiempo de radiación solar
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_RS.Value           = NameTxt;
            elseif strcmp(NameTS,'Precipitación')
                NameTxt     = ['TimeSeries_PVer_',UserName];
                % Nombre del archivo de series de tiempo de precipitación
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_P.Value            = NameTxt;
            elseif strcmp(NameTS,'Viento Zonal')
                NameTxt     = ['TimeSeries_Ux_',UserName];
                % Nombre del archivo de series de tiempo de viento zonal
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_VZ.Value           = NameTxt;
            elseif strcmp(NameTS,'Viento Meridional')
                NameTxt     = ['TimeSeries_Uy_',UserName];
                % Nombre del archivo de series de tiempo de viento meridional
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_VM.Value           = NameTxt;
            elseif strcmp(NameTS,'Caudal Líquido Descargado')
                NameTxt     = ['TimeSeries_Qld_',UserName];
                % Nombre del archivo de caudal líquido descargado
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Q_LD.Value         = NameTxt;
            elseif strcmp(NameTS,'Caudal Líquido Captado')
                NameTxt     = ['TimeSeries_Qlc_',UserName];
                % Nombre del archivo de caudal líquido captado
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Q_LC.Value         = NameTxt;
            elseif strcmp(NameTS,'Arcillas Mezcladas en Descarga')
                NameTxt     = ['TimeSeries_Clay_',UserName];
                % Nombre del archivo de series de tiempo de arcillas
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Clay.Value         = NameTxt;
            elseif strcmp(NameTS,'Limos Mezclados en Descarga')
                NameTxt     = ['TimeSeries_Silt_',UserName];
                % Nombre del archivo de series de tiempo de limos
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Silt.Value         = NameTxt;
            elseif strcmp(NameTS,'Arenas Mezcladas en Descarga')
                NameTxt     = ['TimeSeries_Sand_',UserName];
                % Nombre del archivo de series de tiempo de arenas
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Sand.Value         = NameTxt;
            elseif strcmp(NameTS,'Tasas de Restauración')
                NameTxt     = ['TimeSeries_TasRest_',UserName];
                % Nombre del archivo de tasas de restauración
                Path        = fullfile(app.ProjectPath,'entradas','series',[NameTxt,'.txt']);
                Write_TimeSeriesFile(Path, UserData)
                app.Asignation_List_files_Execution;
                app.Edit_Res.Value          = NameTxt;
            end
            
            % Plot Time Series
            app.NameTimeSeriesValueChanged(NameTS);
            
            % Mensaje 
            msgbox("Serie de tiempo configurada correctamente");
        end

        % Value changed function: Button_Save_Params
        function Button_Save_ParamsValueChanged(app, event)
            app.Button_Save_Params.Value = 0;
            
            % Solicitar nombre de archivo
            Name_txt = inputdlg('Ingrese el nombre del nuevo archivo de calibración');
            
            if isempty(Name_txt) || isempty(Name_txt{1})
                return
            end
            
            % leer de la app los datos para el archivo de calibración
            [StatusError,UserData_Params] = app.Read_Calibration_App;
            
            if StatusError
                errordlg("Se a encontrado un error en los parámetros ingresados. Por favor revisar")
                return
            end
            
            % Guardar Archivo de Parametros
            Name_txt = strrep(Name_txt,' ','_');            
            FilePath = fullfile(app.ProjectPath,'entradas','calibracion',[Name_txt{1},'.txt']);  
            Write_CalibrationFile(FilePath, UserData_Params);                                              
            
            % Leer arhivos de carpetas para lista desplegable
            app.Asignation_List_files_Execution;
            
            % Asignar a lista desplegable de pestaña de ejcuación
            app.Edit_FacCal.Value = Name_txt{1};
            
            % Asignar a cuadro de texto de pestaña de parámetros
            app.Edit_CalName.Value = Name_txt{1};
            
            % Mensaje 
            msgbox("La nueva configuración de parámetros se ha realizado correctamente");
        end

        % Value changed function: Button_Load_MM
        function Button_Load_MMValueChanged(app, event)
            app.Button_Load_MM.Value = 0;
            
            % Informar al usuario
            answer = questdlg(['Para poder configurar los movimientos en masa',...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de movimientos en masa. La plantilla puede ser descargada en el botón ',...
                'de información que se encuentra al lado del botón de creación. ',...
                'Recuerde utilizar -9999 para valores nulos. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	'Movimientos en masa', ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Lectura de datos
            try
                Data = dlmread([Path,File],',',1,0);                
            catch
                f = errordlg('El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar');
                set(f, 'color',[1 1 1])
                return
            end
            
            % Remplazar valores nodata
            Data(Data == app.NoValue) = NaN;            
            
            % Asignar datos a tabla
            app.Table_MM.Data = Data;
            
            % Graficar datos  
            app.Button_Plot_MM(1);
            
            % Numero de vertimientos
            app.N_MM.Value = num2str(length(Data(:,1)));
            
            % Plot
            app.Button_Plot_MMValueChanged(0)
            
            % mostrar mensaje
            msgbox('Configuración de datos exitosa')
        end

        % Value changed function: Button_Load_Dumping
        function Button_Load_DumpingValueChanged(app, event)
            app.Button_Load_Dumping.Value = 0;                        
            
            % Informar al usuario
            answer = questdlg(['Para poder configurar vertimientos',...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de vertimientos. La plantilla puede ser descargada en el botón ',...
                'de información que se encuentra al lado del botón de creación. ',...
                'Recuerde utilizar -9999 para valores nulos. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	'Vertimientos', ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Lectura de datos
            try
                ID_File = fopen([Path,File],'r');                
                Tmp = ['%s',repmat('%f',1,15)]; 
                TextLine = fgetl(ID_File);
                Data = textscan(ID_File, Tmp,'Delimiter',',');
                fclose(ID_File);
                
                % Tomar datos numericos
                Tmp = cell2mat(Data(:,2:end));
                
                % Remplazar valores nodata
                Tmp(Tmp == app.NoValue) = NaN;
                                
                % Transformar a Cell
                Tmp = num2cell(Tmp);
                
                % Asignar
                Name        = Data{1}; 
                Data        = cell(length(Data{1}),16);
                Data(:,1)   = Name;
                Data(:,2:end) = Tmp;
            catch
                f = errordlg('El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar');
                set(f, 'color',[1 1 1])
                return
            end            
            
            % Asignar datos a tabla
            app.Table_Dumping.Data = Data;                       
            
            % Numero de vertimientos
            app.NDumping.Value = num2str(length(Data(:,1)));
            
            % Graficar datos  
            app.Button_Plot_Dumping(1);
            
            % mostrar mensaje
            msgbox('Configuración de datos exitosa')
        end

        % Value changed function: Button_Load_LC
        function Button_Load_LCValueChanged(app, event)
            app.Button_Load_LC.Value = 0;
            
            % Informar al usuario
            answer = questdlg(['Para poder configurar los ciclos de carga',...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de ciclos de carga. La plantilla puede ser descargada en el botón ',...
                'de información que se encuentra al lado del botón de creación. ',...
                'Recuerde que este archivo no puede contener valores nulos. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	'Movimientos en masa', ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Lectura de datos
            try
                Code = dlmread([Path,File],',',0,1);
                Code = Code(1,:);
                Data = dlmread([Path,File],',',1,1);                
            catch
                f = errordlg('El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar');
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check valores nulos
            if sum(isnan(Data(:))) > 0
                f = errordlg('El archivo no puede contener valores nulos');
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check de longitud
            if length(Data(:,1)) ~= 366
                f = errordlg('El archivo de contener 367 filas. Revisar estructura');
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check de número de ciclos
            [~,c] = size(Data);
            if c == 1
                f = errordlg('El archivo de contener 367 filas y 2 columnas. Revisar estructura');
                set(f, 'color',[1 1 1]);
                return
            end

            % Asignar datos a estructura            
            UserData.Pday       = 366;              
            UserData.NCycle     = length(Code);
            UserData.Data       = Data;
            UserData.Date       = (1:366)';
            UserData.CodeCycle  = Code;
            
            % Asignar datos
            app.Asignation_LoadCycle(UserData)
            
            % mostrar mensaje
            msgbox('Configuración de datos exitosa')
            
        end

        % Value changed function: Info_LC
        function Info_LCValueChanged(app, event)
            app.Info_LC.Value = 0;            
            % Plantilla de datos
            dos('explorer https://tnc.box.com/s/dlrqyhec6pdqwhmzzrx3j9wzhjqfrcel')
        end

        % Value changed function: Info_TS
        function Info_TSValueChanged(app, event)
            app.Info_TS.Value = 0;
            
            % Plantilla de datos
            dos('explorer https://tnc.box.com/s/a9nw3dcvb971u8rj7jk35r8151kh03kh')
        end

        % Value changed function: Info_MM
        function Info_MMValueChanged(app, event)
            app.Info_MM.Value = 0;
            
            % Plantilla de datos
            dos('explorer https://tnc.box.com/s/gy7io2djq80zca0doorc0y2qjhgxjhe8')
        end

        % Value changed function: Info_Dumping
        function Info_DumpingValueChanged(app, event)
            app.Info_Dumping.Value = 0;
            
            % Plantilla de datos
            dos('explorer https://tnc.box.com/s/c4izyc2kclv15f41bmek9lbj6ij6udzf')
        end

        % Value changed function: N_Pveg
        function N_PvegValueChanged(app, event)
            value       = str2double(app.N_Pveg.Value);
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.N_Pveg.Value = '2';
                return
            end

            if isnan(value)
                app.N_Pveg.Value = '2';
                f = errordlg('Debe ingresar un valor númerico');
                set(f, 'color',[1 1 1])
                return
            end
            
            if value < 2
                app.N_Pveg.Value = '2';
               f = errordlg(['El número mínimo de acciones de restauración es 2. ',...
                             'Es obligatorio que los códigos 0 y 1 esten.']);
               set(f, 'color',[1 1 1])
               return
            end
            
            if value > 500
               f = errordlg('El número máximo de acciones de restauración es 500');
               set(f, 'color',[1 1 1])
               return
            end                            
            
            [fil, ~]  = size(app.Table_Veg.Data);
            
            if fil > value
                app.Table_Veg.Data  = app.Table_Veg.Data(1:value,:);
            else
                Data                = NaN(value,5);
                Data(:,1)           = (0:value-1)';
                Data(1:fil,:)       = app.Table_Veg.Data;
                app.Table_Veg.Data  = Data;
            end
                      
        end

        % Close request function: Configuracion
        function ConfiguracionCloseRequest(app, event)
            app.AppSIGA.Enable_Button;
            
            % Cerrar app
            close(app.Configuracion)           
        end

        % Value changed function: Edit_Tmax
        function Edit_TmaxValueChanged(app, event)
            value = app.Edit_Tmax.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
        end

        % Value changed function: Edit_RS
        function Edit_RSValueChanged(app, event)
            value = app.Edit_RS.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
        end

        % Value changed function: Button_Load_Points
        function Button_Load_PointsValueChanged(app, event)
            app.Button_Load_Points.Value = 0;
            
            % Informar al usuario
            answer = questdlg(['Para poder cargar Puntos de Control',...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de Puntos de Control. La plantilla puede ser descargada en el botón ',...
                'de información que se encuentra al lado. ',...
                'Recuerde que este archivo no puede contener valores nulos o vacios. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	'Puntos de Control', ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Lectura de datos
            try
                Tmp  = readtable([Path,File]);
                Data = [Tmp(:,1:2).Variables];
                Name = [Tmp(:,3:4).Variables];
            catch
                f = errordlg(['El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar']);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check de valores nulos
            id = isnan(Data);
            if sum(id) > 0
                f = errordlg('El archivo contiene valores nulos. Por favor revisar y volver a cargar',...
                    'Error en archivo');
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check de estadistico
            MyFun = @(x) strcmp(x,'valor')||strcmp(x,'media');
            id = cellfun(MyFun,Name(:,end));
            if sum(id) ~= length(Name(:,end))
                f = errordlg(['El archivo contiene valores de estadístico que no son correctos.',...
                    'Solo se admiten "valor" y "media". Por favor revisar y volver a cargar'], 'Error en archivo');
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Asignar datos
            Tmp = cell(100,4);            
            try
                R = [num2cell(Data) Name];
                for i = 1:length(R(:,1))
                    Tmp(i,:) = R(i,:);
                end
                app.Table_ControlPoint.Data = Tmp;
                
                app.Plot_ControlPoints;
            catch
                f = errordlg(['El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar']);
                set(f, 'color',[1 1 1]);
                return
            end
        end

        % Value changed function: Button_Load_Branch
        function Button_Load_BranchValueChanged(app, event)
            app.Button_Load_Branch.Value  = 0;
            
            % Informar al usuario
            answer = questdlg(['Para poder cargar Tramos de Control',...
                ', los datos deben estar configurados de acuerdo con la plantilla ',...
                'de Tramos de Control. La plantilla puede ser descargada en el botón ',...
                'de información que se encuentra al lado. ',...
                'Recuerde que este archivo no puede contener valores nulos o vacios. ',...
                '¿Los datos están configurados de acuerdo con la plantilla? '], ...
            	'Puntos de Control', ...
            	'Si','No','Si');
            
            if strcmp(answer,'No')
                return
            end
            
            % Seleccione el archivo
            [File,Path] = uigetfile('*.csv');
            
            if ~ischar(File) || ~ischar(Path)
                return
            end
            
            % Lectura de datos
            try
                Tmp  = readtable([Path,File]);
                Data = [Tmp(:,1:4).Variables];
                Name = [Tmp(:,5).Variables];
            catch
                f = errordlg(['El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar']);
                set(f, 'color',[1 1 1]);
                return
            end
            
            % Check de valores nulos
            id = isnan(Data);
            if sum(id) > 0
                f = errordlg('El archivo contiene valores nulos. Por favor revisar y volver a cargar',...
                    'Error en archivo');
                set(f, 'color',[1 1 1]);
                return
            end            
            
            % Asignar datos
            Tmp = cell(100,5);            
            try
                R = [num2cell(Data) Name];
                for i = 1:length(R(:,1))
                    Tmp(i,:) = R(i,:);
                end
                app.Table_ControlBranch.Data = Tmp;
                
                app.Plot_ControlBranch_Start;
                app.Plot_ControlBranch_End;
            catch
                f = errordlg(['El archivo no se encuentra en la estructura correcta ',...
                    'o no esta adecuadamente configurado. Por favor revisar y volver a cargar']);
                set(f, 'color',[1 1 1]);
                return
            end
        end

        % Value changed function: Edit_Clay
        function Edit_ClayValueChanged2(app, event)
            value = app.Edit_Clay.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Silt
        function Edit_SiltValueChanged2(app, event)
            value = app.Edit_Silt.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Sand
        function Edit_SandValueChanged2(app, event)
            value = app.Edit_Sand.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Tmin
        function Edit_TminValueChanged(app, event)
            value = app.Edit_Tmin.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_HR
        function Edit_HRValueChanged(app, event)
            value = app.Edit_HR.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_P
        function Edit_PValueChanged(app, event)
            value = app.Edit_P.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_VZ
        function Edit_VZValueChanged(app, event)
            value = app.Edit_VZ.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_VM
        function Edit_VMValueChanged(app, event)
            value = app.Edit_VM.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Edit_Res
        function Edit_ResValueChanged(app, event)
            value = app.Edit_Res.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            app.Asignation_ListTimeSeries
        end

        % Value changed function: Info_LC_2
        function Info_LC_2ValueChanged(app, event)
            app.Info_LC_2.Value = 0;
            dos('explorer https://tnc.box.com/s/m1otazr0b2skrzc5vd1qz14dtw3ofb4w')
            
        end

        % Value changed function: Info_LC_3
        function Info_LC_3ValueChanged(app, event)
            app.Info_LC_3.Value = 0;
            dos('explorer https://tnc.box.com/s/4vk5hphdpkedmbur6lbzhmg4c57qkn9q')
            
        end

        % Value changed function: Edit_Boundary
        function Edit_BoundaryValueChanged(app, event)
            value = app.Edit_Boundary.Value;
            
            % Actulizar lista de archivos
            app.Asignation_List_files_Execution;
            
            % % Leer Archivo de Muestreador
            % FilePath = fullfile(app.ProjectPath,'entradas','muestras',[value,'.txt']);
            % UserData_Sampler = Read_SamplerFile(FilePath);               
            % 
            % % Asignar valores
            % app.Asignation_Sampler(UserData_Sampler)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Configuracion = uifigure('Visible', 'off');
            app.Configuracion.Name = 'UI Figure';
            app.Configuracion.CloseRequestFcn = createCallbackFcn(app, @ConfiguracionCloseRequest, true);
            app.ConfiguracindelmodeloSIGACALCuencaVerdePanel = uipanel(app.Configuracion);
            app.ConfiguracindelmodeloSIGACALCuencaVerdePanel.Title = 'Configuración del modelo SIGA-CAL (CuencaVerde)';
            app.TabGroup3 = uitabgroup(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.EjecucinTab = uitab(app.TabGroup3);
            app.EjecucinTab.Title = 'Ejecución';
            app.MduloFenolgicosPanel = uipanel(app.EjecucinTab);
            app.MduloFenolgicosPanel.Title = 'Módulo Fenológicos';
            app.TasasderestauracinLabel = uilabel(app.MduloFenolgicosPanel);
            app.TasasderestauracinLabel.Text = {'Tasas de '; 'restauración (*)'};
            app.Edit_Res = uidropdown(app.MduloFenolgicosPanel);
            app.Edit_Res.Items = {'NA'};
            app.Edit_Res.ValueChangedFcn = createCallbackFcn(app, @Edit_ResValueChanged, true);
            app.Edit_Res.Value = 'NA';
            app.ConfiguracionesgeneralesPanel = uipanel(app.EjecucinTab);
            app.ConfiguracionesgeneralesPanel.Title = 'Configuraciones generales';
            app.FechaInicialLabel = uilabel(app.ConfiguracionesgeneralesPanel);
            app.FechaInicialLabel.Text = 'Fecha Inicial (*)';
            app.Edit_StartDate = uidatepicker(app.ConfiguracionesgeneralesPanel);
            app.Edit_StartDate.Limits = [datetime([1900 1 1]) datetime([9999 12 31])];
            app.FechaFinalLabel = uilabel(app.ConfiguracionesgeneralesPanel);
            app.FechaFinalLabel.Text = 'Fecha Final (*)';
            app.Edit_EndDate = uidatepicker(app.ConfiguracionesgeneralesPanel);
            app.Edit_EndDate.Limits = [datetime([1900 1 1]) datetime([9999 12 31])];
            app.TopologaLabel = uilabel(app.ConfiguracionesgeneralesPanel);
            app.TopologaLabel.Text = 'Topología (*)';
            app.Edit_Topo = uidropdown(app.ConfiguracionesgeneralesPanel);
            app.Edit_Topo.Items = {'NA'};
            app.Edit_Topo.ValueChangedFcn = createCallbackFcn(app, @Edit_TopoValueChanged, true);
            app.Edit_Topo.Value = 'NA';
            app.FactoresdecalibracinLabel = uilabel(app.ConfiguracionesgeneralesPanel);
            app.FactoresdecalibracinLabel.Text = {'Factores de'; 'calibración (*)'};
            app.Edit_FacCal = uidropdown(app.ConfiguracionesgeneralesPanel);
            app.Edit_FacCal.Items = {'NA'};
            app.Edit_FacCal.ValueChangedFcn = createCallbackFcn(app, @Edit_FacCalValueChanged2, true);
            app.Edit_FacCal.Value = 'NA';
            app.MuestreadorderesultadosLabel = uilabel(app.ConfiguracionesgeneralesPanel);
            app.MuestreadorderesultadosLabel.Text = {'Muestreador'; 'de resultados (*)'};
            app.Edit_Sampler = uidropdown(app.ConfiguracionesgeneralesPanel);
            app.Edit_Sampler.Items = {'NA'};
            app.Edit_Sampler.ValueChangedFcn = createCallbackFcn(app, @Edit_SamplerValueChanged2, true);
            app.Edit_Sampler.Value = 'NA';
            app.MuestreadorderesultadosLabel_2 = uilabel(app.ConfiguracionesgeneralesPanel);
            app.MuestreadorderesultadosLabel_2.Text = {'Condiciones'; 'de frontera (*)'};
            app.Edit_Boundary = uidropdown(app.ConfiguracionesgeneralesPanel);
            app.Edit_Boundary.Items = {'NA'};
            app.Edit_Boundary.ValueChangedFcn = createCallbackFcn(app, @Edit_BoundaryValueChanged, true);
            app.Edit_Boundary.Value = 'NA';
            app.MdulometeorolgicoehidrolgicoPanel = uipanel(app.EjecucinTab);
            app.MdulometeorolgicoehidrolgicoPanel.Title = 'Módulo meteorológico e hidrológico';
            app.TemperaturamnimaLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.TemperaturamnimaLabel.Text = {'Temperatura'; 'mínima (*)'};
            app.Edit_Tmin = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_Tmin.Items = {'NA'};
            app.Edit_Tmin.ValueChangedFcn = createCallbackFcn(app, @Edit_TminValueChanged, true);
            app.Edit_Tmin.Value = 'NA';
            app.TemperaturamximaLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.TemperaturamximaLabel.Text = {'Temperatura'; 'máxima (*)'};
            app.Edit_Tmax = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_Tmax.Items = {'NA'};
            app.Edit_Tmax.ValueChangedFcn = createCallbackFcn(app, @Edit_TmaxValueChanged, true);
            app.Edit_Tmax.Value = 'NA';
            app.HumedadrelativaLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.HumedadrelativaLabel.Text = {'Humedad'; 'relativa (*)'; ''};
            app.Edit_HR = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_HR.Items = {'NA'};
            app.Edit_HR.ValueChangedFcn = createCallbackFcn(app, @Edit_HRValueChanged, true);
            app.Edit_HR.Value = 'NA';
            app.RadiacinsolarLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.RadiacinsolarLabel.Text = {'Radiación'; 'solar (*)'};
            app.Edit_RS = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_RS.Items = {'NA'};
            app.Edit_RS.ValueChangedFcn = createCallbackFcn(app, @Edit_RSValueChanged, true);
            app.Edit_RS.Value = 'NA';
            app.PrecipitacinLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.PrecipitacinLabel.Text = 'Precipitación (*)';
            app.Edit_P = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_P.Items = {'NA'};
            app.Edit_P.ValueChangedFcn = createCallbackFcn(app, @Edit_PValueChanged, true);
            app.Edit_P.Value = 'NA';
            app.VientozonalLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.VientozonalLabel.Text = {'Viento'; 'zonal (*)'};
            app.Edit_VZ = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_VZ.Items = {'NA'};
            app.Edit_VZ.ValueChangedFcn = createCallbackFcn(app, @Edit_VZValueChanged, true);
            app.Edit_VZ.Value = 'NA';
            app.VientomeridionalLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.VientomeridionalLabel.Text = {'Viento'; 'meridional (*)'};
            app.Edit_VM = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_VM.Items = {'NA'};
            app.Edit_VM.ValueChangedFcn = createCallbackFcn(app, @Edit_VMValueChanged, true);
            app.Edit_VM.Value = 'NA';
            app.CaudallquidodescargadoLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.CaudallquidodescargadoLabel.Text = {'Caudal líquido'; 'descargado'};
            app.Edit_Q_LD = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_Q_LD.Items = {'NA'};
            app.Edit_Q_LD.ValueChangedFcn = createCallbackFcn(app, @Edit_Q_LDValueChanged, true);
            app.Edit_Q_LD.Value = 'NA';
            app.CaudallquidocaptadoLabel = uilabel(app.MdulometeorolgicoehidrolgicoPanel);
            app.CaudallquidocaptadoLabel.Text = {'Caudal líquido'; 'captado'};
            app.Edit_Q_LC = uidropdown(app.MdulometeorolgicoehidrolgicoPanel);
            app.Edit_Q_LC.Items = {'NA'};
            app.Edit_Q_LC.ValueChangedFcn = createCallbackFcn(app, @Edit_Q_LCValueChanged, true);
            app.Edit_Q_LC.Value = 'NA';
            app.MdulodecalidaddelaguaPanel = uipanel(app.EjecucinTab);
            app.MdulodecalidaddelaguaPanel.Title = 'Módulo de calidad del agua';
            app.PuntosdevertimientosLabel = uilabel(app.MdulodecalidaddelaguaPanel);
            app.PuntosdevertimientosLabel.Text = {'Puntos de'; 'vertimientos'};
            app.Edit_Dumping = uidropdown(app.MdulodecalidaddelaguaPanel);
            app.Edit_Dumping.Items = {'NA'};
            app.Edit_Dumping.ValueChangedFcn = createCallbackFcn(app, @Edit_DumpingValueChanged, true);
            app.Edit_Dumping.Value = 'NA';
            app.CiclosanualesdecargasdifusasLabel = uilabel(app.MdulodecalidaddelaguaPanel);
            app.CiclosanualesdecargasdifusasLabel.Text = {'Ciclos anuales de'; 'cargas difusas (*)'};
            app.Edit_LoadCycle = uidropdown(app.MdulodecalidaddelaguaPanel);
            app.Edit_LoadCycle.Items = {'NA'};
            app.Edit_LoadCycle.ValueChangedFcn = createCallbackFcn(app, @Edit_LoadCycleValueChanged, true);
            app.Edit_LoadCycle.Value = 'NA';
            app.MdulosedimentolgicoPanel = uipanel(app.EjecucinTab);
            app.MdulosedimentolgicoPanel.Title = 'Módulo sedimentológico';
            app.LimosmezcladosendescargaLabel = uilabel(app.MdulosedimentolgicoPanel);
            app.LimosmezcladosendescargaLabel.Text = {'Limos mezclados'; ' en descarga'};
            app.Edit_Silt = uidropdown(app.MdulosedimentolgicoPanel);
            app.Edit_Silt.Items = {'NA'};
            app.Edit_Silt.ValueChangedFcn = createCallbackFcn(app, @Edit_SiltValueChanged2, true);
            app.Edit_Silt.Value = 'NA';
            app.ArenasmezcladasendescargaLabel = uilabel(app.MdulosedimentolgicoPanel);
            app.ArenasmezcladasendescargaLabel.Text = {'Arenas mezcladas'; 'en descarga'};
            app.Edit_Sand = uidropdown(app.MdulosedimentolgicoPanel);
            app.Edit_Sand.Items = {'NA'};
            app.Edit_Sand.ValueChangedFcn = createCallbackFcn(app, @Edit_SandValueChanged2, true);
            app.Edit_Sand.Value = 'NA';
            app.ArcillasmezcladasendescargaLabel = uilabel(app.MdulosedimentolgicoPanel);
            app.ArcillasmezcladasendescargaLabel.Text = {'Arcillas mezcladas'; ' en descarga'};
            app.Edit_Clay = uidropdown(app.MdulosedimentolgicoPanel);
            app.Edit_Clay.Items = {'NA'};
            app.Edit_Clay.ValueChangedFcn = createCallbackFcn(app, @Edit_ClayValueChanged2, true);
            app.Edit_Clay.Value = 'NA';
            app.MovimientosenmasaLabel = uilabel(app.MdulosedimentolgicoPanel);
            app.MovimientosenmasaLabel.Text = {'Movimientos'; 'en masa'};
            app.Edit_MM = uidropdown(app.MdulosedimentolgicoPanel);
            app.Edit_MM.Items = {'NA'};
            app.Edit_MM.ValueChangedFcn = createCallbackFcn(app, @Edit_MMValueChanged, true);
            app.Edit_MM.Value = 'NA';
            app.ConfiguracionesdeejecucinPanel = uipanel(app.EjecucinTab);
            app.ConfiguracionesdeejecucinPanel.Title = 'Configuraciones de ejecución';
            app.EscenarioLabel_2 = uilabel(app.ConfiguracionesdeejecucinPanel);
            app.EscenarioLabel_2.Text = 'Escenario (*)';
            app.Edit_ExeFileName = uieditfield(app.ConfiguracionesdeejecucinPanel, 'text');
            app.Edit_ExeFileName.Value = 'NA';
            app.MododeejecucinLabel = uilabel(app.ConfiguracionesdeejecucinPanel);
            app.MododeejecucinLabel.Text = {'Modo de '; 'ejecución (*)'};
            app.Edit_SIGAExeType = uidropdown(app.ConfiguracionesdeejecucinPanel);
            app.Edit_SIGAExeType.Items = {'Hídrología', 'Hídrología+Sedimentos', 'Hídrología+Sedimentos+CalidadAgua'};
            app.Edit_SIGAExeType.ValueChangedFcn = createCallbackFcn(app, @Edit_SIGAExeTypeValueChanged, true);
            app.Edit_SIGAExeType.Value = 'Hídrología';
            app.Edit_GeoExeType = uicheckbox(app.ConfiguracionesdeejecucinPanel);
            app.Edit_GeoExeType.ValueChangedFcn = createCallbackFcn(app, @Edit_GeoExeTypeValueChanged, true);
            app.Edit_GeoExeType.Enable = 'off';
            app.Edit_GeoExeType.Text = '';
            app.ActivarmodelogeotcnicoLabel = uilabel(app.ConfiguracionesdeejecucinPanel);
            app.ActivarmodelogeotcnicoLabel.Text = {'Activar modelo '; 'geotécnico (*)'};
            app.Edit_pHExeType = uicheckbox(app.ConfiguracionesdeejecucinPanel);
            app.Edit_pHExeType.Enable = 'off';
            app.Edit_pHExeType.Text = '';
            app.ActivarmodelogeotcnicoLabel_2 = uilabel(app.ConfiguracionesdeejecucinPanel);
            app.ActivarmodelogeotcnicoLabel_2.Text = {'Evaluar modelo '; 'pH cuenca inicializada (*)'};
            app.ParmetrosTab = uitab(app.TabGroup3);
            app.ParmetrosTab.Title = 'Parámetros';
            app.NombredeconfiguracindeCalibracinLabel = uilabel(app.ParmetrosTab);
            app.NombredeconfiguracindeCalibracinLabel.Text = 'Nombre de configuración de Calibración';
            app.Edit_CalName = uieditfield(app.ParmetrosTab, 'text');
            app.Edit_CalName.Editable = 'off';
            app.Edit_CalName.Value = 'NA';
            app.ParamsMenu = uitabgroup(app.ParmetrosTab);
            app.MdulometeorolgicohidrolgicoyfenolgicoTab = uitab(app.ParamsMenu);
            app.MdulometeorolgicohidrolgicoyfenolgicoTab.Title = 'Módulo meteorológico, hidrológico y fenológico';
            app.Table_Met = uitable(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.Table_Met.ColumnName = {'p'; 'Ez1'; 'Ez2'};
            app.Table_Met.RowName = {''};
            app.Table_Met.ColumnEditable = true;
            app.Table_Veg = uitable(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.Table_Veg.ColumnName = {'Transicion'; 'CLC'; 'Deltatm'; 'idQ'; 'Accion'};
            app.Table_Veg.RowName = {''};
            app.Table_Veg.ColumnEditable = [false true true true true];
            app.ParmetrosmeteorolgicosLabel = uilabel(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.ParmetrosmeteorolgicosLabel.Text = 'Parámetros meteorológicos';
            app.ParmetrosderestauracindelavegetacinLabel = uilabel(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.ParmetrosderestauracindelavegetacinLabel.Text = 'Parámetros de restauración de la vegetación';
            app.Table_Fen = uitable(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.Table_Fen.ColumnName = {'CLC'; 'psi'; 'theta'; 'n2'; 'C_usle'; 'LAImax'; 'LAImin'; 'PHU'; 'l1'; 'l2'; 'l3'; 'frPHUsen'; 'SMIc'; 'nSMI'; 'TAinf'; 'TAsup'; 'SOS1'; 'SOS2'; 'KCmin'; 'KCmax'; 'etah'};
            app.Table_Fen.RowName = {''};
            app.Table_Fen.ColumnEditable = true;
            app.ParmetrosfenolgicosLabel = uilabel(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.ParmetrosfenolgicosLabel.Text = 'Parámetros fenológicos';
            app.Table_Hid = uitable(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.Table_Hid.ColumnName = {'fc_PV'; 'fc_PH'; 'fc_SU0'; 'fc_SU1'; 'fc_SU3'; 'fc_ks'; 'fc_kp'; 'fc_ki'; 'fc_U2'; 'fc_U3'; 'fc_U4'; 'fc_U5'; 'us'; 'ui'};
            app.Table_Hid.RowName = {''};
            app.Table_Hid.ColumnEditable = true;
            app.ParmetroshidrolgicosLabel = uilabel(app.MdulometeorolgicohidrolgicoyfenolgicoTab);
            app.ParmetroshidrolgicosLabel.Text = 'Parámetros hidrológicos';
            app.N_Pveg = uieditfield(app.MdulometeorolgicohidrolgicoyfenolgicoTab, 'text');
            app.N_Pveg.ValueChangedFcn = createCallbackFcn(app, @N_PvegValueChanged, true);
            app.N_Pveg.Value = '1';
            app.MdulosedimentolgicoTab = uitab(app.ParamsMenu);
            app.MdulosedimentolgicoTab.Title = 'Módulo sedimentológico';
            app.Table_Sed = uitable(app.MdulosedimentolgicoTab);
            app.Table_Sed.ColumnName = {'fc_E2Smax'; 'fc_E5Smax'; 'Bx'; 'b'; 'Us_arc'; 'Us_lim'; 'Us_are'; 'Ds_arc'; 'Ds_lim'; 'Ds_are'; 'Gs'; 'Rs'};
            app.Table_Sed.RowName = {''};
            app.Table_Sed.ColumnEditable = true;
            app.ParmetrossedimentolgicosLabel = uilabel(app.MdulosedimentolgicoTab);
            app.ParmetrossedimentolgicosLabel.Text = 'Parámetros sedimentológicos';
            app.Table_Geo = uitable(app.MdulosedimentolgicoTab);
            app.Table_Geo.ColumnName = {'fc_c'; 'fc_phi'; 'fc_rhoB'; 'fc_HS'; 'alfa_crit'; 'alfa_stop'; 'a_stop'};
            app.Table_Geo.RowName = {''};
            app.Table_Geo.ColumnEditable = true;
            app.ParmetrosgeotcnicosLabel = uilabel(app.MdulosedimentolgicoTab);
            app.ParmetrosgeotcnicosLabel.Text = 'Parámetros geotécnicos';
            app.Params_CalidadAgua = uitab(app.ParamsMenu);
            app.Params_CalidadAgua.Title = 'Módulo de calidad del agua';
            app.Table_Nit = uitable(app.Params_CalidadAgua);
            app.Table_Nit.ColumnName = {'ts_desni'; 'ts_fija'; 'ts_pltNO3'; 'ts_nitri'; 'ts_miner'; 'ts_inmov'; 'ts_pltNH4'; 'ts_hidNO'; 'Us_NO'; 'K_odn'; 'fc_QNO'; 'fc_QNO3'; 'fc_QNH4'};
            app.Table_Nit.RowName = {''};
            app.Table_Nit.ColumnEditable = true;
            app.ParmetrosdeaplicacindecargasdifusasLabel = uilabel(app.Params_CalidadAgua);
            app.ParmetrosdeaplicacindecargasdifusasLabel.Text = 'Parámetros de aplicación de cargas difusas';
            app.Table_CaD = uitable(app.Params_CalidadAgua);
            app.Table_CaD.ColumnName = {'idQ'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'};
            app.Table_CaD.RowName = {''};
            app.Table_CaD.ColumnEditable = true;
            app.Table_DBO = uitable(app.Params_CalidadAgua);
            app.Table_DBO.ColumnName = {'ts_d'; 'Us_cdbo'};
            app.Table_DBO.RowName = {''};
            app.Table_DBO.ColumnEditable = true;
            app.ModeloDBOcarbonaceaLabel = uilabel(app.Params_CalidadAgua);
            app.ModeloDBOcarbonaceaLabel.Text = 'Modelo DBO carbonacea';
            app.Table_Air = uitable(app.Params_CalidadAgua);
            app.Table_Air.ColumnName = {'mod_rair'; 'fc_ts_ra'};
            app.Table_Air.RowName = {''};
            app.Table_Air.ColumnEditable = true;
            app.ModelodereaireacinLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodereaireacinLabel.Text = 'Modelo de reaireación';
            app.Table_EsC = uitable(app.Params_CalidadAgua);
            app.Table_EsC.ColumnName = {'ts_regen'; 'mrt_min'; 'Us_sst'; 'fr_bs'; 'Io'; 'fc_QEC'};
            app.Table_EsC.RowName = {''};
            app.Table_EsC.ColumnEditable = true;
            app.ModelodeescherichacoliLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodeescherichacoliLabel.Text = 'Modelo de eschericha coli';
            app.ModelodenitrgenoLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodenitrgenoLabel.Text = 'Modelo de nitrógeno';
            app.Table_Fos = uitable(app.Params_CalidadAgua);
            app.Table_Fos.ColumnName = {'ts_minerP'; 'ts_inmovP'; 'ts_pltPO'; 'ts_pltPI'; 'ts_fbPOin'; 'ts_fbPOout'; 'ts_fbPIin'; 'ts_fbPIout'; 'ts_hidPO'; 'Us_PO'; 'Us_PI'; 'fc_QPO'; 'fc_QPI'};
            app.Table_Fos.RowName = {''};
            app.Table_Fos.ColumnEditable = true;
            app.ModelodefsforoLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodefsforoLabel.Text = 'Modelo de fósforo';
            app.Table_CoT = uitable(app.Params_CalidadAgua);
            app.Table_CoT.ColumnName = {'b_CE'; 'alfa'};
            app.Table_CoT.RowName = {''};
            app.Table_CoT.ColumnEditable = true;
            app.ModelodecoliformestotalesLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodecoliformestotalesLabel.Text = 'Modelo de coliformes totales';
            app.Table_Tur = uitable(app.Params_CalidadAgua);
            app.Table_Tur.ColumnName = {'K_turb'; 'exp_turb'};
            app.Table_Tur.RowName = {''};
            app.Table_Tur.ColumnEditable = true;
            app.ModelodeturbiedadLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodeturbiedadLabel.Text = 'Modelo de turbiedad';
            app.Table_CoE = uitable(app.Params_CalidadAgua);
            app.Table_CoE.ColumnName = {'mod_rair'; 'fc_ts_ra'};
            app.Table_CoE.RowName = {''};
            app.Table_CoE.ColumnEditable = true;
            app.ModelodeconductividadelctricaLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodeconductividadelctricaLabel.Text = 'Modelo de conductividad eléctrica';
            app.Table_SST = uitable(app.Params_CalidadAgua);
            app.Table_SST.ColumnName = {'fr_arc'; 'fr_lim'; 'fr_are'};
            app.Table_SST.RowName = {''};
            app.Table_SST.ColumnEditable = true;
            app.ModelodesolidossuspendidostotalesLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodesolidossuspendidostotalesLabel.Text = 'Modelo de solidos suspendidos totales';
            app.Table_Tsa = uitable(app.Params_CalidadAgua);
            app.Table_Tsa.ColumnName = {'t_ss'; 't_sa'; 't_ww'; 't_wa'};
            app.Table_Tsa.RowName = {''};
            app.Table_Tsa.ColumnEditable = true;
            app.ModelodetemperaturadelsueloyaguaLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodetemperaturadelsueloyaguaLabel.Text = 'Modelo de temperatura del suelo y agua';
            app.ParmetrosparalacorrecindetasaportemperaturaLabel = uilabel(app.Params_CalidadAgua);
            app.ParmetrosparalacorrecindetasaportemperaturaLabel.Text = 'Parámetros para la correción de tasa por temperatura';
            app.Table_The = uitable(app.Params_CalidadAgua);
            app.Table_The.ColumnName = {'th_cdbo'; 'th_bact'; 'th_N'; 'th_hidN'; 'th_rair'; 'th_hidP'; 'th_P'};
            app.Table_The.RowName = {''};
            app.Table_The.ColumnEditable = true;
            app.Table_ADZ = uitable(app.Params_CalidadAgua);
            app.Table_ADZ.ColumnName = {'K_turb'; 'exp_turb'};
            app.Table_ADZ.RowName = {''};
            app.Table_ADZ.ColumnEditable = true;
            app.ModeloADZQUASARLabel = uilabel(app.Params_CalidadAgua);
            app.ModeloADZQUASARLabel.Text = 'Modelo ADZ-QUASAR';
            app.Table_Tau = uitable(app.Params_CalidadAgua);
            app.Table_Tau.ColumnName = {'nTau'};
            app.Table_Tau.RowName = {''};
            app.Table_Tau.ColumnEditable = true;
            app.RezagostemporalesLabel = uilabel(app.Params_CalidadAgua);
            app.RezagostemporalesLabel.Text = 'Rezagos temporales';
            app.Table_pH = uitable(app.Params_CalidadAgua);
            app.Table_pH.ColumnName = {'pCO2'; 'roc'; 'fc_alk'};
            app.Table_pH.RowName = {};
            app.Table_pH.ColumnEditable = true;
            app.ModelodePhLabel = uilabel(app.Params_CalidadAgua);
            app.ModelodePhLabel.Text = 'Modelo de Ph';
            app.Button_Save_Params = uibutton(app.ParmetrosTab, 'state');
            app.Button_Save_Params.ValueChangedFcn = createCallbackFcn(app, @Button_Save_ParamsValueChanged, true);
            app.Button_Save_Params.Icon = 'Icons_SaveFile.png';
            app.Button_Save_Params.Text = 'Guardar archivo';
            app.MuestreadorTab = uitab(app.TabGroup3);
            app.MuestreadorTab.Title = 'Muestreador';
            app.NombredeconfiguracindeMuestreadorLabel = uilabel(app.MuestreadorTab);
            app.NombredeconfiguracindeMuestreadorLabel.Text = 'Nombre de configuración de Muestreador';
            app.Edit_NameSampler = uieditfield(app.MuestreadorTab, 'text');
            app.Edit_NameSampler.Editable = 'off';
            app.Edit_NameSampler.Value = 'NA';
            app.TabGroup5 = uitabgroup(app.MuestreadorTab);
            app.PuntosytramosdecontrolTab_2 = uitab(app.TabGroup5);
            app.PuntosytramosdecontrolTab_2.Title = 'Puntos y tramos de control';
            app.TabGroup6 = uitabgroup(app.PuntosytramosdecontrolTab_2);
            app.MuestreadorTab_3 = uitab(app.TabGroup6);
            app.MuestreadorTab_3.Title = 'Muestreador';
            app.MapsMuestreo = uiaxes(app.MuestreadorTab_3);
            xlabel(app.MapsMuestreo, 'Este (m)')
            ylabel(app.MapsMuestreo, 'Norte (m)')
            app.Clear_ControlPoints = uibutton(app.MuestreadorTab_3, 'state');
            app.Clear_ControlPoints.ValueChangedFcn = createCallbackFcn(app, @Clear_ControlPointsValueChanged, true);
            app.Clear_ControlPoints.Icon = 'icons_ClearPoints.png';
            app.Clear_ControlPoints.Text = 'Eliminar';
            app.Create_ControlBranch = uibutton(app.MuestreadorTab_3, 'state');
            app.Create_ControlBranch.ValueChangedFcn = createCallbackFcn(app, @Create_ControlBranchValueChanged, true);
            app.Create_ControlBranch.Icon = 'Icons_CreateBranch.png';
            app.Create_ControlBranch.Text = 'Crear';
            app.Label = uilabel(app.MuestreadorTab_3);
            app.Label.Text = {'1)	Para crear un punto de control debe dar clic sobre el '; '        Botón “Crear”. Luego sobre el mapa, seleccione la ubicación'; '        del punto de control.'; '2)	Para eliminar un punto de control, debe dar clic sobre el '; '        botón "Eliminar" y luego seleccionar el punto que desea '; '        eliminar.'; '3)	Mientras el botón “Crear o Eliminar” esté seleccionado'; '         usted podrá seguir creando o eliminando puntos de control'; ''};
            app.Label_2 = uilabel(app.MuestreadorTab_3);
            app.Label_2.Text = {'1)	Para crear un tramo de control debe dar clic sobre el '; '        Botón “Crear”. Luego sobre el mapa, seleccione la ubicación'; '        Del punto inicial del tramo de control y luego el punto final.'; '2)	Para eliminar un tramo de control, debe dar clic sobre el '; '        botón "Eliminar" y luego seleccionar el punto de inicio o final'; '        del tramo de control que desea eliminar.'; '3)	Mientras el botón “Crear o Eliminar” esté seleccionado'; '         usted podrá seguir creando o eliminando tramos de control'};
            app.PuntosdeControlLabel = uilabel(app.MuestreadorTab_3);
            app.PuntosdeControlLabel.Text = 'Puntos de Control';
            app.TramosdeControlLabel = uilabel(app.MuestreadorTab_3);
            app.TramosdeControlLabel.Text = 'Tramos de Control';
            app.Create_ControlPoints = uibutton(app.MuestreadorTab_3, 'state');
            app.Create_ControlPoints.ValueChangedFcn = createCallbackFcn(app, @Create_ControlPointsValueChanged, true);
            app.Create_ControlPoints.Icon = 'Icons_CreatePoints.jpg';
            app.Create_ControlPoints.Text = 'Crear';
            app.Clear_ControlBranch = uibutton(app.MuestreadorTab_3, 'state');
            app.Clear_ControlBranch.ValueChangedFcn = createCallbackFcn(app, @Clear_ControlBranchValueChanged, true);
            app.Clear_ControlBranch.Icon = 'Icons_ClearBranch.png';
            app.Clear_ControlBranch.Text = 'Eliminar';
            app.PuntodecontrolLampLabel = uilabel(app.MuestreadorTab_3);
            app.PuntodecontrolLampLabel.Text = 'Punto de control';
            app.PuntodecontrolLamp = uilamp(app.MuestreadorTab_3);
            app.PuntoinicialdetramodecontrolLampLabel = uilabel(app.MuestreadorTab_3);
            app.PuntoinicialdetramodecontrolLampLabel.Text = 'Punto inicial de tramo de control';
            app.PuntoinicialdetramodecontrolLamp = uilamp(app.MuestreadorTab_3);
            app.PuntofinaldetramodecontrolLampLabel = uilabel(app.MuestreadorTab_3);
            app.PuntofinaldetramodecontrolLampLabel.Text = 'Punto final de tramo de control';
            app.PuntofinaldetramodecontrolLamp = uilamp(app.MuestreadorTab_3);
            app.PuntosytramosdecontrolTab_3 = uitab(app.TabGroup6);
            app.PuntosytramosdecontrolTab_3.Title = 'Puntos y tramos de control';
            app.Table_ControlPoint = uitable(app.PuntosytramosdecontrolTab_3);
            app.Table_ControlPoint.ColumnName = {'X'; 'Y'; 'Nombre'; 'Estadístico'};
            app.Table_ControlPoint.RowName = {''};
            app.Table_ControlPoint.ColumnEditable = [false false true true];
            app.ListadodePuntosdeControlLabel = uilabel(app.PuntosytramosdecontrolTab_3);
            app.ListadodePuntosdeControlLabel.Text = 'Listado de Puntos de Control';
            app.Table_ControlBranch = uitable(app.PuntosytramosdecontrolTab_3);
            app.Table_ControlBranch.ColumnName = {'X1'; 'Y1'; 'X2'; 'Y2'; 'Nombre del Tramo'};
            app.Table_ControlBranch.RowName = {''};
            app.Table_ControlBranch.ColumnEditable = [false false false false true];
            app.ListadodeTramosdeControlLabel = uilabel(app.PuntosytramosdecontrolTab_3);
            app.ListadodeTramosdeControlLabel.Text = 'Listado de Tramos de Control';
            app.Button_Load_Points = uibutton(app.PuntosytramosdecontrolTab_3, 'state');
            app.Button_Load_Points.ValueChangedFcn = createCallbackFcn(app, @Button_Load_PointsValueChanged, true);
            app.Button_Load_Points.Icon = 'Icons_UploadFile.png';
            app.Button_Load_Points.Text = 'Cargar Puntos';
            app.Button_Load_Branch = uibutton(app.PuntosytramosdecontrolTab_3, 'state');
            app.Button_Load_Branch.ValueChangedFcn = createCallbackFcn(app, @Button_Load_BranchValueChanged, true);
            app.Button_Load_Branch.Icon = 'Icons_UploadFile.png';
            app.Button_Load_Branch.Text = 'Cargar Tramos';
            app.Info_LC_2 = uibutton(app.PuntosytramosdecontrolTab_3, 'state');
            app.Info_LC_2.ValueChangedFcn = createCallbackFcn(app, @Info_LC_2ValueChanged, true);
            app.Info_LC_2.Icon = 'Icons_Info.png';
            app.Info_LC_2.Text = '';
            app.Info_LC_3 = uibutton(app.PuntosytramosdecontrolTab_3, 'state');
            app.Info_LC_3.ValueChangedFcn = createCallbackFcn(app, @Info_LC_3ValueChanged, true);
            app.Info_LC_3.Icon = 'Icons_Info.png';
            app.Info_LC_3.Text = '';
            app.VariblesTab = uitab(app.TabGroup5);
            app.VariblesTab.Title = 'Varibles';
            app.Table_ControlPointsVar = uitable(app.VariblesTab);
            app.Table_ControlPointsVar.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ControlPointsVar.RowName = {''};
            app.Table_ControlPointsVar.ColumnEditable = true;
            app.ListadodeVariablesParaPuntosdeControlLabel = uilabel(app.VariblesTab);
            app.ListadodeVariablesParaPuntosdeControlLabel.Text = 'Listado de Variables Para Puntos de Control';
            app.Table_ControlBranchVar = uitable(app.VariblesTab);
            app.Table_ControlBranchVar.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ControlBranchVar.RowName = {''};
            app.Table_ControlBranchVar.ColumnEditable = true;
            app.ListadodeVariablesParaTramosdeControlLabel = uilabel(app.VariblesTab);
            app.ListadodeVariablesParaTramosdeControlLabel.Text = 'Listado de Variables Para Tramos de Control';
            app.Table_ListMapVar_Stream = uitable(app.VariblesTab);
            app.Table_ListMapVar_Stream.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ListMapVar_Stream.RowName = {''};
            app.Table_ListMapVar_Stream.ColumnEditable = true;
            app.ListadodeVariablesParaMapasSoloEnCauceLabel = uilabel(app.VariblesTab);
            app.ListadodeVariablesParaMapasSoloEnCauceLabel.Text = 'Listado de Variables Para Mapas Solo En Cauce';
            app.Table_ListMapVar_StreamMask = uitable(app.VariblesTab);
            app.Table_ListMapVar_StreamMask.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ListMapVar_StreamMask.RowName = {''};
            app.Table_ListMapVar_StreamMask.ColumnEditable = true;
            app.ListadodeVariablesParaEnmascararMapasEnCauceLabel = uilabel(app.VariblesTab);
            app.ListadodeVariablesParaEnmascararMapasEnCauceLabel.Text = 'Listado de Variables Para Enmascarar Mapas En Cauce';
            app.Table_ListMapVar = uitable(app.VariblesTab);
            app.Table_ListMapVar.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ListMapVar.RowName = {''};
            app.Table_ListMapVar.ColumnEditable = true;
            app.ListadodeVariablesParaMapasLabel = uilabel(app.VariblesTab);
            app.ListadodeVariablesParaMapasLabel.Text = 'Listado de Variables Para Mapas';
            app.FactoresTab = uitab(app.TabGroup5);
            app.FactoresTab.Title = 'Factores';
            app.Table_LabelVar = uitable(app.FactoresTab);
            app.Table_LabelVar.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_LabelVar.RowName = {''};
            app.EtiquetasdeVariablesLabel = uilabel(app.FactoresTab);
            app.EtiquetasdeVariablesLabel.Text = 'Etiquetas de Variables';
            app.Table_VarUnit = uitable(app.FactoresTab);
            app.Table_VarUnit.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_VarUnit.RowName = {''};
            app.Table_VarUnit.ColumnEditable = [true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false true true true true false true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false false false false false false false false false false false false false false false];
            app.UnidadesdeVariablesLabel = uilabel(app.FactoresTab);
            app.UnidadesdeVariablesLabel.Text = 'Unidades de Variables';
            app.Table_ConFactors = uitable(app.FactoresTab);
            app.Table_ConFactors.ColumnName = {'TA'; 'TAmin'; 'TAmax'; 'TAran'; 'TS'; 'TW'; 'HR'; 'RS'; 'PVer'; 'U'; 'Ux'; 'Uy'; 'LCL'; 'LWC'; 'EV'; 'ETo'; 'SMI'; 'HU'; 'LAI'; 'psi'; 'theta'; 'ks'; 'n2v'; 'n2'; 'C_usle'; 'ETP'; 'PHor'; 'DTRjo'; 'LAIjo'; 'LAIopt'; 'S0'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'E0'; 'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'I0'; 'I1'; 'I2'; 'I3'; 'I4'; 'O4'; 'U2'; 'U3'; 'U4'; 'U5'; 'AF3'; 'Q2'; 'Q5'; 'Q5a'; 'Q5c'; 'Q5d'; 'Q5p'; 'H2'; 'H5'; 'H5b'; 'W5'; 'W5b'; 'P5'; 'A5'; 'R5'; 'D5'; 'n5l'; 'n5b'; 'n5'; 'arcS2S'; 'limS2S'; 'areS2S'; 'arcS2D'; 'limS2D'; 'areS2D'; 'arcS5S'; 'limS5S'; 'areS5S'; 'arcS5D'; 'limS5D'; 'areS5D'; 'arcE2S'; 'limE2S'; 'areE2S'; 'arcE2D'; 'limE2D'; 'areE2D'; 'arcE2E'; 'limE2E'; 'areE2E'; 'arcE5S'; 'limE5S'; 'areE5S'; 'arcE5D'; 'limE5D'; 'areE5D'; 'arcE5E'; 'limE5E'; 'areE5E'; 'E2Smax'; 'E2Dmax'; 'arcE5Smax'; 'limE5Smax'; 'areE5Smax'; 'arcI2D'; 'limI2D'; 'areI2D'; 'arcI5D'; 'limI5D'; 'areI5D'; 'ER5b'; 'PRx'; 'Lx'; 'Fx'; 'EntSed'; 'SalSed'; 'EST'; 'EDT'; 'E2ET'; 'E5ET'; 'EET'; 'ESDET'; 'Zw'; 'arcDV'; 'limDV'; 'areDV'; 'PFB'; 'S0EC'; 'S1ECp'; 'S1ECs'; 'S2EC'; 'S3EC'; 'S4EC'; 'S0NO'; 'S1NOp'; 'S1NOs'; 'S2NO'; 'S3NO'; 'S4NO'; 'S0NH4'; 'S1NH4p'; 'S1NH4s'; 'S2NH4'; 'S3NH4'; 'S4NH4'; 'S0NO3'; 'S1NO3p'; 'S1NO3s'; 'S2NO3'; 'S3NO3'; 'S4NO3'; 'S0PO'; 'S1POp'; 'S1POs'; 'S2PO'; 'S3PO'; 'S4PO'; 'S0PI'; 'S1PIp'; 'S1PIs'; 'S2PI'; 'S3PI'; 'S4PI'; 'S0PO_fb'; 'S1POp_fb'; 'S1POs_fb'; 'S2PO_fb'; 'S3PO_fb'; 'S0PI_fb'; 'S1PIp_fb'; 'S1PIs_fb'; 'S2PI_fb'; 'S3PI_fb'; 'QEC'; 'QNO3'; 'QNH4'; 'QNO'; 'QPO'; 'QPI'; 'I1EC'; 'I2EC'; 'I3EC'; 'I4EC'; 'E2EC'; 'E3EC'; 'S0NO_miner'; 'S1NOp_miner'; 'S1NOs_miner'; 'S2NO_miner'; 'S3NO_miner'; 'I1NO'; 'I2NO'; 'I3NO'; 'I4NO'; 'E2NO'; 'E3NO'; 'E4NO'; 'I1NO3'; 'I2NO3'; 'I3NO3'; 'I4NO3'; 'E2NO3'; 'E3NO3'; 'E4NO3'; 'S0NH4_nitri'; 'S1NH4p_nitri'; 'S1NH4s_nitri'; 'S2NH4_nitri'; 'S3NH4_nitri'; 'I1NH4'; 'I2NH4'; 'I3NH4'; 'I4NH4'; 'E2NH4'; 'E3NH4'; 'E4NH4'; 'I1PO'; 'I2PO'; 'I3PO'; 'I4PO'; 'E2PO'; 'E3PO'; 'E4PO'; 'I1PI'; 'I2PI'; 'I3PI'; 'I4PI'; 'E2PI'; 'E3PI'; 'E4PI'; 'OD'; 'ODs'; 'CDBO'; 'CDBO5'; 'CE'; 'EC'; 'CT'; 'SST'; 'TUR'; 'NO3'; 'NH4'; 'NO'; 'PO'; 'PI'; 'PT'; 'pH'; 'alk'; 'CA'};
            app.Table_ConFactors.RowName = {''};
            app.Table_ConFactors.ColumnEditable = [true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false true true true true false true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true true false false false false false false false false false false false false false false false];
            app.FactoresdeConversindelaUnidadLabel = uilabel(app.FactoresTab);
            app.FactoresdeConversindelaUnidadLabel.Text = 'Factores de Conversión de la Unidad';
            app.Button_CreateSampler = uibutton(app.MuestreadorTab, 'state');
            app.Button_CreateSampler.ValueChangedFcn = createCallbackFcn(app, @Button_CreateSamplerValueChanged, true);
            app.Button_CreateSampler.Icon = 'Icons_SaveFile.png';
            app.Button_CreateSampler.Text = 'Guardar archivo';
            app.CiclodeCargasTab = uitab(app.TabGroup3);
            app.CiclodeCargasTab.Title = 'Ciclo de Cargas';
            app.PlotAx_LoadCycle = uiaxes(app.CiclodeCargasTab);
            title(app.PlotAx_LoadCycle, 'Ciclo')
            xlabel(app.PlotAx_LoadCycle, 'Días')
            ylabel(app.PlotAx_LoadCycle, 'Valor')
            app.PlotAx_LoadCycle.ALim = [0 1];
            app.Table_LoadCycle = uitable(app.CiclodeCargasTab);
            app.Table_LoadCycle.ColumnName = {'Día'; 'Ciclo 1'};
            app.Table_LoadCycle.RowName = {''};
            app.Table_LoadCycle.ColumnEditable = [false true];
            app.NmerodeCiclosaconfigurarLabel = uilabel(app.CiclodeCargasTab);
            app.NmerodeCiclosaconfigurarLabel.Text = 'Número de Ciclos a configurar';
            app.N_Cycle = uieditfield(app.CiclodeCargasTab, 'text');
            app.N_Cycle.ValueChangedFcn = createCallbackFcn(app, @N_CycleValueChanged, true);
            app.N_Cycle.Value = '1';
            app.SeleccionecicloparagrficarLabel = uilabel(app.CiclodeCargasTab);
            app.SeleccionecicloparagrficarLabel.Text = 'Seleccione ciclo para gráficar';
            app.SelectCycle = uidropdown(app.CiclodeCargasTab);
            app.SelectCycle.Items = {'Todos'};
            app.SelectCycle.ValueChangedFcn = createCallbackFcn(app, @SelectCycleValueChanged, true);
            app.SelectCycle.Value = 'Todos';
            app.Button_CreateLoadCycle = uibutton(app.CiclodeCargasTab, 'state');
            app.Button_CreateLoadCycle.ValueChangedFcn = createCallbackFcn(app, @Button_CreateLoadCycleValueChanged, true);
            app.Button_CreateLoadCycle.Icon = 'Icons_SaveFile.png';
            app.Button_CreateLoadCycle.Text = 'Guardar archivo';
            app.NombredeconfiguracindeCiclosLabel = uilabel(app.CiclodeCargasTab);
            app.NombredeconfiguracindeCiclosLabel.Text = 'Nombre de configuración de Ciclos';
            app.Edit_NameLoadCycle = uieditfield(app.CiclodeCargasTab, 'text');
            app.Edit_NameLoadCycle.Editable = 'off';
            app.Edit_NameLoadCycle.Value = 'NA';
            app.Panel = uipanel(app.CiclodeCargasTab);
            app.Button_Load_LC = uibutton(app.Panel, 'state');
            app.Button_Load_LC.ValueChangedFcn = createCallbackFcn(app, @Button_Load_LCValueChanged, true);
            app.Button_Load_LC.Icon = 'Icons_UploadFile.png';
            app.Button_Load_LC.Text = 'Cargar datos';
            app.Info_LC = uibutton(app.Panel, 'state');
            app.Info_LC.ValueChangedFcn = createCallbackFcn(app, @Info_LCValueChanged, true);
            app.Info_LC.Icon = 'Icons_Info.png';
            app.Info_LC.Text = '';
            app.SeriesTab = uitab(app.TabGroup3);
            app.SeriesTab.Title = 'Series';
            app.Plot_TimeSeries = uiaxes(app.SeriesTab);
            title(app.Plot_TimeSeries, 'Title')
            xlabel(app.Plot_TimeSeries, 'X')
            ylabel(app.Plot_TimeSeries, 'Y')
            app.MapSeries = uiaxes(app.SeriesTab);
            title(app.MapSeries, 'Title')
            xlabel(app.MapSeries, 'Este (m)')
            ylabel(app.MapSeries, 'Norte (m)')
            app.VariableDropDownLabel_2 = uilabel(app.SeriesTab);
            app.VariableDropDownLabel_2.Text = 'Variable';
            app.NameTimeSeries = uidropdown(app.SeriesTab);
            app.NameTimeSeries.Items = {'None'};
            app.NameTimeSeries.ValueChangedFcn = createCallbackFcn(app, @NameTimeSeriesValueChanged, true);
            app.NameTimeSeries.Value = 'None';
            app.Table_Series = uitable(app.SeriesTab);
            app.Table_Series.ColumnName = {'Fecha'; 'Dato'};
            app.Table_Series.RowName = {};
            app.Table_Series.ColumnEditable = true;
            app.Button_Load_TS = uibutton(app.SeriesTab, 'state');
            app.Button_Load_TS.ValueChangedFcn = createCallbackFcn(app, @Button_Load_TSValueChanged, true);
            app.Button_Load_TS.Icon = 'Icons_UploadFile.png';
            app.Button_Load_TS.Text = 'Cargar datos';
            app.Info_TS = uibutton(app.SeriesTab, 'state');
            app.Info_TS.ValueChangedFcn = createCallbackFcn(app, @Info_TSValueChanged, true);
            app.Info_TS.Icon = 'Icons_Info.png';
            app.Info_TS.Text = '';
            app.MovimientosenMasaTab = uitab(app.TabGroup3);
            app.MovimientosenMasaTab.Title = 'Movimientos en Masa';
            app.MapsMM = uiaxes(app.MovimientosenMasaTab);
            title(app.MapsMM, 'Title')
            xlabel(app.MapsMM, 'Este (m)')
            ylabel(app.MapsMM, 'Norte (m)')
            app.Table_MM = uitable(app.MovimientosenMasaTab);
            app.Table_MM.ColumnName = {'Año'; 'Mes'; 'Día'; 'X'; 'Y'; 'Area'};
            app.Table_MM.RowName = {};
            app.Table_MM.ColumnEditable = true;
            app.NmerodemovimientosenmasaLabel = uilabel(app.MovimientosenMasaTab);
            app.NmerodemovimientosenmasaLabel.Text = 'Número de movimientos en masa';
            app.N_MM = uieditfield(app.MovimientosenMasaTab, 'text');
            app.N_MM.ValueChangedFcn = createCallbackFcn(app, @N_MMValueChanged, true);
            app.N_MM.Value = '1';
            app.Button_Plot_MM = uibutton(app.MovimientosenMasaTab, 'state');
            app.Button_Plot_MM.ValueChangedFcn = createCallbackFcn(app, @Button_Plot_MMValueChanged, true);
            app.Button_Plot_MM.Icon = 'Icons_MM.png';
            app.Button_Plot_MM.Text = 'Graficar sitios de movimientos en masa';
            app.Button_CreateMM = uibutton(app.MovimientosenMasaTab, 'state');
            app.Button_CreateMM.ValueChangedFcn = createCallbackFcn(app, @Button_CreateMMValueChanged, true);
            app.Button_CreateMM.Icon = 'Icons_SaveFile.png';
            app.Button_CreateMM.Text = 'Guardar archivo';
            app.NombredeconfiguracindemovimientosenmasaLabel = uilabel(app.MovimientosenMasaTab);
            app.NombredeconfiguracindemovimientosenmasaLabel.Text = 'Nombre de configuración de movimientos en masa';
            app.Edit_NameMM = uieditfield(app.MovimientosenMasaTab, 'text');
            app.Edit_NameMM.Editable = 'off';
            app.Edit_NameMM.Value = 'NA';
            app.Button_Load_MM = uibutton(app.MovimientosenMasaTab, 'state');
            app.Button_Load_MM.ValueChangedFcn = createCallbackFcn(app, @Button_Load_MMValueChanged, true);
            app.Button_Load_MM.Icon = 'Icons_UploadFile.png';
            app.Button_Load_MM.Text = 'Cargar datos';
            app.Info_MM = uibutton(app.MovimientosenMasaTab, 'state');
            app.Info_MM.ValueChangedFcn = createCallbackFcn(app, @Info_MMValueChanged, true);
            app.Info_MM.Icon = 'Icons_Info.png';
            app.Info_MM.Text = '';
            app.MovimientosenmasaLampLabel = uilabel(app.MovimientosenMasaTab);
            app.MovimientosenmasaLampLabel.Text = 'Movimientos en masa';
            app.MM_Lamp = uilamp(app.MovimientosenMasaTab);
            app.VertimientosTab = uitab(app.TabGroup3);
            app.VertimientosTab.Title = 'Vertimientos';
            app.MapsDumping = uiaxes(app.VertimientosTab);
            title(app.MapsDumping, 'Data')
            xlabel(app.MapsDumping, 'Este (m)')
            ylabel(app.MapsDumping, 'Norte (m)')
            app.Table_Dumping = uitable(app.VertimientosTab);
            app.Table_Dumping.ColumnName = {'Nombre'; 'X'; 'Y'; 'Q'; 'T'; 'SST'; 'NO3'; 'NH4'; 'NO'; 'CT'; 'EC'; 'pH'; 'alk'; 'OD'; 'CDBO5'; 'CE'; 'PO'; 'PI'};
            app.Table_Dumping.RowName = {};
            app.Table_Dumping.ColumnEditable = true;
            app.NmerodevertimientosLabel = uilabel(app.VertimientosTab);
            app.NmerodevertimientosLabel.Text = 'Número de vertimientos';
            app.NDumping = uieditfield(app.VertimientosTab, 'text');
            app.NDumping.ValueChangedFcn = createCallbackFcn(app, @NDumpingValueChanged, true);
            app.NDumping.Value = '1';
            app.Button_Plot_Dumping = uibutton(app.VertimientosTab, 'state');
            app.Button_Plot_Dumping.ValueChangedFcn = createCallbackFcn(app, @Button_Plot_DumpingValueChanged, true);
            app.Button_Plot_Dumping.Icon = 'Icons_Dumping.png';
            app.Button_Plot_Dumping.Text = 'Graficar sitios de vertimientos';
            app.Button_CreateDumping = uibutton(app.VertimientosTab, 'state');
            app.Button_CreateDumping.ValueChangedFcn = createCallbackFcn(app, @Button_CreateDumpingValueChanged, true);
            app.Button_CreateDumping.Icon = 'Icons_SaveFile.png';
            app.Button_CreateDumping.Text = 'Guardar archivo';
            app.NombredeconfiguracindevertimientosLabel = uilabel(app.VertimientosTab);
            app.NombredeconfiguracindevertimientosLabel.Text = 'Nombre de configuración de vertimientos';
            app.Edit_NameDumping = uieditfield(app.VertimientosTab, 'text');
            app.Edit_NameDumping.Editable = 'off';
            app.Edit_NameDumping.Value = 'NA';
            app.Button_Load_Dumping = uibutton(app.VertimientosTab, 'state');
            app.Button_Load_Dumping.ValueChangedFcn = createCallbackFcn(app, @Button_Load_DumpingValueChanged, true);
            app.Button_Load_Dumping.Icon = 'Icons_UploadFile.png';
            app.Button_Load_Dumping.Text = 'Cargar datos';
            app.Info_Dumping = uibutton(app.VertimientosTab, 'state');
            app.Info_Dumping.ValueChangedFcn = createCallbackFcn(app, @Info_DumpingValueChanged, true);
            app.Info_Dumping.Icon = 'Icons_Info.png';
            app.Info_Dumping.Text = '';
            app.VertimientosLampLabel = uilabel(app.VertimientosTab);
            app.VertimientosLampLabel.Text = 'Vertimientos';
            app.Dumping_Lamp = uilamp(app.VertimientosTab);
            app.Logo_TNC = uiimage(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.Logo_TNC.ImageSource = 'Logo_TNC.png';
            app.Logo_N4W = uiimage(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.Logo_N4W.ImageSource = 'Logo_N4W-Facility.png';
            app.Logo_Gotta = uiimage(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.Logo_Gotta.ImageSource = 'Icons_Gotta.jpg';
            app.Logo_Pegasys = uiimage(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.Logo_Pegasys.ImageSource = 'Logo_Pegasys.png';
            app.Logo_CuencaVerde = uiimage(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel);
            app.Logo_CuencaVerde.ImageSource = 'Logo_CuencaVerde.png';
            app.Button_Save = uibutton(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel, 'state');
            app.Button_Save.ValueChangedFcn = createCallbackFcn(app, @Button_SaveValueChanged, true);
            app.Button_Save.Icon = 'Icons_Save.png';
            app.Button_Save.Text = 'Guardar';
            app.Button_Cancel = uibutton(app.ConfiguracindelmodeloSIGACALCuencaVerdePanel, 'state');
            app.Button_Cancel.ValueChangedFcn = createCallbackFcn(app, @Button_CancelValueChanged, true);
            app.Button_Cancel.Icon = 'Icons_Cancel.png';
            app.Button_Cancel.Text = 'Cancelar';
            % Show the figure after all components are created
            app.Configuracion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_02_Configuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Configuracion)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Configuracion)
        end
    end
end