classdef App_03_Plot_TimeSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Plot_Escenarios           matlab.ui.Figure
        Figure                    matlab.ui.control.UIAxes
        Button_Plot               matlab.ui.control.StateButton
        Button_Clear              matlab.ui.control.StateButton
        Logo_CuencaVerde_2        matlab.ui.control.Image
        Logo_Gotta_2              matlab.ui.control.Image
        Logo_TNC_2                matlab.ui.control.Image
        Logo_Pegasys_2            matlab.ui.control.Image
        Logo_N4W_2                matlab.ui.control.Image
        Panel                     matlab.ui.container.Panel
        Switch_Aggregation        matlab.ui.control.Switch
        Panel_3                   matlab.ui.container.Panel
        List_D_Resolution         matlab.ui.control.DropDown
        ResolucinLabel_2          matlab.ui.control.Label
        Panel_4                   matlab.ui.container.Panel
        Text_Resolution           matlab.ui.control.EditField
        PasodetiempoLabel         matlab.ui.control.Label
        Panel_5                   matlab.ui.container.Panel
        EstadsticaagregacinLabel  matlab.ui.control.Label
        List_D_NameFun            matlab.ui.control.DropDown
        Panel_6                   matlab.ui.container.Panel
        EscenarioDropDownLabel    matlab.ui.control.Label
        List_Sce                  matlab.ui.control.DropDown
        EjecucinLabel             matlab.ui.control.Label
        List_Exe                  matlab.ui.control.DropDown
        SitioLabel                matlab.ui.control.Label
        List_Place                matlab.ui.control.DropDown
        VariableLabel             matlab.ui.control.Label
        List_Var                  matlab.ui.control.DropDown
        Panel_7                   matlab.ui.container.Panel
        TipodegrficaLabel         matlab.ui.control.Label
        List_D_TypePlot           matlab.ui.control.DropDown
        Info_MM                   matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        NameLegend
        ln
        Data
        ProjectPath
        StatusPlot = '';
        NameVar_i
    end       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ProjectPath, SceFileName)
            app.ProjectPath = ProjectPath;
            %NameLegend ln
            
            % Leer carpetas de Scenarios
            d           = dir(fullfile(app.ProjectPath,'salidas'));
            dfolders    = d([d(:).isdir]);
            dfolders    = dfolders(~ismember({dfolders(:).name},{'.','..'}));
            Tmp         = {'None',dfolders.name}';
            app.List_Sce.Items = Tmp;
            app.List_Sce.Value = Tmp{1};
            
            app.NameLegend = {};
            app.ln = 0;
            
            % Figure
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')
                 
            
        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
            % Deshabilitar botón de plot
            app.Button_Plot.Value = 0;
            
            NameVar     = app.List_Var.Value; 
            if strcmp(NameVar,'None')
                f = msgbox('No ha seleccionado una variable valida');    
                set(f, 'color',[1 1 1])  
                return
            end
            
            % Borrar gráfico si son variables diferentes
            if ~strcmp(app.NameVar_i, NameVar) && ~isempty(app.NameVar_i)
                app.Button_ClearValueChanged;                
            end
            app.NameVar_i = NameVar;
            
            %% Leer datos
            Data        = app.Data;            
            NameLegend  = app.NameLegend;
            ln          = app.ln;
            
            %% Leer Parametros
            TypePlot    = app.List_D_TypePlot.Value;
            CheckAgg    = app.Switch_Aggregation.Value;
            Step        = str2double(app.Text_Resolution.Value);
            NameFun     = app.List_D_NameFun.Value;                      
            Resolution  = app.List_D_Resolution.Value;
            
            NameSce = app.List_Sce.Value;
            NameExe = app.List_Exe.Value;
            NamePlace = app.List_Place.Value;
            
            ln = ln + 1;
            NameLegend{1,ln} = [NameSce,'|',NameExe,'|',NamePlace];            
            app.NameLegend = NameLegend;
            app.ln = ln;
            
            %% Agregación
            if strcmp(CheckAgg,'R')
                if strcmp(Resolution,"Diaria")
                    Results = Data; 
                elseif strcmp(Resolution,"Mensual")
                    Results = Aggregation_TimeSeries(Data, Resolution, NameFun);
                elseif strcmp(Resolution,"Anual")
                    Results = Aggregation_TimeSeries(Data, Resolution, NameFun);
                end
                
            else     
                Results = Aggregation_TimeSeries(Data, Step, NameFun);
            end
            
            %% Buscar Variable
            ii = find(strcmp(Data.NameVar, NameVar));                

            %% Plot
            if strcmp(TypePlot,'Serie de Tiempo')
                if strcmp(app.StatusPlot,'Serie de Tiempo')
                    hold(app.Figure,'on')   
                else                    
                    reset(app.Figure)
                    cla(app.Figure,'reset');
                    app.Figure.BackgroundColor = [1 1 1];
                    hold(app.Figure,'off')
%                     
%                     app.NameLegend = {};
%                     app.ln = 0;
                end
            
                
                %% Plot    
                plot(app.Figure,Results.Date,Results.Data(:,ii),'Linewidth',1.3)
                xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)   
                title(app.Figure,'Serie de tiempo', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)    
                
                Leg = legend(app.Figure,NameLegend);
                set(Leg,'Fontsize',8)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Serie de Tiempo';
                
            elseif strcmp(TypePlot,'Curva de Duración')                                
                if strcmp(app.StatusPlot,'Curva de Duración')
                    hold(app.Figure,'on')   
                else                    
                    reset(app.Figure)
                    cla(app.Figure,'reset');
                    app.Figure.BackgroundColor = [1 1 1];
                    hold(app.Figure,'off')                                        
                end
                
                Q               = Results.Data(:,ii); 
                [Por_Q,Qd]      = hist(Q,length(unique(Q)));
                [Qsort, id ]    = sort(Qd, 'descend');
                PQ              = (cumsum(Por_Q(id))/sum(Por_Q(id)))*100;
                [~, id]         = unique(PQ);
                
                Porcentaje      = [10 50 75 85 95];
                DataQ           = interp1(PQ(id), Qsort(id), Porcentaje);
                
                hold(app.Figure,'on')
                
                plot(app.Figure, PQ, Qsort,'Linewidth',1.3);
                hold(app.Figure,'on')
                
                xlabel(app.Figure,['Porcentaje del tiempo en el que ','${',NameVar,'}$',' es excedido (\%)'], 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Curva de duraci\''on', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                Leg = legend(app.Figure,NameLegend);
                set(Leg,'Fontsize',8)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Curva de Duración';
                
            elseif strcmp(TypePlot,'Histograma')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];
                hold(app.Figure,'off') 
                
                histogram(app.Figure,Results.Data(:,ii),30)
                
                xlabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,'Frecuaenc\''ia', 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Histograma', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Histograma';
                
            elseif strcmp(TypePlot,'Boxplot')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];                
                hold(app.Figure,'off') 
                
                boxplot(app.Figure,Results.Data(:,ii))
                
                xlabel(app.Figure,'Boxplot', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Boxplot', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Boxplot';
                
            elseif strcmp(TypePlot,'Curva Acumulada')
                reset(app.Figure)
                cla(app.Figure,'reset');
                app.Figure.BackgroundColor = [1 1 1];
                hold(app.Figure,'off') 
                
                Pcm     = cumsum(Results.Data(:,ii), 'omitnan');
                
                % Fit Lineal Model
                Datec   = datenum(Results.Date);
                Params  = polyfit(Datec,Pcm,1);
                Psim    = polyval(Params,Datec);
                
                plot(app.Figure,Results.Date, Pcm,'Linewidth',2)%[0.84 0.23 0.24]
                hold(app.Figure,'on')
                plot(app.Figure,Results.Date,Psim,'--','Linewidth',1, 'color','black')          
                ylim(app.Figure,[min(Pcm) max(Pcm)])
                
                xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['$Accum - {',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,'Curva Acumulada', 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                
                % Controla el reinicio de las graficas
                app.StatusPlot = 'Curva Acumulada';
                
            end
            
        end

        % Value changed function: List_Sce
        function List_SceValueChanged(app, event)
           
            value = app.List_Sce.Value;
            
            if ~strcmp(value,'None')                                
                % Listar ejecuciones
                d = dir(fullfile(app.ProjectPath,'salidas',value));                    
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                Tmp      = {'None',dfolders.name}';
                
                % Asignar valores de lista                
                app.List_Exe.Items  = Tmp;
                app.List_Exe.Value  = Tmp{1};                                
                
                % Habilitar listas
                app.List_Exe.Enable = true;
            else
                % Habilitar
                app.List_Exe.Value              = 'None';
                app.List_Exe.Enable             = false;
                app.List_Place.Value            = 'None';
                app.List_Place.Enable           = false;
                app.List_Var.Value              = 'None';
                app.List_Var.Enable             = false;                                
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
                app.Button_Plot.Enable          = false;
                app.Button_Clear.Enable         = false;
                app.Switch_Aggregation.Enable   = false;
            end
                  
            app.List_ExeValueChanged;
        end

        % Value changed function: List_Exe
        function List_ExeValueChanged(app, event)
           
            value = app.List_Exe.Value;
            
            if ~strcmp(value,'None')
                % Listar Series
                NameSce = app.List_Sce.Value;
                NameFiles = dir(fullfile(app.ProjectPath,'salidas',NameSce, value,'series','*.txt'));
                NameFiles = {'None',NameFiles.name}';
                
                % Asignar valores de lista  
                app.List_Place.Items = NameFiles;
                app.List_Place.Value = NameFiles{1};
                
                % Habilitar
                app.List_Place.Enable = true;
                
            else
                % Desabilitar                
                app.List_Place.Value            = 'None';
                app.List_Place.Enable           = false;
                app.List_Var.Value              = 'None';
                app.List_Var.Enable             = false;                
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.Text_Resolution.Enable      = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end            
            app.List_PlaceValueChanged;
        end

        % Value changed function: List_Place
        function List_PlaceValueChanged(app, event)

            value = app.List_Place.Value;
            
            if ~strcmp(value,'None')
                % leer variables de las salidas
                NameSce = app.List_Sce.Value;
                NameExe = app.List_Exe.Value;
                PathFile = fullfile(app.ProjectPath,'salidas',NameSce, NameExe,'series',value);
                
                Data = Read_TimeSeries_Output(PathFile);
                app.Data = Data;                
                
                % Asignar valores de lista  
                app.List_Var.Items = {'None',Data.NameVar{:}}';
                app.List_Var.Value = 'None';
                
                % Habilitar
                app.List_Var.Enable = true;
            else
                app.List_Var.Value              = 'None';
                app.List_Var.Enable             = false;
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.Text_Resolution.Enable      = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
            app.List_VarValueChanged;
        end

        % Value changed function: List_Var
        function List_VarValueChanged(app, event)
            value = app.List_Var.Value;
            
            if ~strcmp(value,'None')
                app.Button_Clear.Enable         = true;
                app.Button_Plot.Enable          = true;
                app.Switch_Aggregation.Enable   = true;
                if strcmp(app.Switch_Aggregation.Value,'R')
                    app.List_D_Resolution.Enable    = true;
                else
                    app.Text_Resolution.Enable    = true;
                end                
                app.List_D_TypePlot.Enable      = true;                
            else
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.Text_Resolution.Enable      = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
            end
            
        end

        % Value changed function: Switch_Aggregation
        function Switch_AggregationValueChanged(app, event)
            value = app.Switch_Aggregation.Value;
            
            if strcmp(value,'R')
                app.Text_Resolution.Enable      = 0;
                app.List_D_Resolution.Enable    = 1;
                app.List_D_NameFun.Enable       = 0;
                app.List_D_Resolution.Value     = 'Diaria';
            else
                app.Text_Resolution.Enable      = 1;                
                app.List_D_Resolution.Enable    = 0;
                app.List_D_NameFun.Enable       = 1;
            end
        end

        % Value changed function: Button_Clear
        function Button_ClearValueChanged(app, event)
            app.Button_Clear.Value = 0;
            
            reset(app.Figure) 
            cla(app.Figure,'reset');
            app.Figure.BackgroundColor = [1 1 1];
                                   
            app.StatusPlot = 'Clear';
            app.NameLegend = {};
            app.ln = 0;
            
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')
            set(app.Figure,'box','on')
        end

        % Value changed function: List_D_Resolution
        function List_D_ResolutionValueChanged(app, event)
            value = app.List_D_Resolution.Value;
            
            if ~strcmp(value,'Diaria')
                app.List_D_NameFun.Enable = true;
            else
                app.List_D_NameFun.Enable = false;
            end
        end

        % Value changed function: List_D_TypePlot
        function List_D_TypePlotValueChanged(app, event)
            value = app.List_D_TypePlot.Value;
            
        end

        % Value changed function: Text_Resolution
        function Text_ResolutionValueChanged(app, event)
            value  = str2double(app.Text_Resolution.Value);            
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end
            
            if isnan(value)
                f = errordlg('Ingrese un valor numérico');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end            
            
            if value < 2
                f = errordlg('El valor debe ser mayor que "1".');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end
            
            N_Data = app.Data.Fil;
            if value > (N_Data - 1)
                f = errordlg(['El valor máximo de agregación permitido es: ',num2str(N_Data-1,'%d')]);
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = num2str(N_Data-1,'%d');
                return
            end
            
            app.Text_Resolution.Value = num2str(round(value,0));
        end

        % Value changed function: Info_MM
        function Info_MMValueChanged(app, event)
            app.Info_MM.Value = 0;
            
            dos('explorer https://tnc.box.com/s/r8smn41pd3fxbwc3jku26v8ysd5tidxn')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Plot_Escenarios = uifigure('Visible', 'off');
            app.Plot_Escenarios.Name = 'UI Figure';
            app.Figure = uiaxes(app.Plot_Escenarios);
            title(app.Figure, 'Title')
            xlabel(app.Figure, 'X')
            ylabel(app.Figure, 'Y')
            app.Figure.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Button_Plot = uibutton(app.Plot_Escenarios, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Icon = 'Icons_TimeSeries_2.png';
            app.Button_Plot.Text = 'Graficar';
            app.Button_Clear = uibutton(app.Plot_Escenarios, 'state');
            app.Button_Clear.ValueChangedFcn = createCallbackFcn(app, @Button_ClearValueChanged, true);
            app.Button_Clear.Enable = 'off';
            app.Button_Clear.Icon = 'Icons_Clear.png';
            app.Button_Clear.Text = 'limpiar';
            app.Logo_CuencaVerde_2 = uiimage(app.Plot_Escenarios);
            app.Logo_CuencaVerde_2.ImageSource = 'Logo_CuencaVerde.png';
            app.Logo_Gotta_2 = uiimage(app.Plot_Escenarios);
            app.Logo_Gotta_2.ImageSource = 'Icons_Gotta.jpg';
            app.Logo_TNC_2 = uiimage(app.Plot_Escenarios);
            app.Logo_TNC_2.ImageSource = 'Logo_TNC.png';
            app.Logo_Pegasys_2 = uiimage(app.Plot_Escenarios);
            app.Logo_Pegasys_2.ImageSource = 'Logo_Pegasys.png';
            app.Logo_N4W_2 = uiimage(app.Plot_Escenarios);
            app.Logo_N4W_2.ImageSource = 'Logo_N4W-Facility.png';
            app.Panel = uipanel(app.Plot_Escenarios);
            app.Switch_Aggregation = uiswitch(app.Panel, 'slider');
            app.Switch_Aggregation.Items = {'PT', 'R'};
            app.Switch_Aggregation.ValueChangedFcn = createCallbackFcn(app, @Switch_AggregationValueChanged, true);
            app.Switch_Aggregation.Enable = 'off';
            app.Switch_Aggregation.Value = 'R';
            app.Panel_3 = uipanel(app.Panel);
            app.List_D_Resolution = uidropdown(app.Panel_3);
            app.List_D_Resolution.Items = {'Diaria', 'Mensual', 'Anual'};
            app.List_D_Resolution.Editable = 'on';
            app.List_D_Resolution.ValueChangedFcn = createCallbackFcn(app, @List_D_ResolutionValueChanged, true);
            app.List_D_Resolution.Enable = 'off';
            app.List_D_Resolution.Value = 'Diaria';
            app.ResolucinLabel_2 = uilabel(app.Panel_3);
            app.ResolucinLabel_2.Text = 'Resolución';
            app.Panel_4 = uipanel(app.Panel);
            app.Text_Resolution = uieditfield(app.Panel_4, 'text');
            app.Text_Resolution.ValueChangedFcn = createCallbackFcn(app, @Text_ResolutionValueChanged, true);
            app.Text_Resolution.Enable = 'off';
            app.Text_Resolution.Value = '2';
            app.PasodetiempoLabel = uilabel(app.Panel_4);
            app.PasodetiempoLabel.Text = {'Paso de '; 'tiempo'};
            app.Panel_5 = uipanel(app.Panel);
            app.EstadsticaagregacinLabel = uilabel(app.Panel_5);
            app.EstadsticaagregacinLabel.Text = {'Estadística'; 'agregación'};
            app.List_D_NameFun = uidropdown(app.Panel_5);
            app.List_D_NameFun.Items = {'Promedio', 'Máximo', 'Mínimo', 'Suma', ''};
            app.List_D_NameFun.Enable = 'off';
            app.List_D_NameFun.Value = 'Promedio';
            app.Panel_6 = uipanel(app.Plot_Escenarios);
            app.EscenarioDropDownLabel = uilabel(app.Panel_6);
            app.EscenarioDropDownLabel.Text = 'Escenario';
            app.List_Sce = uidropdown(app.Panel_6);
            app.List_Sce.Items = {'None'};
            app.List_Sce.ValueChangedFcn = createCallbackFcn(app, @List_SceValueChanged, true);
            app.List_Sce.Value = 'None';
            app.EjecucinLabel = uilabel(app.Panel_6);
            app.EjecucinLabel.Text = 'Ejecución';
            app.List_Exe = uidropdown(app.Panel_6);
            app.List_Exe.Items = {'None'};
            app.List_Exe.ValueChangedFcn = createCallbackFcn(app, @List_ExeValueChanged, true);
            app.List_Exe.Enable = 'off';
            app.List_Exe.Value = 'None';
            app.SitioLabel = uilabel(app.Panel_6);
            app.SitioLabel.Text = 'Sitio';
            app.List_Place = uidropdown(app.Panel_6);
            app.List_Place.Items = {'None'};
            app.List_Place.ValueChangedFcn = createCallbackFcn(app, @List_PlaceValueChanged, true);
            app.List_Place.Enable = 'off';
            app.List_Place.Value = 'None';
            app.VariableLabel = uilabel(app.Panel_6);
            app.VariableLabel.Text = 'Variable';
            app.List_Var = uidropdown(app.Panel_6);
            app.List_Var.Items = {'None'};
            app.List_Var.ValueChangedFcn = createCallbackFcn(app, @List_VarValueChanged, true);
            app.List_Var.Enable = 'off';
            app.List_Var.Value = 'None';
            app.Panel_7 = uipanel(app.Plot_Escenarios);
            app.TipodegrficaLabel = uilabel(app.Panel_7);
            app.TipodegrficaLabel.Text = 'Tipo de gráfica';
            app.List_D_TypePlot = uidropdown(app.Panel_7);
            app.List_D_TypePlot.Items = {'Serie de Tiempo', 'Histograma', 'Curva de Duración', 'Boxplot', 'Curva Acumulada'};
            app.List_D_TypePlot.ValueChangedFcn = createCallbackFcn(app, @List_D_TypePlotValueChanged, true);
            app.List_D_TypePlot.Enable = 'off';
            app.List_D_TypePlot.Value = 'Serie de Tiempo';
            app.Info_MM = uibutton(app.Plot_Escenarios, 'state');
            app.Info_MM.ValueChangedFcn = createCallbackFcn(app, @Info_MMValueChanged, true);
            app.Info_MM.Icon = 'Icons_Info.png';
            app.Info_MM.Text = '';
            % Show the figure after all components are created
            app.Plot_Escenarios.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_03_Plot_TimeSeries(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Plot_Escenarios)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Plot_Escenarios)
        end
    end
end