classdef App_04_Plot_Scenarios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Comparacion_Escenarios       matlab.ui.Figure
        Figure                       matlab.ui.control.UIAxes
        Button_Plot                  matlab.ui.control.StateButton
        Button_Clear                 matlab.ui.control.StateButton
        Panel                        matlab.ui.container.Panel
        Switch_Aggregation           matlab.ui.control.Switch
        Panel_3                      matlab.ui.container.Panel
        List_D_Resolution            matlab.ui.control.DropDown
        ResolucinLabel_2             matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        Text_Resolution              matlab.ui.control.EditField
        PasodetiempoLabel            matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        EstadsticaagregacinLabel     matlab.ui.control.Label
        List_D_NameFun               matlab.ui.control.DropDown
        Panel_6                      matlab.ui.container.Panel
        EscenarioReferenciaLabel     matlab.ui.control.Label
        List_Sce_1                   matlab.ui.control.DropDown
        EjecucinReferenciaLabel      matlab.ui.control.Label
        List_Exe_1                   matlab.ui.control.DropDown
        SitioReferenciaLabel         matlab.ui.control.Label
        List_Place_1                 matlab.ui.control.DropDown
        VariableReferenciaLabel      matlab.ui.control.Label
        List_Var_1                   matlab.ui.control.DropDown
        Panel_7                      matlab.ui.container.Panel
        TipodegrficaLabel            matlab.ui.control.Label
        List_D_TypePlot              matlab.ui.control.DropDown
        EscenariocomparativosLabel   matlab.ui.control.Label
        Panel_8                      matlab.ui.container.Panel
        Logo_Gotta_2                 matlab.ui.control.Image
        Logo_Pegasys_2               matlab.ui.control.Image
        Logo_TNC_2                   matlab.ui.control.Image
        Logo_N4W_2                   matlab.ui.control.Image
        Logo_CuencaVerde_2           matlab.ui.control.Image
        Info_MM                      matlab.ui.control.StateButton
        ListSceCom                   matlab.ui.control.ListBox
        EscenarioscomparativosLabel  matlab.ui.control.Label
        List_Sce_2                   matlab.ui.control.EditField
        List_Exe_2                   matlab.ui.control.DropDown
        List_Place_2                 matlab.ui.control.DropDown
        List_Sce_3                   matlab.ui.control.EditField
        List_Exe_3                   matlab.ui.control.DropDown
        List_Place_3                 matlab.ui.control.DropDown
        List_Sce_4                   matlab.ui.control.EditField
        List_Exe_4                   matlab.ui.control.DropDown
        List_Place_4                 matlab.ui.control.DropDown
        List_Sce_5                   matlab.ui.control.EditField
        List_Exe_5                   matlab.ui.control.DropDown
        List_Place_5                 matlab.ui.control.DropDown
        List_Sce_6                   matlab.ui.control.EditField
        List_Exe_6                   matlab.ui.control.DropDown
        List_Place_6                 matlab.ui.control.DropDown
        List_Sce_7                   matlab.ui.control.EditField
        List_Exe_7                   matlab.ui.control.DropDown
        List_Place_7                 matlab.ui.control.DropDown
        EjecucinLabel                matlab.ui.control.Label
        SitioLabel                   matlab.ui.control.Label
        EscenarioLabel               matlab.ui.control.Label
        Panel_9                      matlab.ui.container.Panel
        IndicadoresLabel             matlab.ui.control.Label
        List_Index                   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        NameLegend
        ln = 0;
        Data
        ProjectPath
        StatusPlot = '';
        NameVar_i
        
        % Lista de escenarios para comparción
        ListSce
        % Index
        IndexValue
        NVI
        NameIndex
        
    end
    
    methods (Access = public)
        
        function Data = ReadData(app,NumSce)
            % leer variables de las salidas
            eval(['NameSce = app.List_Sce_',num2str(NumSce),'.Value;']);
            eval(['NameExe = app.List_Exe_',num2str(NumSce),'.Value;']);
            eval(['NamePlace = app.List_Place_',num2str(NumSce),'.Value;']);
            PathFile = fullfile(app.ProjectPath,'salidas',NameSce, NameExe,'series',NamePlace);
            
            Data = Read_TimeSeries_Output(PathFile);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ProjectPath)
%             ProjectPath = 'C:\Users\TNC\Music\SIGA_CAL_Dummy\SIGA_CAL_Dummy_V2';
            app.ProjectPath = ProjectPath;
            %NameLegend ln
            
            % Leer carpetas de Scenarios
            d           = dir(fullfile(app.ProjectPath,'salidas'));
            dfolders    = d([d(:).isdir]);
            dfolders    = dfolders(~ismember({dfolders(:).name},{'.','..'}));
            Tmp         = {'None',dfolders.name}';
            
            % Asignar lista de escenarios base
            app.List_Sce_1.Items = Tmp;
            app.List_Sce_1.Value = Tmp{1};
            
            % Lista de posible escenarios para comparación
            app.ListSce = {dfolders.name}';                                                  
            
            % Figure
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')                               
            
        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
            % Deshabilitar botón de plot
            app.Button_Plot.Value = 0;
            
            NameVar     = app.List_Var_1.Value; 
            if strcmp(NameVar,'None')&&~strcmp(app.List_D_TypePlot.Value,'Indicadores') 
                f = msgbox('No ha seleccionado una variable valida');    
                set(f, 'color',[1 1 1])  
                return
            end
            
            % Borrar gráfico siempre
            app.Button_ClearValueChanged; 

            app.NameVar_i = NameVar;
                                                                   
            %% Leer datos
            SceCom = app.ListSceCom.Value;
            
            % Inidcadores
            app.NameIndex   = {'Q5','ESDET','OD','CDBO5','EC','SST','NO3','NH4','NO','PO','PI','PT','PFB'};
            UnitIndex       = {'(m3.d-1)','(m3)','(kg.m-3)','(kg.m-3)','(ufc.m-3)','(kg.m-3)',...
                               '(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(kg.m-3)','(m3.d-1)'};
            NewUnitIndex    = {'(m3.s-1)','(Ton.d-1)','(mg.l-1)','(mg.l-1)','(ufc/m3)','(mg.l-1)',...
                               '(mg.l-1)','(mg.l-1)','(mg.l-1)','(mg.l-1)','(mg.l-1)','(mg.l-1)','(m3.s-1)'};
            FacorC          = [1/86400, 2.65/1000, 1000, 1000, 1, 1000, 1000, 1000, 1000, 1000, 1000, 1000, 1/86400];
            Index           = zeros(1+length(SceCom),length(app.NameIndex));
            
            for NumSce  = 1:1+length(SceCom)
                % ---------------------------------------------------------------------------------------------------------------------
                % Lectura de datos
                % ---------------------------------------------------------------------------------------------------------------------
                DataRaw     = app.ReadData(NumSce);            
                NameLegend1 = app.NameLegend;
                ln          = app.ln;
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Leer de parametros para gráfico
                % ---------------------------------------------------------------------------------------------------------------------
                TypePlot    = app.List_D_TypePlot.Value;
                CheckAgg    = app.Switch_Aggregation.Value;
                Step        = str2double(app.Text_Resolution.Value);
                NameFun     = app.List_D_NameFun.Value;                      
                Resolution  = app.List_D_Resolution.Value;
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Configuración de leyenda
                % ---------------------------------------------------------------------------------------------------------------------
                eval(['NameSce   = app.List_Sce_',num2str(NumSce),'.Value;']);
                eval(['NameExe   = app.List_Exe_',num2str(NumSce),'.Value;']);
                eval(['NamePlace = app.List_Place_',num2str(NumSce),'.Value;']);
                
                ln = ln + 1;
                NameLegend1{1,ln} = [NameSce,' - ',NameExe,' - ',NamePlace];            
                app.NameLegend = NameLegend1;
                app.ln = ln;
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Agregación de series de tiempo
                % ---------------------------------------------------------------------------------------------------------------------
                if strcmp(CheckAgg,'R')
                    if strcmp(Resolution,"Diaria")
                        Results = DataRaw; 
                    elseif strcmp(Resolution,"Mensual")
                        Results = Aggregation_TimeSeries(DataRaw, Resolution, NameFun);
                    elseif strcmp(Resolution,"Anual")
                        Results = Aggregation_TimeSeries(DataRaw, Resolution, NameFun);
                    end                    
                else     
                    Results = Aggregation_TimeSeries(DataRaw, Step, NameFun);
                end
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Varible a graficar  
                % ---------------------------------------------------------------------------------------------------------------------
                ii = find(strcmp(Results.NameVar, NameVar));
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Indicadores
                % ---------------------------------------------------------------------------------------------------------------------
                if strcmp(TypePlot,'Indicadores')                    
                    for io = 1:length(app.NameIndex)
                        NameVarIndex = [app.NameIndex{io},UnitIndex{io}];
                        MyFun   = @(x) strfind(x, NameVarIndex);                    
                        id      = cellfun(MyFun,Results.NameVar,'UniformOutput',false);
                        id      = find(cellfun(@isempty,id) == 0);                 
                        if io == 1
                            PoPo = id(1);
                        end
                        
                        if io <= 2
                            Index(NumSce,io) = sum(Results.Data(:,id(1))*FacorC(io),'omitnan');
                        else
                            Index(NumSce,io) = sum(Results.Data(:,id(1))*FacorC(io).*Results.Data(:,PoPo)*FacorC(1),'omitnan');
                        end                        
                    end
                end
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Plot Serie de tiempo
                % ---------------------------------------------------------------------------------------------------------------------
                if strcmp(TypePlot,'Serie de Tiempo')
                    if strcmp(app.StatusPlot,'Serie de Tiempo')
                        hold(app.Figure,'on')   
                    else                    
                        reset(app.Figure)
                        cla(app.Figure,'reset');
                        app.Figure.BackgroundColor = [1 1 1];
                        hold(app.Figure,'off')
                    end                
                    
                    %% Plot    
                    plot(app.Figure,Results.Date,Results.Data(:,ii),'Linewidth',1.3)
                    xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                    ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)   
                    title(app.Figure,'Serie de tiempo', 'interpreter','latex', 'Fontsize',18)
                    set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)    
                    
                    Leg = legend(app.Figure,NameLegend1);
                    set(Leg,'Fontsize',8)
                    
                    % Controla el reinicio de las graficas
                    app.StatusPlot = 'Serie de Tiempo';
                
                % ---------------------------------------------------------------------------------------------------------------------
                % Curvas de duración de caudales
                % ---------------------------------------------------------------------------------------------------------------------
                elseif strcmp(TypePlot,'Curva de Duración')                                
                    if strcmp(app.StatusPlot,'Curva de Duración')
                        hold(app.Figure,'on')   
                    else                    
                        reset(app.Figure)
                        cla(app.Figure,'reset');
                        app.Figure.BackgroundColor = [1 1 1];
                        hold(app.Figure,'off')                                        
                    end
                    
                    Q               = Results.Data(:,ii); 
                    [Por_Q,Qd]      = hist(Q,length(unique(Q)));
                    [Qsort, id ]    = sort(Qd, 'descend');
                    PQ              = (cumsum(Por_Q(id))/sum(Por_Q(id)))*100;
                    [~, id]         = unique(PQ);
                    
                    Porcentaje      = [10 50 75 85 95];
                    DataQ           = interp1(PQ(id), Qsort(id), Porcentaje);
                    
                    hold(app.Figure,'on')
                    
                    plot(app.Figure, PQ, Qsort,'Linewidth',1.3);
                    hold(app.Figure,'on')
                    
                    xlabel(app.Figure,['Porcentaje del tiempo en el que ','${',NameVar,'}$',' es excedido (\%)'],...
                                       'Interpreter','latex', 'Fontsize',18)
                    ylabel(app.Figure,['${',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                    title(app.Figure,'Curva de duraci\''on', 'interpreter','latex', 'Fontsize',18)
                    set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                    
                    Leg = legend(app.Figure,NameLegend1);
                    set(Leg,'Fontsize',8)
                    
                    % Controla el reinicio de las graficas
                    app.StatusPlot = 'Curva de Duración';                                        
                    
                % ---------------------------------------------------------------------------------------------------------------------
                % Curvas acumuladas
                % ---------------------------------------------------------------------------------------------------------------------
                elseif strcmp(TypePlot,'Curva Acumulada')
                    if strcmp(app.StatusPlot,'Curva Acumulada')
                        hold(app.Figure,'on')   
                    else                    
                        reset(app.Figure)
                        cla(app.Figure,'reset');
                        app.Figure.BackgroundColor = [1 1 1];
                        hold(app.Figure,'off')                                        
                    end
                    
                    Pcm     = cumsum(Results.Data(:,ii), 'omitnan');                   
                    
                    plot(app.Figure,Results.Date, Pcm,'Linewidth',2)
                    hold(app.Figure,'on')
                    
                    xlabel(app.Figure,'Tiempo', 'interpreter','latex', 'Fontsize',18)
                    ylabel(app.Figure,['$Accum - {',NameVar,'}$'], 'interpreter','latex', 'Fontsize',18)
                    title(app.Figure,'Curva Acumulada', 'interpreter','latex', 'Fontsize',18)
                    set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
                    
                    Leg = legend(app.Figure,NameLegend1);
                    set(Leg,'Fontsize',8)
                    
                    % Controla el reinicio de las graficas
                    app.StatusPlot = 'Curva Acumulada';
                    
                end
               
            end
            
            if strcmp(TypePlot,'Indicadores') 
                % Nombre de las variables
                app.NVI = { 'Caudal',...
                            'Sedimentos',...
                            'Oxígeno disuelto',...
                            'DBO',...
                            'E. coli',...
                            'Sólidos suspendidos totales',...
                            'Nitratos',...
                            'Nitrógeno amoniacal',...
                            'Nitrógeno orgánico',...
                            'Fósforo orgánico',...
                            'Fósforo inorgánico',...
                            'Fósforo total',...
                            'Flujo base'};
                    
                ii = find(strcmp(app.NVI, app.List_Index.Value));
                
                %% Borrar ojo!!!!!
%                 Index = rand(size(Index));
                
                % Check valores cero
                id = (Index(1,ii) == 0);
                
                if sum(id) > 0
                    errordlg(['la suma de la varible ',app.NVI{ii},' (',app.NameIndex{ii},UnitIndex{ii},')',...
                        ' en el escenario de referencia es igual a cero.',...
                        ' Esto genera un error númerico en el indicador. Revisar las salidas del modelo']) 
                    return
                end
                
                if length(Index(1,:)) == 1
                    errordlg('Debe seleccionar por lo menos un escenario de comparación') 
                    return
                end
                
                % Calcular indicadores
                Tmp1  = bsxfun(@minus, Index(2:end,:),Index(1,:));
                Index = round(bsxfun(@rdivide, Tmp1,Index(1,:)).*100,2);            
                
                app.IndexValue = Index;                                
                
                % Plot bar               
                NameSce = app.ListSceCom.Value;
                for ji = 1:length(NameSce)
                    NameSce{ji} = ['Sce ',num2str(ji),' (',NameSce{ji},')'] ;
                end
                hold(app.Figure,'on')                               
                bar(app.Figure,categorical(NameSce),Index(:,ii),'FaceColor',[0 .6 .6],'EdgeColor',[0 .9 .9]);%'FaceColor',[.7 .7 .7],'EdgeColor',[.9 .9 .9]
                plot(app.Figure,categorical(NameSce),Index(:,ii),'Color',[0.2 0.2 0.2], 'LineWidth',1.5,'Marker',"o","MarkerFaceColor",[0 0 0]);
%                 text(app.Figure,categorical(app.ListSceCom.Value),Index(:,ii),categorical(Index(:,ii)),'Color','red','FontSize',10)
                
                Ymin = floor(min(Index(:,ii)/10))*10 - 10;
                Ymax = ceil(max(Index(:,ii)/10))*10 + 10;
                if (Ymin > 0) && (Ymax > 0)
                    Ymin = 0;
                elseif (Ymin < 0) && (Ymax < 0)
                    Ymax = 0;
                end                
                YT = Ymin:10:Ymax;
                if length(YT) > 15
                    YT = Ymin:50:Ymax;
                elseif length(YT) <= 4
                    YT = Ymin:1:Ymax; 
                elseif length(YT) <= 5
                    YT = Ymin:2:Ymax;                
                end
                
                app.Figure.YTick        = YT;
                app.Figure.YTickLabel   = YT;
                
                xlabel(app.Figure,'Escenarios', 'interpreter','latex', 'Fontsize',18)
                ylabel(app.Figure,['Cambio en ',app.NameIndex{ii},' ${(\%)}$'], 'interpreter','latex', 'Fontsize',18)
                title(app.Figure,['Indicador de cambio de ',app.NVI{ii},' (',app.NameIndex{ii},')'], 'interpreter','latex', 'Fontsize',18)
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
%                 pause
            end
        end

        % Value changed function: List_Sce_1
        function List_Sce_1ValueChanged(app, event)
           
            value = app.List_Sce_1.Value;
            
            if ~strcmp(value,'None')                                
                % Listar ejecuciones
                d = dir(fullfile(app.ProjectPath,'salidas',value));                    
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                Tmp      = {'None',dfolders.name}';
                
                % Asignar valores de lista                
                app.List_Exe_1.Items  = Tmp;
                app.List_Exe_1.Value  = Tmp{1};                                
                
                % Habilitar listas
                app.List_Exe_1.Enable = true;
            else
                % Habilitar
                app.List_Exe_1.Enable           = false;
                app.List_Place_1.Enable         = false;
                app.List_Var_1.Enable           = false;                                
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
                app.Button_Plot.Enable          = false;
                app.Button_Clear.Enable         = false;
                app.Switch_Aggregation.Enable   = false;
                app.ListSceCom.Enable           = false;
                app.ListSceCom.Value            = {};
                app.ListSceComValueChanged;
                app.List_Exe_1.Value            = 'None';
                app.List_Place_1.Value          = 'None';
                app.List_Var_1.Value            = 'None';
            end
            
            %% Asignar valores a list
            List_Sce = app.List_Sce_1.Items;
            List_Sce = List_Sce(2:end);
            MyFun   = @(x) strcmp(x,app.List_Sce_1.Value);
            id      = cellfun(MyFun,List_Sce);
            Choco   = List_Sce; 
            Choco(id) = [];
            
            if strcmp(app.List_Sce_1.Value,'None')
                app.ListSceCom.Items = {''};
            else
                app.ListSceCom.Items = Choco;
            end
            
            app.List_Exe_1ValueChanged;
        end

        % Value changed function: List_Exe_1
        function List_Exe_1ValueChanged(app, event)
           
            value = app.List_Exe_1.Value;
            
            if ~strcmp(value,'None')
                % Listar Series
                NameSce = app.List_Sce_1.Value;
                NameFiles = dir(fullfile(app.ProjectPath,'salidas',NameSce, value,'series','*.txt'));
                NameFiles = {'None',NameFiles.name}';
                
                % Asignar valores de lista  
                app.List_Place_1.Items = NameFiles;
                app.List_Place_1.Value = NameFiles{1};
                
                % Habilitar
                app.List_Place_1.Enable = true;
                
            else
                % Desabilitar                
                app.List_Place_1.Enable         = false;
                app.List_Var_1.Enable           = false;                
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
                app.ListSceCom.Enable           = false;
                app.ListSceCom.Value            = {};
                app.ListSceComValueChanged;      
                app.List_Place_1.Value          = 'None';
                app.List_Var_1.Value            = 'None';
            end
            app.List_Place_1ValueChanged;
        end

        % Value changed function: List_Place_1
        function List_Place_1ValueChanged(app, event)

            value = app.List_Place_1.Value;
            
            if ~strcmp(value,'None')
                % leer variables de las salidas
                NameSce = app.List_Sce_1.Value;
                NameExe = app.List_Exe_1.Value;
                PathFile = fullfile(app.ProjectPath,'salidas',NameSce, NameExe,'series',value);
                
                Data = Read_TimeSeries_Output(PathFile);
                app.Data = Data;
                
                % Asignar valores de lista  
                app.List_Var_1.Items = {'None',Data.NameVar{:}}';
                app.List_Var_1.Value = 'None';
                
                % Habilitar
                if ~strcmp(app.List_D_TypePlot.Value,'Indicadores') 
                    app.List_Var_1.Enable = true;  
                end
               
            else
                app.List_Var_1.Enable           = false;
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_NameFun.Enable       = false;
                app.ListSceCom.Enable           = false;
                app.ListSceCom.Value            = {};
                app.ListSceComValueChanged;
                app.List_Var_1.Value            = 'None';
            end
            app.List_Var_1ValueChanged;
        end

        % Value changed function: List_Var_1
        function List_Var_1ValueChanged(app, event)
            value = app.List_Var_1.Value;
            
            if ~strcmp(value,'None')
                app.Button_Clear.Enable         = true;
                app.Button_Plot.Enable          = true;
                app.Switch_Aggregation.Enable   = true;
                app.List_D_TypePlot.Enable      = true;
                if strcmp(app.Switch_Aggregation.Value,'R')
                    app.List_D_Resolution.Enable    = true;
                else
                    app.Text_Resolution.Enable    = true;
                end 
                app.ListSceCom.Enable           = true;                                
            else
                app.Button_Clear.Enable         = false;
                app.Button_Plot.Enable          = false;
                app.Switch_Aggregation.Enable   = false;
                app.List_D_Resolution.Enable    = false;
                app.Text_Resolution.Enable      = false;
                app.List_D_TypePlot.Enable      = false;
                app.List_D_TypePlot.Value       = 'Serie de Tiempo';
                app.List_Index.Enable           = false;
                app.List_D_NameFun.Enable       = false;
                app.ListSceCom.Enable           = false;
                app.ListSceCom.Value            = {};
                app.ListSceComValueChanged;
            end
            
        end

        % Value changed function: Switch_Aggregation
        function Switch_AggregationValueChanged(app, event)
            value = app.Switch_Aggregation.Value;
            
            if strcmp(value,'R')
                app.Text_Resolution.Enable      = 0;
                app.List_D_Resolution.Enable    = 1;
                app.List_D_NameFun.Enable       = 0;
                app.List_D_Resolution.Value     = 'Diaria';
            else
                app.Text_Resolution.Enable      = 1;                
                app.List_D_Resolution.Enable    = 0;
                app.List_D_NameFun.Enable       = 1;
            end
        end

        % Value changed function: Button_Clear
        function Button_ClearValueChanged(app, event)
            app.Button_Clear.Value = 0;
            
            reset(app.Figure) 
            cla(app.Figure,'reset');
            app.Figure.BackgroundColor = [1 1 1];
                                   
            app.StatusPlot = 'Clear';
            app.NameLegend = {};
            app.ln = 0;
            
            title(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            xlabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18)
            ylabel(app.Figure,'', 'interpreter','latex', 'Fontsize',18) 
            xticklabels(app.Figure,'')
            yticklabels(app.Figure,'')
            set(app.Figure,'box','on')
        end

        % Value changed function: List_D_Resolution
        function List_D_ResolutionValueChanged(app, event)
            value = app.List_D_Resolution.Value;
            
            if ~strcmp(value,'Diaria')
                app.List_D_NameFun.Enable = true;
            else
                app.List_D_NameFun.Enable = false;
            end
        end

        % Value changed function: List_D_TypePlot
        function List_D_TypePlotValueChanged(app, event)
            value = app.List_D_TypePlot.Value;
            
            if strcmp(value,'Indicadores')  
                app.List_Index.Enable = true;
                app.List_Var_1.Enable = false;
            else
                app.List_Index.Enable = false;
                app.List_Var_1.Enable = true;
            end
        end

        % Value changed function: Text_Resolution
        function Text_ResolutionValueChanged(app, event)
            value = str2double(app.Text_Resolution.Value);
            
            if (mod(value,1)~=0)
                f = errordlg('Ingrese un valor entero');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end
            
            if isnan(value)
                f = errordlg('Ingrese un valor numérico');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end
            
            if value < 2
                f = errordlg('El valor debe ser mayor que "1".');
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = '2';
                return
            end
            
            N_Data = app.Data.Fil;
            if value > (N_Data - 1)
                f = errordlg(['El valor máximo de agregación permitido es: ',num2str(N_Data-1,'%d')]);
                f = set(f,'color',[1 1 1]);
                app.Text_Resolution.Value = num2str(N_Data-1,'%d');
                return
            end
        end

        % Callback function
        function EscenariosListBoxValueChanged(app, event)
            value = app.EscenariosListBox.Value;
            
            o = 1;
        end

        % Value changed function: ListSceCom
        function ListSceComValueChanged(app, event)
            value = app.ListSceCom.Value;                
            
            if length(value) > 6
                errordlg('El maxímo número de escenarios que puede seleccionar son seis (6)')                
                app.ListSceCom.Value = value(1:6);
            end
            
            app.ListSce = app.ListSceCom.Value;    
                                                
            %% Asiganar valores a tablas
            for i = 1:length(app.ListSce) 
                % habilitar pestaña
                eval(['app.List_Sce_',num2str(i+1),'.Enable = ''','On',''';','']);
                
                % Asignar nombre del escenario
                eval(['app.List_Sce_',num2str(i+1),'.Value = ','''', app.ListSce{i},'''',';'])
                
                % leer listado de ejecuciones
                d        = dir(fullfile(app.ProjectPath,'salidas',app.ListSce{i}));
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                
                % habilitar pestaña
                eval(['app.List_Exe_',num2str(i+1),'.Enable = ''','On',''';','']);
                
                % Asignar listado de ejecuciones
                Tmp = {dfolders.name};
                eval(['app.List_Exe_',num2str(i+1),'.Items = Tmp;']);                               
                
                for j = 1:length(Tmp)                    
                    NameFiles   = dir(fullfile(app.ProjectPath,'salidas',app.ListSce{i}, Tmp{j},'series','*.txt'));
                    Tmp1        = {NameFiles.name}';
                    
                    % habilitar pestaña
                    eval(['app.List_Place_',num2str(i+1),'.Enable = ''','On',''';','']);
                    
                    % Asignar listado de ejecuciones
                    Tmp = {dfolders.name};
                    eval(['app.List_Place_',num2str(i+1),'.Items = Tmp1;']);                 
                end                
            end  
            
            if ~isempty(app.ListSce)
                i_Start = i+1;
            else
                i_Start = 1;
            end
            
            Tmp = {'None',''};
            for i = i_Start:6
               eval(['app.List_Sce_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Sce_',num2str(i+1),'.Value = ','''''',';'])
               
               eval(['app.List_Exe_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Exe_',num2str(i+1),'.Items = Tmp;'])
               
               eval(['app.List_Place_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Place_',num2str(i+1),'.Items = Tmp;'])         
            end
        end

        % Callback function
        function Button_LoadScePushed(app, event)
            app.ListSce = app.ListSceCom.Value;    

            %% Asiganar valores a tablas
            for i = 1:length(app.ListSce) 
                % habilitar pestaña
                eval(['app.EditField_',num2str(i+1),'.Enable = ''','On',''';','']);
                
                % Asignar nombre del escenario
                eval(['app.EditField_',num2str(i+1),'.Value = ','''', app.ListSce{i},'''',';'])
                
                % leer listado de ejecuciones
                d        = dir(fullfile(app.ProjectPath,'salidas',app.ListSce{i}));
                dfolders = d([d(:).isdir]);            
                dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
                
                % habilitar pestaña
                eval(['app.List_Exe_',num2str(i+1),'.Enable = ''','On',''';','']);
                
                % Asignar listado de ejecuciones
                Tmp = {dfolders.name};
                eval(['app.List_Exe_',num2str(i+1),'.Items = Tmp;']);                               
                
                for j = 1:length(Tmp)                    
                    NameFiles   = dir(fullfile(app.ProjectPath,'salidas',app.ListSce{i}, Tmp{j},'series','*.txt'));
                    Tmp1        = {NameFiles.name}';
                    
                    % habilitar pestaña
                    eval(['app.List_Place_',num2str(i+1),'.Enable = ''','On',''';','']);
                    
                    % Asignar listado de ejecuciones
                    Tmp = {dfolders.name};
                    eval(['app.List_Place_',num2str(i+1),'.Items = Tmp1;']); 
                
                    % Variables
                    PathFile    = fullfile(app.ProjectPath,'salidas',app.ListSce{i}, Tmp{j},'series',Tmp1{1});                    
                    Data        = Read_TimeSeries_Output(PathFile);
                    
                    % Asignar valores de lista  
                    Tmp2        = {Data.NameVar{:}}'; 
                    
                    % habilitar pestaña
                    eval(['app.List_Var_',num2str(i+1),'.Enable = ''','On',''';','']);
                    
                    % Asignar listado de ejecuciones
                    Tmp = {dfolders.name};
                    eval(['app.List_Var_',num2str(i+1),'.Items = Tmp2;']);                 
                end                
            end  
            
            i_Start = i+1;
            Tmp = {'None',''};
            for i = i_Start:6
               eval(['app.EditField_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.EditField_',num2str(i+1),'.Value = ','''''',';'])
               
               eval(['app.List_Exe_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Exe_',num2str(i+1),'.Items = Tmp;'])
               
               eval(['app.List_Place_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Place_',num2str(i+1),'.Items = Tmp;'])
               
               eval(['app.List_Var_',num2str(i+1),'.Enable = ''','Off',''';','']);
               eval(['app.List_Var_',num2str(i+1),'.Items = Tmp;'])               
            end
        end

        % Value changed function: Info_MM
        function Info_MMValueChanged(app, event)
            app.Info_MM.Value = 0;
            
            dos('explorer https://tnc.box.com/s/r8smn41pd3fxbwc3jku26v8ysd5tidxn')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Comparacion_Escenarios = uifigure('Visible', 'off');
            app.Comparacion_Escenarios.Name = 'UI Figure';
            app.Figure = uiaxes(app.Comparacion_Escenarios);
            title(app.Figure, 'Title')
            xlabel(app.Figure, 'X')
            ylabel(app.Figure, 'Y')
            app.Figure.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Button_Plot = uibutton(app.Comparacion_Escenarios, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Icon = 'Icons_TimeSeries_2.png';
            app.Button_Plot.Text = 'Graficar';
            app.Button_Clear = uibutton(app.Comparacion_Escenarios, 'state');
            app.Button_Clear.ValueChangedFcn = createCallbackFcn(app, @Button_ClearValueChanged, true);
            app.Button_Clear.Enable = 'off';
            app.Button_Clear.Icon = 'Icons_Clear.png';
            app.Button_Clear.Text = 'limpiar';
            app.Panel = uipanel(app.Comparacion_Escenarios);
            app.Switch_Aggregation = uiswitch(app.Panel, 'slider');
            app.Switch_Aggregation.Items = {'PT', 'R'};
            app.Switch_Aggregation.ValueChangedFcn = createCallbackFcn(app, @Switch_AggregationValueChanged, true);
            app.Switch_Aggregation.Enable = 'off';
            app.Switch_Aggregation.Value = 'R';
            app.Panel_3 = uipanel(app.Panel);
            app.List_D_Resolution = uidropdown(app.Panel_3);
            app.List_D_Resolution.Items = {'Diaria', 'Mensual', 'Anual'};
            app.List_D_Resolution.Editable = 'on';
            app.List_D_Resolution.ValueChangedFcn = createCallbackFcn(app, @List_D_ResolutionValueChanged, true);
            app.List_D_Resolution.Enable = 'off';
            app.List_D_Resolution.Value = 'Diaria';
            app.ResolucinLabel_2 = uilabel(app.Panel_3);
            app.ResolucinLabel_2.Text = 'Resolución';
            app.Panel_4 = uipanel(app.Panel);
            app.Text_Resolution = uieditfield(app.Panel_4, 'text');
            app.Text_Resolution.ValueChangedFcn = createCallbackFcn(app, @Text_ResolutionValueChanged, true);
            app.Text_Resolution.Enable = 'off';
            app.Text_Resolution.Value = '2';
            app.PasodetiempoLabel = uilabel(app.Panel_4);
            app.PasodetiempoLabel.Text = {'Paso de '; 'tiempo'};
            app.Panel_5 = uipanel(app.Panel);
            app.EstadsticaagregacinLabel = uilabel(app.Panel_5);
            app.EstadsticaagregacinLabel.Text = {'Estadística'; 'agregación'};
            app.List_D_NameFun = uidropdown(app.Panel_5);
            app.List_D_NameFun.Items = {'Promedio', 'Máximo', 'Mínimo', 'Suma', ''};
            app.List_D_NameFun.Enable = 'off';
            app.List_D_NameFun.Value = 'Promedio';
            app.Panel_6 = uipanel(app.Comparacion_Escenarios);
            app.EscenarioReferenciaLabel = uilabel(app.Panel_6);
            app.EscenarioReferenciaLabel.Text = 'Escenario Referencia';
            app.List_Sce_1 = uidropdown(app.Panel_6);
            app.List_Sce_1.Items = {'None'};
            app.List_Sce_1.ValueChangedFcn = createCallbackFcn(app, @List_Sce_1ValueChanged, true);
            app.List_Sce_1.Value = 'None';
            app.EjecucinReferenciaLabel = uilabel(app.Panel_6);
            app.EjecucinReferenciaLabel.Text = 'Ejecución Referencia';
            app.List_Exe_1 = uidropdown(app.Panel_6);
            app.List_Exe_1.Items = {'None'};
            app.List_Exe_1.ValueChangedFcn = createCallbackFcn(app, @List_Exe_1ValueChanged, true);
            app.List_Exe_1.Enable = 'off';
            app.List_Exe_1.Value = 'None';
            app.SitioReferenciaLabel = uilabel(app.Panel_6);
            app.SitioReferenciaLabel.Text = 'Sitio Referencia';
            app.List_Place_1 = uidropdown(app.Panel_6);
            app.List_Place_1.Items = {'None'};
            app.List_Place_1.ValueChangedFcn = createCallbackFcn(app, @List_Place_1ValueChanged, true);
            app.List_Place_1.Enable = 'off';
            app.List_Place_1.Value = 'None';
            app.VariableReferenciaLabel = uilabel(app.Panel_6);
            app.VariableReferenciaLabel.Text = 'Variable Referencia';
            app.List_Var_1 = uidropdown(app.Panel_6);
            app.List_Var_1.Items = {'None'};
            app.List_Var_1.ValueChangedFcn = createCallbackFcn(app, @List_Var_1ValueChanged, true);
            app.List_Var_1.Enable = 'off';
            app.List_Var_1.Value = 'None';
            app.Panel_7 = uipanel(app.Comparacion_Escenarios);
            app.TipodegrficaLabel = uilabel(app.Panel_7);
            app.TipodegrficaLabel.Text = 'Tipo de gráfica';
            app.List_D_TypePlot = uidropdown(app.Panel_7);
            app.List_D_TypePlot.Items = {'Serie de Tiempo', 'Curva de Duración', 'Curva Acumulada', 'Indicadores'};
            app.List_D_TypePlot.ValueChangedFcn = createCallbackFcn(app, @List_D_TypePlotValueChanged, true);
            app.List_D_TypePlot.Enable = 'off';
            app.List_D_TypePlot.Value = 'Serie de Tiempo';
            app.EscenariocomparativosLabel = uilabel(app.Comparacion_Escenarios);
            app.EscenariocomparativosLabel.Text = 'Escenario comparativos';
            app.Panel_8 = uipanel(app.Comparacion_Escenarios);
            app.Logo_Gotta_2 = uiimage(app.Panel_8);
            app.Logo_Gotta_2.ImageSource = 'Icons_Gotta.jpg';
            app.Logo_Pegasys_2 = uiimage(app.Panel_8);
            app.Logo_Pegasys_2.ImageSource = 'Logo_Pegasys.png';
            app.Logo_TNC_2 = uiimage(app.Panel_8);
            app.Logo_TNC_2.ImageSource = 'Logo_TNC.png';
            app.Logo_N4W_2 = uiimage(app.Panel_8);
            app.Logo_N4W_2.ImageSource = 'Logo_N4W-Facility.png';
            app.Logo_CuencaVerde_2 = uiimage(app.Panel_8);
            app.Logo_CuencaVerde_2.ImageSource = 'Logo_CuencaVerde.png';
            app.Info_MM = uibutton(app.Panel_8, 'state');
            app.Info_MM.ValueChangedFcn = createCallbackFcn(app, @Info_MMValueChanged, true);
            app.Info_MM.Icon = 'Icons_Info.png';
            app.Info_MM.Text = '';
            app.ListSceCom = uilistbox(app.Comparacion_Escenarios);
            app.ListSceCom.Items = {};
            app.ListSceCom.Multiselect = 'on';
            app.ListSceCom.ValueChangedFcn = createCallbackFcn(app, @ListSceComValueChanged, true);
            app.ListSceCom.Enable = 'off';
            app.ListSceCom.Value = {};
            app.EscenarioscomparativosLabel = uilabel(app.Comparacion_Escenarios);
            app.EscenarioscomparativosLabel.Text = 'Escenarios comparativos';
            app.List_Sce_2 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_2.Editable = 'off';
            app.List_Sce_2.Enable = 'off';
            app.List_Exe_2 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_2.Items = {'None'};
            app.List_Exe_2.Enable = 'off';
            app.List_Exe_2.Value = 'None';
            app.List_Place_2 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_2.Items = {'None'};
            app.List_Place_2.Enable = 'off';
            app.List_Place_2.Value = 'None';
            app.List_Sce_3 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_3.Editable = 'off';
            app.List_Sce_3.Enable = 'off';
            app.List_Exe_3 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_3.Items = {'None'};
            app.List_Exe_3.Enable = 'off';
            app.List_Exe_3.Value = 'None';
            app.List_Place_3 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_3.Items = {'None'};
            app.List_Place_3.Enable = 'off';
            app.List_Place_3.Value = 'None';
            app.List_Sce_4 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_4.Editable = 'off';
            app.List_Sce_4.Enable = 'off';
            app.List_Exe_4 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_4.Items = {'None'};
            app.List_Exe_4.Enable = 'off';
            app.List_Exe_4.Value = 'None';
            app.List_Place_4 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_4.Items = {'None'};
            app.List_Place_4.Enable = 'off';
            app.List_Place_4.Value = 'None';
            app.List_Sce_5 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_5.Editable = 'off';
            app.List_Sce_5.Enable = 'off';
            app.List_Exe_5 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_5.Items = {'None'};
            app.List_Exe_5.Enable = 'off';
            app.List_Exe_5.Value = 'None';
            app.List_Place_5 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_5.Items = {'None'};
            app.List_Place_5.Enable = 'off';
            app.List_Place_5.Value = 'None';
            app.List_Sce_6 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_6.Editable = 'off';
            app.List_Sce_6.Enable = 'off';
            app.List_Exe_6 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_6.Items = {'None'};
            app.List_Exe_6.Enable = 'off';
            app.List_Exe_6.Value = 'None';
            app.List_Place_6 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_6.Items = {'None'};
            app.List_Place_6.Enable = 'off';
            app.List_Place_6.Value = 'None';
            app.List_Sce_7 = uieditfield(app.Comparacion_Escenarios, 'text');
            app.List_Sce_7.Editable = 'off';
            app.List_Sce_7.Enable = 'off';
            app.List_Exe_7 = uidropdown(app.Comparacion_Escenarios);
            app.List_Exe_7.Items = {'None'};
            app.List_Exe_7.Enable = 'off';
            app.List_Exe_7.Value = 'None';
            app.List_Place_7 = uidropdown(app.Comparacion_Escenarios);
            app.List_Place_7.Items = {'None'};
            app.List_Place_7.Enable = 'off';
            app.List_Place_7.Value = 'None';
            app.EjecucinLabel = uilabel(app.Comparacion_Escenarios);
            app.EjecucinLabel.Text = 'Ejecución';
            app.SitioLabel = uilabel(app.Comparacion_Escenarios);
            app.SitioLabel.Text = 'Sitio';
            app.EscenarioLabel = uilabel(app.Comparacion_Escenarios);
            app.EscenarioLabel.Text = 'Escenario';
            app.Panel_9 = uipanel(app.Comparacion_Escenarios);
            app.IndicadoresLabel = uilabel(app.Panel_9);
            app.IndicadoresLabel.Text = 'Indicadores';
            app.List_Index = uidropdown(app.Panel_9);
            app.List_Index.Items = {'Caudal', 'Sedimentos', 'Oxígeno disuelto', 'DBO', 'E. coli', 'Sólidos suspendidos totales', 'Nitratos', 'Nitrógeno amoniacal', 'Nitrógeno orgánico', 'Fósforo orgánico', 'Fósforo inorgánico', 'Fósforo total', 'Flujo base'};
            app.List_Index.Enable = 'off';
            app.List_Index.Value = 'Caudal';
            % Show the figure after all components are created
            app.Comparacion_Escenarios.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_04_Plot_Scenarios(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Comparacion_Escenarios)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Comparacion_Escenarios)
        end
    end
end