classdef App_05_Help < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ayuda                    matlab.ui.Figure
        Image                    matlab.ui.control.Image
        Logo_TNC                 matlab.ui.control.Image
        Logo_N4W                 matlab.ui.control.Image
        Logo_Gotta               matlab.ui.control.Image
        Logo_Pegasys             matlab.ui.control.Image
        DocumentosdeayudaPanel   matlab.ui.container.Panel
        ManualSIGA               matlab.ui.control.Button
        VarSIGA                  matlab.ui.control.Button
        Infos                    matlab.ui.control.Button
        SitioswebdeinteresPanel  matlab.ui.container.Panel
        Web_CV                   matlab.ui.control.Button
        Web_N4W                  matlab.ui.control.Button
        Web_FA                   matlab.ui.control.Button
        Web_TNC                  matlab.ui.control.Button
        Web_Pega                 matlab.ui.control.Button
        Web_Gotta                matlab.ui.control.Button
        Logo_CuencaVerde         matlab.ui.control.Image
        DSSPanel                 matlab.ui.container.Panel
        DSS                      matlab.ui.control.Button
        SimulacinGeocientficaAbiertaSIGACALCuencaVerdeVersin10Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Web_N4W
        function Web_N4WPushed(app, event)
            dos('explorer https://nature4water.org');
        end

        % Button pushed function: Web_CV
        function Web_CVButtonPushed(app, event)
            dos('explorer https://www.cuencaverde.org');
        end

        % Button pushed function: Web_FA
        function Web_FAButtonPushed(app, event)
            dos('explorer https://www.fondosdeagua.org/es/');
        end

        % Button pushed function: Web_TNC
        function Web_TNCButtonPushed(app, event)
            dos('explorer https://www.nature.org/en-us/');
        end

        % Button pushed function: DSS
        function DSSButtonPushed(app, event)
            dos('explorer https://tnc.box.com/s/vic94z1llvg07li3g7n8pdoqz8ykwevx')
        end

        % Button pushed function: ManualSIGA
        function ManualSIGAButtonPushed(app, event)
            dos('explorer https://tnc.box.com/s/r8smn41pd3fxbwc3jku26v8ysd5tidxn')
        end

        % Button pushed function: VarSIGA
        function VarSIGAButtonPushed(app, event)
            dos('explorer https://tnc.box.com/s/r8smn41pd3fxbwc3jku26v8ysd5tidxn')
        end

        % Button pushed function: Infos
        function InfosButtonPushed(app, event)
            dos('explorer https://tnc.box.com/s/gdllgt12grai6uq5up7wku5mpkpk2ijp')
        end

        % Button pushed function: Web_Pega
        function Web_PegaButtonPushed(app, event)
            dos('explorer https://www.pegasys.co.za/about-us/');
        end

        % Button pushed function: Web_Gotta
        function Web_GottaButtonPushed(app, event)
            dos('explorer https://gottaingenieria.com/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Ayuda = uifigure('Visible', 'off');
            app.Ayuda.Name = 'UI Figure';
            app.Image = uiimage(app.Ayuda);
            app.Image.ImageSource = 'Icons_SIGA_CAL_Model.png';
            app.Logo_TNC = uiimage(app.Ayuda);
            app.Logo_TNC.ImageSource = 'Logo_TNC.png';
            app.Logo_N4W = uiimage(app.Ayuda);
            app.Logo_N4W.ImageSource = 'Logo_N4W-Facility.png';
            app.Logo_Gotta = uiimage(app.Ayuda);
            app.Logo_Gotta.ImageSource = 'Icons_Gotta.jpg';
            app.Logo_Pegasys = uiimage(app.Ayuda);
            app.Logo_Pegasys.ImageSource = 'Logo_Pegasys.png';
            app.DocumentosdeayudaPanel = uipanel(app.Ayuda);
            app.DocumentosdeayudaPanel.Title = 'Documentos de ayuda';
            app.ManualSIGA = uibutton(app.DocumentosdeayudaPanel, 'push');
            app.ManualSIGA.ButtonPushedFcn = createCallbackFcn(app, @ManualSIGAButtonPushed, true);
            app.ManualSIGA.Text = 'Manual de SIGA-CAL Tools';
            app.VarSIGA = uibutton(app.DocumentosdeayudaPanel, 'push');
            app.VarSIGA.ButtonPushedFcn = createCallbackFcn(app, @VarSIGAButtonPushed, true);
            app.VarSIGA.Text = 'Listado de variables de SIGA-CAL';
            app.Infos = uibutton(app.DocumentosdeayudaPanel, 'push');
            app.Infos.ButtonPushedFcn = createCallbackFcn(app, @InfosButtonPushed, true);
            app.Infos.Text = 'Informes - N4W Facility';
            app.SitioswebdeinteresPanel = uipanel(app.Ayuda);
            app.SitioswebdeinteresPanel.Title = 'Sitios web de interes';
            app.Web_CV = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_CV.ButtonPushedFcn = createCallbackFcn(app, @Web_CVButtonPushed, true);
            app.Web_CV.Text = 'Pagina web - CuencaVerde';
            app.Web_N4W = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_N4W.ButtonPushedFcn = createCallbackFcn(app, @Web_N4WPushed, true);
            app.Web_N4W.Text = 'Pagina web - N4W Facility';
            app.Web_FA = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_FA.ButtonPushedFcn = createCallbackFcn(app, @Web_FAButtonPushed, true);
            app.Web_FA.Text = 'Pagina web - Fondos de Agua';
            app.Web_TNC = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_TNC.ButtonPushedFcn = createCallbackFcn(app, @Web_TNCButtonPushed, true);
            app.Web_TNC.Text = 'Pagina web - TNC';
            app.Web_Pega = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_Pega.ButtonPushedFcn = createCallbackFcn(app, @Web_PegaButtonPushed, true);
            app.Web_Pega.Text = 'Pagina web - Pegasys';
            app.Web_Gotta = uibutton(app.SitioswebdeinteresPanel, 'push');
            app.Web_Gotta.ButtonPushedFcn = createCallbackFcn(app, @Web_GottaButtonPushed, true);
            app.Web_Gotta.Text = 'Pagina web - Gotta';
            app.Logo_CuencaVerde = uiimage(app.Ayuda);
            app.Logo_CuencaVerde.ImageSource = 'Logo_CuencaVerde.png';
            app.DSSPanel = uipanel(app.Ayuda);
            app.DSSPanel.Title = 'DSS';
            app.DSS = uibutton(app.DSSPanel, 'push');
            app.DSS.ButtonPushedFcn = createCallbackFcn(app, @DSSButtonPushed, true);
            app.DSS.Text = 'Indsumos - DSS - CuencaVerde';
            app.SimulacinGeocientficaAbiertaSIGACALCuencaVerdeVersin10Label = uilabel(app.Ayuda);
            app.SimulacinGeocientficaAbiertaSIGACALCuencaVerdeVersin10Label.Text = {'Simulación  Geocientífica  Abierta - SIGA-CAL - CuencaVerde '; 'SIGA-CAL Tools Versión 1.0'};
            % Show the figure after all components are created
            app.Ayuda.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_05_Help

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ayuda)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ayuda)
        end
    end
end